classdef MARSgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        LeftPanel    matlab.ui.container.Panel
        CenterPanel  matlab.ui.container.Panel
        UIAxes       matlab.ui.control.UIAxes
        RightPanel   matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    methods (Access = private)

        function [slope, intercept, R_squared, x_intercept] = linregress(~,x,y)

            %     This function computes a basic linear regression when given x and y data.
            %     It returns the slope, intercept, R-squared value, and x_intercept.
            %     For further information on an alternative, see sklearn.linear_model.LinearRegression.

            if length(x) ~= length(y)
                disp("Error: Lists must be the same size!")
                return
            end

            x_squared=[];
            x_times_y=[];

            for i = 1:length(x)
                x_squared(i) = x(i)^2; %(float(x(i))^2);
                x_times_y(i) = x(i)*y(i);%(float(x(i))*float(y(i)));
            end

            intercept=(sum(y)*sum(x_squared)-sum(x)*sum(x_times_y))/(length(x)*sum(x_squared)-sum(x)^2);
            slope = (length(x)*sum(x_times_y)-sum(x)*sum(y))/(length(x)*sum(x_squared)-sum(x)^2);

            %     a+bx=y Where a is intercept and b is slope
            %     Calculate the R^2 value through the formula 1-(SSres/SStot)

            y_mean=sum(y)/length(y);
            y_res = [];
            y_tot = [];

            for i = 1:length(y)
                modeled_pt = slope*x(i)+intercept;
                y_res(i) = (modeled_pt-y_mean)^2;
                y_tot(i) = (y(i)-y_mean)^2;
            end

            R_squared = sum(y_res)/sum(y_tot);
            %     y_diff_squared=[]
            %     for i in y:
            %         y_diff_squared.append((i-y_mean)**2)
            %     SStot=sum(y_diff_squared)
            %     y_diff_func_squared=[]
            %     for i in y:
            %         y_diff_func_squared.append((i-(slope*i+intercept))**2)
            %     SSres=sum(y_diff_func_squared)
            %
            %     R_squared=1-(SSres/SStot)
            x_intercept = -intercept/slope;
        end


        %         function str = santosh_newhouse_gregoire_method(~,energy,absorption_coefficient,bg_type,show_graph)
        %             bg_type = 'direct';
        %             show_graph = true;
        %             str = 'This method has not yet been given permission to be used.';
        %         end

        function mean_band_gaps = segmentation_method(app,energy,absorption_coefficient,bg_type,show_graph)
            bg_type = 'direct';
            show_graph = true;

            %             direct_abs_corrected = absorption_coefficient;
            %             t = [];

            %             for k = direct_abs_corrected
            %                 t(end+1) = k/(max(direct_abs_corrected));
            %             end
            %             direct_abs_corrected=t;

            norm = max(absorption_coefficient);
            direct_abs_corrected = absorption_coefficient./norm;

            if show_graph == true
                figure;
                scatter(energy,direct_abs_corrected);
            end

            first_deriv = [];
            energy_deriv = [];

            for k = 1:length(energy)-1 %SHOULD BE -1?
                first_deriv(k) = (direct_abs_corrected(k+1)-direct_abs_corrected(k))/(energy(k+1)-energy(k));
                energy_deriv(k) = energy(k);
            end

            first_deriv(end+1) = first_deriv(end);
            energy_deriv(end+1) = energy(end);
            first_deriv_savgol_filt = sgolayfilt(first_deriv,4,25);
            %     first_deriv_savgol_filt = np.ndarray.tolist(scipy.signal.savgol_filter(first_deriv,25,4))

            energy_negats_elim = [];
            first_deriv_negats_elim = [];

            for k = 1:length(first_deriv_savgol_filt)
                if first_deriv_savgol_filt(k) > mean(first_deriv_savgol_filt) % float issue?
                    energy_negats_elim(end+1) = energy(k);
                    first_deriv_negats_elim(end+1) = direct_abs_corrected(k);
                end
            end

            EA = [];
            E = [];
            for k = 1:length(energy_negats_elim)
                if energy_negats_elim(k) < mean(energy_negats_elim)+2*std(energy_negats_elim)
                    E(end+1) = energy_negats_elim(k);
                    EA(end+1) = first_deriv_negats_elim(k);
                end
            end

            new_energy = E;
            new_absorption_direct = EA;
            spring_quotient = 1.0; %USE THIS TO ALTER THE NUMBER OF LINES SELECTED!

            try
                position_linregress_vals_10_role = [];
                s = 1:length(new_energy)/10:length(new_energy);

                for k = 1:length(s)-1 %-1??
                    [slope, intercept, R_squared, x_intercept] = linregress(app,new_energy(s(k):s(k+1)),new_absorption_direct(s(k):s(k+1)));
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared < 0.75
                        pass
                    else
                        position_linregress_vals_10_role(end+1) = {s(k),s(k+1),slope,intercept,R_squared,x_intercept};%tuple([s(k),s(k+1),slope,intercept,R_squared,x_intercept])
                    end
                end
                position_linregress_vals_10_role = sortrows(position_linregress_vals_10_role,slope); %key=lambda item:item(2)) 2+1?
                for h = position_linregress_vals_10_role
                    if h(2) < (position_linregress_vals_10_role(end,2))/(spring_quotient) %Float issue? %h(2+1?) %(end,2)??
                        position_linregress_vals_10_role(h) = [];%position_linregress_vals_10_role.remove(h)
                    end
                end
            catch ValueError
                position_linregress_vals_10_role = None;
            end

            try
                position_linregress_vals_9_role = [];
                r = 1:length(new_energy)/9:length(new_energy);

                for k = 1:length(r)-1
                    [slope, intercept, R_squared, x_intercept] = linregress(new_energy(r(k):r(k+1)),new_absorption_direct(r(k):r(k+1))); %(r(k):r(k+1) + 1)??
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared < 0.75
                        pass
                    else
                        position_linregress_vals_9_role(end+1) = {r(k),r(k+1),slope,intercept,R_squared,x_intercept}; %tuple([r(k),r(k+1),slope,intercept,R_squared,x_intercept])
                    end
                end

                position_linregress_vals_9_role = sortrows(position_linregress_vals_9_role,slopes); %key=lambda item:item(2)) 2+1?

                for h = position_linregress_vals_9_role
                    if h(2) < (position_linregress_vals_9_role(end,2))/(spring_quotient)%Float issue? %h(2+1?) %(end,2)??
                        position_linregress_vals_9_role(h) = [];%position_linregress_vals_9_role.remove(h)
                    end
                end

            catch ValueError
                position_linregress_vals_9_role = None;
            end

            try
                position_linregress_vals_8_role = [];
                r = 1:length(new_energy)/8:length(new_energy);

                for k = 1:length(r)-1 %-1?
                    [slope, intercept, R_squared, x_intercept] = linregress(new_energy(r(k):r(k+1),new_absorption_direct(r(k):r(k+1))));%(r(k):r(k+1) + 1)??
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared < 0.75
                        continue
                    else
                        position_linregress_vals_8_role(end+1) = {r(k),r(k+1),slope,intercept,R_squared,x_intercept}; %tuple([r(k),r(k+1),slope,intercept,R_squared,x_intercept])
                    end
                end

                position_linregress_vals_8_role = sortrows(position_linregress_vals_8_role,slope); %key=lambda item:item(2)) 2+1?

                for h = position_linregress_vals_8_role
                    if h(2) < (position_linregress_vals_8_role(end,2))/(spring_quotient) %Float issue? %h(2+1?) %(end,2)??
                        position_linregress_vals_8_role(h) = [];%position_linregress_vals_8_role.remove(h)
                    end
                end
            catch ValueError
                position_linregress_vals_8_role = None;
            end

            try
                position_linregress_vals_7_role = [];
                t = 1:length(new_energy)/7:length(new_energy);

                for k = 1:length(t)-1 %-1?
                    [slope, intercept, R_squared, x_intercept] = linregress(new_energy(t(k):t(k+1)),new_absorption_direct(t(k):t(k+1)));%(t(k):t(k+1) + 1)??
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared < 0.75
                        continue
                    else
                        position_linregress_vals_7_role(end+1) = {t(k),t(k+1),slope,intercept,R_squared,x_intercept};%tuple([t(k),t(k+1),slope,intercept,R_squared,x_intercept])
                    end
                end

                position_linregress_vals_7_role = sortrows(position_linregress_vals_7_role,slope); %key=lambda item:item(2)) 2+1?

                for h = position_linregress_vals_7_role
                    if h(2) < (position_linregress_vals_7_role(end,2))/(spring_quotient) %Float issue? %h(2+1?) %(end,2)??
                        position_linregress_vals_7_role.remove(h); %position_linregress_vals_7_role.remove(h)
                    end
                end
            catch ValueError
                position_linregress_vals_7_role = None;
            end

            try
                position_linregress_vals_6_role = [];
                t = 1:length(new_energy)/6:length(new_energy);
                for k = 1:length(t)-1 %-1?
                    [slope, intercept, R_squared, x_intercept] = linregress(new_energy(t(k):t(k+1)),new_absorption_direct(t(k):t(k+1)));%(t(k):t(k+1) + 1)??
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared < 0.75
                        continue
                    else
                        position_linregress_vals_6_role(end+1) = {t(k),t(k+1),slope,intercept,R_squared,x_intercept};%tuple([t(k),t(k+1),slope,intercept,R_squared,x_intercept])
                    end
                end

                position_linregress_vals_6_role = sortrows(position_linregress_vals_6_role,slope); %key=lambda item:item(2)) 2+1?

                for h = position_linregress_vals_6_role
                    if h(2) < (position_linregress_vals_6_role(end,2))/(spring_quotient)%Float issue? %h(2+1?) %(end,2)??
                        position_linregress_vals_6_role(h) = [];%position_linregress_vals_6_role.remove(h)
                    end
                end
            catch ValueError
                position_linregress_vals_6_role = None;
            end

            try
                position_linregress_vals_5_role = [];
                t = 1:length(new_energy)/5:length(new_energy);
                for k = 1:length(t)-1 %-1?
                    [slope, intercept, R_squared, x_intercept] = linregress(new_energy(t(k):t(k+1),new_absorption_direct(t(k):t(k+1))));%(t(k):t(k+1) + 1)??
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared <  0.75
                        continue
                    else
                        position_linregress_vals_5_role(end+1) = {t(k),t(k+1),slope,intercept,R_squared,x_intercept};%tuple([t(k),t(k+1),slope,intercept,R_squared,x_intercept])
                    end
                end

                position_linregress_vals_5_role = sortrows(position_linregress_vals_5_role,slope); %key=lambda item:item(2)) 2+1?

                for h = position_linregress_vals_5_role
                    if h(2) < position_linregress_vals_5_role(end,2)/spring_quotient%Float issue? %h(2+1?) %(end,2)??
                        position_linregress_vals_5_role(h) = []; %position_linregress_vals_5_role.remove(h)
                    end
                end
            catch ValueError
                position_linregress_vals_5_role = None;
            end

            try
                band_gaps_list = [position_linregress_vals_5_role(end,5);
                    position_linregress_vals_6_role(end,5);
                    position_linregress_vals_7_role(end,5);
                    position_linregress_vals_8_role(end,5);
                    position_linregress_vals_9_role(end,5);
                    position_linregress_vals_10_role(end,5)];

                mean_band_gaps = mean(band_gaps_list);
                std_band_gaps = std(band_gaps_list);

                for h = band_gaps_list
                    if h > (mean_band_gaps+2)*std_band_gaps || h < (mean_band_gaps-2)*std_band_gaps
                        band_gaps_list(h) = [];
                    end
                end

                mean_band_gaps = mean(band_gaps_list);
                std_band_gaps = std(band_gaps_list);

                x=linspace(min(new_energy),max(new_energy),1000);

                if show_graph == true
                    for h = position_linregress_vals_5_role(end)
                        scatter(x,x*h(2)+h(3),2,'Color','g');
                    end
                    for h = position_linregress_vals_6_role(end)
                        scatter(x,x*h(2)+h(3),2,'Color','c');
                    end
                    for h = position_linregress_vals_7_role(end)
                        scatter(x,x*h(2)+h(3),2,'Color','m');
                    end
                    for h = position_linregress_vals_8_role(end)
                        scatter(x,x*h(2)+h(3),2,'Color','yellow');
                    end
                    for h = position_linregress_vals_9_role(end)
                        scatter(x,x*h(2)+h(3),2,'Color','orange');
                    end
                    for h = position_linregress_vals_10_role(end)
                        scatter(x,x*h(2)+h(3),2,'Color','r');
                    end

                    title('Tauc Plot for Direct Transitions',"FontSize",20);
                    xlabel('Energy (eV)','FontSize',14);
                    ylabel('(E'+u + '\u03B1' +')'+u+'\u00B2','FontSize',14);
                    ax.FontSize = 14;
                    %                     xticks('FontSize',14);
                    %                     yticks('FontSize',14);
                    axis([min(energy),max(energy),0,1]);
                    %                 show()
                end

            catch e %(ValueError, IndexError)
                disp("Empty Sequence!");
                return
            end
        end

        function bandgap = mars_method(app,energy,absorption_coefficient,bg_type,show_graph)
            bg_type = "direct";
            show_graph = true;

            % Normalize absorption coeff data
            norm = max(absorption_coefficient);
            direct_abs_corrected = absorption_coefficient./norm;

            try
                trainParams = aresparams([],[],false); % Force piecewise linear
                [model, time, resultsEval] = aresbuild(energy,direct_abs_corrected,trainParams);%,[],[],true);
            catch ValueError
                uialert(app.UIFigure,"Problem in MARS fitting parameters!","MARS Problem","Icon","Error");
                return
            end

            energy_elbows = [min(energy); max(energy)];

            basislist = areseq(model);% basislist = [model.basis_];
            strarray = string(basislist(1:end-1));
            sz = size(strarray,1);
            for i = 1:sz %coeff = list(model.basis_)[1:]
                try
                    str = strarray(i); % Iterate through each basis eqn
                    out = regexp(str,"\d+\.\d+","match");
%                     pieces = strsplit(str,["x1 ",")"]);


%                     For piecewise-cubic|MAYBE ADD IF ELSE FOR THESE CASES
%                     pieces = strsplit(str,[",","|"]); % Split into separate pieces
%                     sign = str2double(pieces(2)); % Extract sign
%                     lin_num = str2double(pieces(4)); % Extract linear coefficient
%                     new = sign*lin_num; % Multiply by sign to get proper new elbow num

                    new = str2double(out);
                    if new ~= energy_elbows
                        energy_elbows = [energy_elbows;new];%(float(re.findall("\d+\.\d+", str(coeff))[0]))
                    end

                    % Split the equations at the equal sign and take expr
                    eqn = strsplit(str, " = ");
                    basis_funcs = eqn(2);

                    BF{i,1} = basis_funcs;
                catch IndexError
                    disp(IndexError);
                    disp(strarray(i));
                    continue
                end
            end

            [y_hat, ~] = arespredict(model,energy);

            if show_graph == true
                scatter(app.UIAxes,energy,direct_abs_corrected,10,"black","filled","o");
                line(app.UIAxes,energy,y_hat, "Color",'b','Marker',".");

%                 xlabel('Energy (eV)','FontSize',14);
%                 ylabel('(E'+"\alpha"+')'+"^{2}","FontSize",14);
                %                             plt.ylabel('sqrt(E'+u"\u03B1"+')',fontsize=14)
                %                             plt.ylabel('Normalized Direct Absorbance',fontsize=14)
%                 title("Tauc Plot for Direct Transitions","FontSize",20);
%                 xticks(gca,'FontSize',14);
%                 yticks(gca,'FontSize',14);
%                 gca.FontSize = 14;
            end

%             func = export.export_sympy(model); % IS THIS JUST END of list

            last = strsplit(string(basislist(end))," = ");
            export = last(2);

            for i = 1:sz
                % Add this condition so that last element is replaced w/o space
                if i ~= sz
                    old = strcat("BF",num2str(i)," ");
                else
                    old = strcat("BF",num2str(i));
                end
                export = strrep(export,old,BF{i,1});
            end
            
            lastfunc = str2func(['@(x1)',convertStringsToChars(export)]);

            direct_abs_elbows = [];

            elbw_sz = size(energy_elbows,1);
            for i = 1:elbw_sz
                direct_abs_elbows = [direct_abs_elbows;feval(lastfunc,energy_elbows(i,1))]; % direct_abs_elbows(end+1) = func.evalf(subs={'x0':coeff}); %subs=????????
            end

            elbows_list = [];

%             for elbow_num = 1:elbw_sz
%                 elbows_list(elbow_num,1) = energy_elbows(elbow_num);
%                 elbows_list(elbow_num,2) = direct_abs_elbows(elbow_num);
%             end
            elbows_list(:,1) = energy_elbows;
            elbows_list(:,2) = direct_abs_elbows;

            elbows_list = sortrows(elbows_list);

            line_segs = [];

            for point = 1:size(elbows_list,1)-1
                que = [];
                for i = 1:size(energy,1)
                    if energy(i,1) > elbows_list(point,1) && energy(i,1) < elbows_list(point+1,1)%if w > elbows_list[point][0] && w < elbows_list[point+1][0]
                        que = [que;energy(i,1)];
                    end
                end

                num_pts = size(que,1);

                x_length = elbows_list(point+1,1)-elbows_list(point,1);
                length = ((elbows_list(point+1,1)-elbows_list(point,1))^2 + (elbows_list(point+1,2)-elbows_list(point,2))^2)^(1/2);
                slope = (elbows_list(point+1,2)-elbows_list(point,2))/(elbows_list(point+1,1)-elbows_list(point,1));
                y_intercept = elbows_list(point+1,2)-slope*elbows_list(point+1,1);

                % ORIGINAL
                %                 x_length = elbows_list[point+1][0]-elbows_list[point][0]
                %                 length = ((elbows_list[point+1][0]-elbows_list[point][0])**2+(elbows_list[point+1][1]-elbows_list[point][1])**2)**.5
                %                 slope = (elbows_list[point+1][1]-elbows_list[point][1])/(elbows_list[point+1][0]-elbows_list[point][0])
                %                 y_intercept = elbows_list[point+1][1]-slope*elbows_list[point+1][0]

                x_intercept = (-1*y_intercept) / slope;
                weighting_factor = slope^2 * x_length*2 * abs(length)^(1/2) * num_pts; % should x_length^2
                try
                    if x_intercept > 0 && slope > 0 && num_pts > 10
                        line_segs = [line_segs;[x_length,length,slope,y_intercept,x_intercept,weighting_factor]];
                    end
                catch TypeError
                    disp("Weird complex zoo error..");
                    continue
                end
            end

            line_segs = sortrows(line_segs,6); % Sort by weighting factor
            %             disp(line_segs)
            % Max should be the last element
            winner = line_segs(end,:); %winner = max(line_segs,key=lambda item:item[5]) % Sort max by weighting_factor

            adj_energy = linspace(min(energy),max(energy),1000);
            adj_energy = adj_energy';

            adj_winner = [];
            adj_winner = adj_energy*winner(3)+winner(4);

            if show_graph == true
                Tau_Segment = line(app.UIAxes,adj_energy,adj_winner,"Color",'r');
                axis(app.UIAxes,[min(energy),max(energy),0,1]);
            end

            bandgap = winner(5)
        end

        function df = properties(~,identity)
            url = "https://htem-api.nrel.gov/api/sample/"+string(identity);
            %         response = urllib.request.urlopen(url)
            %         data = json.loads(response.read())
            data = webread(url);
            df = data;
            %             df = frames.DataFrame;
            %             for i = data
            %                 df(:,i) = data(i);
            %             end
        end

        function df = spectra(~,which,identity) % USED FOR TESTING SAMPLE LIBRARY W/ REST-API
            url = "https://htem-api.nrel.gov/api/sample/"+string(identity);
            %There is the potential to replace this with mvl_optical or mvl_xrd,
            %but these seem to be broken at the moment...
            %         response = urllib.request.urlopen(url);
            %         data = json.loads(response.read());
            data = webread(url);

            %         df = pd.DataFrame();
            %             df = frames.DataFrame;
            %             df = table('VariableNames',["uvit_wave","uvit_response","uvir_wave","uvir_response","nirt_wave","nirt_response","nirr_wave","nirr_response"]);
            df = table;
            %         df = varfun(@class,[],'OutputFormat',table);
            temp1 = nan(900,1);
            temp2 = nan(900,1);

            if which == "xrd"
                df(:,"xrd_angle") = data("xrd_angle");
                df(:,"xrd_background") = data("xrd_background");
                df(:,"xrd_intensity") = data("xrd_intensity");
            elseif which == 'optical'
                %             uvit_df = pd.DataFrame();
                %                 temp1 = ones(size(data.opt_uvit_wavelength));
                %                 temp2 = ones(size(data.opt_uvit_response));
                %                 uvit_df = frames.DataFrame([temp1, temp2],[],["uvit_wave", "uvit_response"]);
                uvit_df = table(temp1,temp2,'VariableNames',["uvit_wave", "uvit_response"]);
                try
                    %                 uvit_df = frames.DataFrame([data('opt_uvit_wavelength'), data('opt_uvit_response')],[],["uvit_wave","uvit_response"]);
                    %                 uvit_df = frames.DataFrame(data('opt_uvit_response'),[],"uvit_response");
                    uvit_df(:,"uvit_wave") = array2table(data.opt_uvit_wavelength);
                    uvit_df(:,"uvit_response") = array2table(data.opt_uvit_response);
                catch KeyError %No uvit available
                    disp("KeyError")
                    ...
                end
                %             uvir_df = pd.DataFrame();
                %                 temp1 = ones(size(data.opt_uvir_wavelength));
                %                 temp2 = ones(size(data.opt_uvir_response));
                %                 uvir_df = frames.DataFrame([temp1, temp2],[],["uvir_wave", "uvir_response"]);
                uvir_df = table(temp1,temp2,'VariableNames',["uvir_wave", "uvir_response"]);
                try
                    uvir_df(:,"uvir_wave") = array2table(data.opt_uvir_wavelength);
                    uvir_df(:,"uvir_response") = array2table(data.opt_uvir_response);
                catch KeyError %No uvir available
                    ...
                end
                %             nirt_df = pd.DataFrame();
                %                 temp1 = ones(size(data.opt_nirt_wavelength));
                %                 temp2 = ones(size(data.opt_nirt_response));
                %                 nirt_df = frames.DataFrame([temp1, temp2],[],["nirt_wave", "nirt_response"]);
                nirt_df = table(temp1,temp2,'VariableNames',["nirt_wave", "nirt_response"]);
                try
                    nirt_df(1:171,"nirt_wave") = array2table(data.opt_nirt_wavelength);
                    nirt_df(1:171,"nirt_response") = array2table(data.opt_nirt_response);
                catch KeyError %No nirt available
                    ...
                end
                %             nirr_df = pd.DataFrame();
                %                 temp1 = ones(size(data.opt_nirr_wavelength));
                %                 temp2 = ones(size(data.opt_nirr_response));
                %                 nirr_df = frames.DataFrame([temp1, temp2],[],["nirr_wave", "nirr_response"]);
                nirr_df = table(temp1,temp2,'VariableNames',["nirr_wave", "nirr_response"]);
                try
                    nirr_df(1:433,"nirr_wave") = array2table(data.opt_nirr_wavelength);
                    nirr_df(1:433,"nirr_response") = array2table(data.opt_nirr_response);
                catch KeyError %No nirr available
                    ...
                end
                df = [uvit_df,uvir_df,nirt_df,nirr_df];
            else
                %             df = pd.DataFrame();
                df = frames.DataFrame;
            end
        end


        %Absorption coefficient - grabs API DB data and then stores in table. Calculates energy and absorption coefficient.
        function band_gap_run(app,sample,bg_type,show_graph,method)
            bg_type = "direct";
            show_graph = true;
            method = "MARS";

            try
                optical_spectra = spectra(app,"optical",sample); %Error with this in the absence of optical data.
                %                     uvit = sorted(zip(list(optical_spectra("uvit_wave")),list(optical_spectra("uvit_response"))));
                uvit = table2array([rmmissing(optical_spectra(:,"uvit_wave")),rmmissing(optical_spectra(:,"uvit_response"))]);
                %                     uvit = sort(uvitzip);
                %                     uvir = sorted(zip(list(optical_spectra("uvir_wave")),list(optical_spectra("uvir_response"))));
                uvir = table2array([rmmissing(optical_spectra(:,"uvir_wave")),rmmissing(optical_spectra(:,"uvir_response"))]);

                %                     nirt = sorted(zip(list(rmmissing(optical_spectra("nirt_wave"))),list(rmmissing(optical_spectra("nirt_response")))));
                nirt = table2array([rmmissing(optical_spectra(:,"nirt_wave")),rmmissing(optical_spectra(:,"nirt_response"))]);
                %                     nirr = sorted(zip(list(rmmissing(optical_spectra("nirr_wave"))),list(rmmissing(optical_spectra("nirr_response")))));
                nirr = table2array([rmmissing(optical_spectra(:,"nirr_wave")),rmmissing(optical_spectra(:,"nirr_response"))]);
            catch TypeError
                disp("Sorry, the requested optical data is unavailable. :(");
                return
            end

            %                 thickness = float(Sample(209139).properties().thickness)
            thickness = properties(app,209139).thickness;
            transmittance = [uvit;nirt];
            %                 transmittance = sort(uvit+nirt);
            reflectance = [uvir;nirr];
            %                 reflectance = sort(uvir+nirr);
            absorption_coefficient = [];

            for i = 1:length(transmittance) %Equation for absorption coefficient
                %(-1*np.log(abs(transmittance[i][1]/(1-reflectance[i][1])))/float(thickness*10**-4))
                absorption_coefficient(end+1,1) = -1*log(abs(transmittance(i,2)/(1-reflectance(i,2))))/((thickness*(10^(-4))));
            end

            %                 energy = [1240.0/i[0] for i in transmittance]
            %                 for i = transmittance
            energy = 1240./transmittance(:,1); % SHOULD BE 1239.8?
            %                 end

            calculate_band_gap(app,energy,absorption_coefficient,bg_type,show_graph,method)
        end

        % Calculates band gap using the Mars method.
        function calculate_band_gap(app,energy,absorption_coefficient,bg_type,show_graph,method)
            bg_type = "direct";
            show_graph = true;
            method = "MARS";

            %           absorption_coeff_savgol_filt = np.ndarray.tolist(signal.savgol_filter(absorption_coefficient,25,4));
            absorption_coeff_savgol_filt = sgolayfilt(absorption_coefficient,4,25);
            absorption_y = [];

            if bg_type == "direct"
                %                     for k = 1:length(absorption_coeff_savgol_filt)
                % %                         (abs(absorption_coeff_savgol_filt[k])**2.0)*energy[k]**2.0
                %                         absorption_y(end+1,1) = (abs(absorption_coeff_savgol_filt(k))^2)*energy(k)^2.0;
                %                     end
                absorption_y(:,1) = (abs(absorption_coeff_savgol_filt).*energy).^2;
            elseif bg_type == "indirect"
                %                     for k = 1:length(absorption_coeff_savgol_filt)
                %                         absorption_y(end+1,1) = (abs(absorption_coeff_savgol_filt(k))^(1/2))*energy(k)^(1/2);
                %                     end
                absorption_y(:,1) = (abs(absorption_coeff_savgol_filt).*energy).^(1/2);
            elseif bg_type == "log10"
                %                     for k = 1:length(absorption_coeff_savgol_filt)
                %                         absorption_y(end+1,1) = (log10(abs(absorption_coeff_savgol_filt(k)))-3);
                %                     end
                absorption_y(:,1) = log10(abs(absorption_coeff_savgol_filt))-3;
            elseif bg_type == "raw_alpha"
                %                     for k = 1:length(absorption_coeff_savgol_filt)
                %                         absorption_y(end+1,1) = (abs(absorption_coeff_savgol_filt(k)));
                %                     end
                absorption_y(:,1) = abs(absorption_coeff_savgol_filt);
            else
                disp('Not a valid band gap type.');
            end

            if method == "S-N-G"
                santosh_newhouse_gregoire_method(energy,absorption_y,bg_type,show_graph);
            elseif method == "Segmentation"
                segmentation_method(app,energy,absorption_y,bg_type,show_graph);
            elseif method == "MARS"
                mars_method(app,energy,absorption_y,bg_type,show_graph);
            else
                disp("Not a valid band gap methodology");
            end
        end

        function test(app)
            band_gap_run(app,221101)
        end

%         function results = export_sympy(app,model, columns)
%             %     """
%             %     Constructs a sympy expression or list of sympy expressions from of a trained earth model.
%             %     :param earth_model: Trained pyearth model
%             %     :param columns: The index or indices of the output columns for which expressions are to
%             %       be constructed.  If an integer is used, a sympy expression is returned.  If indices
%             %       are given then a list of sympy expressions is returned.  If columns is None, it is treated
%             %       as if columns=0 for models with only one output column or as columns=slice(None) for more than
%             %       one output column.
%             %     :return: a sympy expression or list of sympy expressions equivalent to the Earth.predict method for
%             %       the selected output columns.
%             %     """
%             columns = None;
%             %Set a sane default for columns
%             if columns == None
%                 if model.coef_.shape(1) == 1
%                     columns = 0;
%                 else
%                     columns = slice(None);
%                 end
%             end
% 
%             %Get basis function terms
%             terms = export_sympy_term_expressions(app,model);
% 
%             %Handle column choice
%             coefs = earth_model.coef_(columns);
%             if len(coefs.shape) == 1
%                 unwrap = true;
%                 %                 coefs = [coefs];
%                 n_cols = 1;
%             else
%                 unwrap = false;
%                 n_cols = coefs.shape(1);
%             end
% 
%             %Combine coefficients with terms for each output column
%             %         result = [sum([coefs[i][j] * term for j, term in enumerate(terms)]) for i in range(n_cols)]
% 
%             if unwrap
%                 %Result should be an expression rather than a list of expressions.
%                 result = result(1);
%             end
%         end
% 
%         function expressions = export_sympy_term_expressions(~,model)
%             %     """
%             %     Construct a list of sympy expressions for all non-pruned terms in the model.
%             %     :param earth_model: Trained pyearth model
%             %     :return: a list of sympy expressions representing terms in the model.  These
%             %       expressions are the symbolic equivalent of the Earth.transform method.
%             %     """
%             %     from sympy import Symbol, Add, Mul, Max, RealNumber, Piecewise, Pow, And, nan, Function, Not
%             %     from ._basis import LinearBasisFunction, HingeBasisFunction, SmoothedHingeBasisFunction, \
%             %           MissingnessBasisFunction, ConstantBasisFunction, VariableBasisFunction
% 
%             Missing = Function('Missing');
%             NaNProtect = Function('NaNProtect');
% 
%             function bf_var = linear_bf_to_factor(bf, bf_var)
%                 bf_var;
%                 return
%             end
% 
%             function factor = smoothed_hinge_bf_to_factor(bf, bf_var)
%                 knot = RealNumber(bf.get_knot());
%                 knot_minus = RealNumber(bf.get_knot_minus());
%                 knot_plus = RealNumber(bf.get_knot_plus());
%                 r = RealNumber(bf.get_r());
%                 p = RealNumber(bf.get_p());
%                 if bf.get_reverse()
%                     lower_p = [(-(bf_var - knot)), (bf_var <= knot_minus)];
%                     upper_p = [0, bf_var >= knot_plus];
%                     left_exp = Mul(p, Pow((bf_var - knot_plus), 2));
%                     right_exp = Mul(r, Pow((bf_var - knot_plus), 3));
%                     middle_b = And(knot_minus < bf_var, bf_var < knot_plus);
%                     middle_exp = [Add(left_exp, right_exp), middle_b];
%                     piecewise = Piecewise(lower_p, upper_p, middle_exp);
%                     factor = piecewise;
%                 else
%                     lower_p = [0, bf_var <= knot_minus];
%                     upper_p = [bf_var - knot, bf_var >= knot_plus];
%                     left_exp = Mul(p, Pow((bf_var - knot_minus), 2));
%                     right_exp = Mul(r, Pow((bf_var - knot_minus), 3));
%                     middle_b = And(knot_minus < bf_var, bf_var < knot_plus);
%                     middle_exp = [Add(left_exp, right_exp), middle_b];
%                     piecewise = Piecewise(lower_p, upper_p, middle_exp);
%                     factor = piecewise;
%                 end
%             end
% 
%             function factor = hinge_bf_to_factor(bf, bf_var)
%                 knot = bf.get_knot();
%                 if bf.get_reverse()
%                     factor = Max(0, RealNumber(knot) - bf_var);
%                 else
%                     factor = Max(0, bf_var - RealNumber(knot));
%                 end
%             end
% 
% 
%             function result = missingness_bf_to_factor(bf, bf_var)
%                 %This is the error that should be raised when a user attempts to use functionality
%                 %that has not yet been implemented.
%                 if bf.complement
%                     result = ~(Missing(bf_var));
%                 else
%                     result = Missing(bf_var);
%                 end
%             end
% 
%             function result = constant_bf_to_factor(bf, bf_var)
%                 result = RealNumber(1);
%             end
% 
%             function result = protect_from_nan(label, missables)
%                 if label == missables
%                     result = NaNProtect(Symbol(label));
%                 else
%                     result = Symbol(label);
%                 end
%             end
% 
%             function result = dont_protect_from_nan(label, missables)
%                 result = Symbol(label);
%             end
% 
%             K = {LinearBasisFunction,SmoothedHingeBasisFunction,HingeBasisFunction,MissingnessBasisFunction,ConstantBasisFunction};
%             V1 = [linear_bf_to_factor,smoothed_hinge_bf_to_factor,hinge_bf_to_factor,missingness_bf_to_factor,constant_bf_to_factor];
%             V2 = [protect_from_nan,protect_from_nan,protect_from_nan,dont_protect_from_nan,protect_from_nan];
%             V3 = [non_missable,non_missable,non_missable,missingness_bf_get_missables,non_missable];
% 
%             bf_to_factor_dispatcher = containers.Map(K,V1);
%             nan_protect_dispatch = containers.Map(K,V2);
%             bf_get_missables_dispatcher = containers.Map(K,V3);
% 
%             %     bf_to_factor_dispatcher = {LinearBasisFunction: linear_bf_to_factor,
%             %                                SmoothedHingeBasisFunction: smoothed_hinge_bf_to_factor,
%             %                                HingeBasisFunction: hinge_bf_to_factor,
%             %                                MissingnessBasisFunction: missingness_bf_to_factor,
%             %                                ConstantBasisFunction: constant_bf_to_factor}
% 
%             %     nan_protect_dispatch = {LinearBasisFunction: protect_from_nan,
%             %                             SmoothedHingeBasisFunction: protect_from_nan,
%             %                             HingeBasisFunction: protect_from_nan,
%             %                             MissingnessBasisFunction: dont_protect_from_nan,
%             %                             ConstantBasisFunction: protect_from_nan}
% 
%             function result = bf_to_factor(bf, missables)
%                 % '''
%                 % Convert a BasisFunction to a factor of a term.
%                 % '''
%                 if isinstance(bf, VariableBasisFunction)
% %                     bf_var = nan_protect_dispatch{bf.__class__}(bf.label, missables);
%                 else
%                     bf_var = None;
%                 end
% 
% %                 result = bf_to_factor_dispatcher{bf.__class__}(bf, bf_var);
%             end
% 
%             function result = missingness_bf_get_missables(bf)
%                 bf_var = bf.label;
%                 result = set([bf_var]);
%             end
% 
%             function result = non_missable(bf)
%                 result = set();
%             end
% 
%             %     bf_get_missables_dispatcher = {LinearBasisFunction: non_missable,
%             %                                    SmoothedHingeBasisFunction: non_missable,
%             %                                    HingeBasisFunction: non_missable,
%             %                                    MissingnessBasisFunction: missingness_bf_get_missables,
%             %                                    ConstantBasisFunction: non_missable}
% 
%             function missables = get_missables(bf)
% %                 missables = bf_get_missables_dispatcher{bf.__class__}(bf);
%                 parent = bf.get_parent();
%                 if parent == None
%                     return
%                 else
%                     missables.update(get_missables(parent)) %update?? append??
%                 end
%             end
% 
%             function result = bf_to_term(bf, missables)
%                 % '''
%                 % Convert a BasisFunction to a term (without coefficient).
%                 % '''
%                 term = bf_to_factor(bf, missables);
%                 parent = bf.get_parent();
% 
%                 if parent == None
%                     result = term;
%                 else
%                     result = Mul(term, bf_to_term(parent, missables));
%                 end
%             end
% 
%             for bf = earth_model.basis_.piter()
%                 expressions(bf) = bf_to_term(bf, get_missables(bf));%??     return [bf_to_term(bf, get_missables(bf)) for bf in earth_model.basis_.piter()]
%             end
% 
%         end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            test(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Tauc Plot for Direct Transitions')
            xlabel(app.UIAxes, 'Energy (eV)')
            ylabel(app.UIAxes, '(E\alpha)^{2}')
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MARSgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end