classdef TaucPlotGUI_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        LeftPanel    matlab.ui.container.Panel
        CenterPanel  matlab.ui.container.Panel
        UIAxes       matlab.ui.control.UIAxes
        RightPanel   matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    methods (Access = private)
        function aresanova(app,model, Xtr, Ytr, weights)
            % aresanova
            % Performs ANOVA decomposition of given ARES model and reports the results.
            % For details, see remarks on aresanova in user's manual as well as
            % Sections 3.5 and 4.3 in (Friedman, 1991a) and Sections 2.4 and 4.1 in
            % (Friedman, 1991b).
            % For multi-response modelling, supply one submodel at a time.
            %
            % Call:
            %   aresanova(model, Xtr, Ytr, weights)
            %
            % Input:
            %   model         : ARES model.
            %   Xtr, Ytr      : Training data observations. The same data that was used
            %                   when the model was built.
            %   weights       : Optional. A vector of weights for observations. The
            %                   same weights that were used when the model was built.

            % =========================================================================
            % ARESLab: Adaptive Regression Splines toolbox for Matlab/Octave
            % Author: Gints Jekabsons (gints.jekabsons@rtu.lv)
            % URL: http://www.cs.rtu.lv/jekabsons/
            %
            % Copyright (C) 2009-2016  Gints Jekabsons
            %
            % This program is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or
            % (at your option) any later version.
            %
            % This program is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with this program. If not, see <http://www.gnu.org/licenses/>.
            % =========================================================================

            % Last update: May 15, 2016

            if nargin < 3
                error('Not enough input arguments.');
            end

            if isempty(Xtr) || isempty(Ytr)
                error('Data is empty.');
            end
            if iscell(Xtr) || iscell(Ytr)
                error('Xtr and Ytr should not be cell arrays.');
            end
            if islogical(Ytr)
                Ytr = double(Ytr);
            elseif ~isfloat(Ytr)
                error('Ytr data type should be double or logical.');
            end
            if ~isfloat(Xtr)
                error('Xtr data type should be double.');
            end
            if any(any(isnan(Xtr)))
                error('ARESLab cannot handle missing values (NaN).');
            end

            [n, d] = size(Xtr); % number of observations and number of input variables
            if size(Ytr,1) ~= n
                error('The number of rows in Xtr and Ytr should be equal.');
            end
            if length(model) > 1
                error('This function works with single-response models only. You can supply one submodel at a time.');
            else
                if iscell(model)
                    model = model{1};
                end
            end
            if length(model.minX) ~= d
                error('The number of columns in Xtr is different from the number when the model was built.');
            end
            if size(Ytr,2) ~= 1
                error('Ytr should have one column.');
            end
            if (nargin < 4)
                weights = [];
            else
                if (~isempty(weights)) && ...
                        ((size(weights,1) ~= n) || (size(weights,2) ~= 1))
                    error('weights vector is of wrong size.');
                end
            end

            gcvNull = gcv(1, var2(Ytr, weights), n, 0);
            nVars = length(model.minX);
            nBasisExI = length(model.knotdims); % excluding intercept

            if model.trainParams.cubic
                fprintf('Type: piecewise-cubic\n');
            else
                fprintf('Type: piecewise-linear\n');
            end
            fprintf('GCV: %g\n', model.GCV);
            fprintf('R2GCV: %g\n', 1 - model.GCV / gcvNull);
            fprintf('Total number of basis functions (including intercept): %d\n', length(model.coefs));
            fprintf('Total effective number of parameters: %g\n', ...
                length(model.coefs) + model.trainParams.c * nBasisExI / 2);

            if nBasisExI <= 0
                return;
            end

            fprintf('ANOVA decomposition:\n');
            fprintf('Function        STD             GCV         R2GCV       #basis  #params     variable(s)\n');
            maxNumInteract = 0; % max number of interactions
            for i = 1 : nBasisExI
                if maxNumInteract < length(model.knotdims{i})
                    maxNumInteract = length(model.knotdims{i});
                end
            end
            priority = zeros(1,nBasisExI); % for ordering ANOVA functions according to number of variables and variable indices
            for i = 1 : nBasisExI
                sorted = sort(model.knotdims{i});
                sorted = [zeros(1, maxNumInteract - length(sorted)) sorted];
                priority(i) = sum(sorted .* (nVars .^ ((maxNumInteract-1):-1:0)));
            end
            [~, idx] = sort(priority);
            % Show ANOVA functions ordered by their priority (i.e., depending on the subset of used variables)
            counterANOVA = 0;
            for i = 1 : length(idx)
                if idx(i) <= 0
                    continue;
                end
                counterANOVA = counterANOVA + 1;
                usedBasis = printLine(model, Xtr, Ytr, weights, gcvNull, counterANOVA, model.knotdims{idx(i)});
                for j = usedBasis
                    idx(idx == j) = 0;
                end
            end
            return

            function usedBasis = printLine(model, Xtr, Ytr, weights, gcvNull, counterANOVA, combination)
                [modelReduced, usedBasis] = aresanovareduce(model, combination, true);
                counterStr = num2str(counterANOVA);
                counterStr = [counterStr repmat(' ', 1, 4-length(counterStr))];
                fprintf(counterStr);
                % standard deviation of the ANOVA function
                fprintf('%15f', sqrt(var2(arespredict(modelReduced, Xtr), weights)));
                % GCV when the basis functions of the ANOVA function are deleted
                modelReduced = aresdel(model, usedBasis, Xtr, Ytr, weights);
                fprintf('%16f', modelReduced.GCV);
                if modelReduced.GCV / gcvNull - 1e-10 <= model.GCV / gcvNull % the same result as with gcvs alone but allows that 1e-10 stuff
                    fprintf(' !');
                else
                    fprintf('  ');
                end
                fprintf('%12.5f', 1 - modelReduced.GCV / gcvNull);
                % the number of basis functions for that ANOVA function
                fprintf('%13d', length(usedBasis));
                % effective number of parameters
                fprintf('%9.2f     ', length(usedBasis) + model.trainParams.c * length(usedBasis) / 2);
                % used variables
                fprintf('%d ', sort(combination));
                fprintf('\n');
                return

                function res = var2(values, weights)
                    % Calculates variance either normally or using weights
                    if isempty(weights)
                        res = var(values, 1);
                    else
                        valuesMean = sum(values(:,1) .* weights) / sum(weights);
                        res = sum(((values(:,1) - valuesMean) .^ 2) .* weights) / sum(weights);
                    end
                    return

                    function g = gcv(nBasis, MSE, n, c)
                        % Calculates GCV from model complexity, its Mean Squared Error, number of
                        % observations n, and penalty c.
                        enp = nBasis + c * (nBasis - 1) / 2; % model's effective number of parameters
                        if enp >= n
                            g = Inf;
                        else
                            p = 1 - enp / n;
                            g = MSE / (p * p);
                        end
                        return


                    end
                end
            end
        end

        function [model, usedBasis] = aresanovareduce(app,model, varsToStay, exact)
            % aresanovareduce
            % Deletes all the basis functions from ARES model (without recalculating
            % model's coefficients and relocating additional knots of piecewise-cubic
            % models) in which at least one used variable is not in the given list of
            % allowed variables. This can be used to perform ANOVA decomposition as
            % well as for investigation of individual and joint contributions of
            % variables in the model, i.e., the reduced model can then be plotted to
            % visualize the contributions.
            % For multi-response modelling, supply one submodel at a time.
            %
            % Call:
            %   [model, usedBasis] = aresanovareduce(model, varsToStay, exact)
            %
            % Input:
            %   model         : ARES model.
            %   varsToStay    : A vector of indices for input variables to stay in the
            %                   model. The size of the vector should be between one and
            %                   the total number of input variables.
            %   exact         : Set this to true to get a model with only those basis
            %                   functions where the exact combination of variables is
            %                   present (default value = false). This is used from
            %                   function aresanova.
            %
            % Output:
            %   model         : Reduced ARES model.
            %   usedBasis     : Vector of original indices for basis functions still in
            %                   use.

            % =========================================================================
            % ARESLab: Adaptive Regression Splines toolbox for Matlab/Octave
            % Author: Gints Jekabsons (gints.jekabsons@rtu.lv)
            % URL: http://www.cs.rtu.lv/jekabsons/
            %
            % Copyright (C) 2009-2016  Gints Jekabsons
            %
            % This program is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or
            % (at your option) any later version.
            %
            % This program is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with this program. If not, see <http://www.gnu.org/licenses/>.
            % =========================================================================

            % Last update: May 15, 2016

            if nargin < 2
                error('Not enough input arguments.');
            end
            if isempty(varsToStay)
                error('varsToStay is empty.');
            end
            if length(model) > 1
                error('This function works with single-response models only. You can supply one submodel at a time.');
            else
                if iscell(model)
                    model = model{1};
                end
            end
            if (nargin < 3) || isempty(exact)
                exact = false;
            end

            nBasis = length(model.knotdims);
            notvars = setdiff(1:length(model.minX), varsToStay);
            stay = true(1,nBasis);

            for i = 1 : nBasis
                if exact && (length(unique(model.knotdims{i})) ~= length(varsToStay))
                    stay(i) = false;
                    continue;
                end
                for j = 1 : length(notvars)
                    if any(model.knotdims{i} == notvars(j))
                        stay(i) = false;
                        break;
                    end
                end
            end

            usedBasis = find(stay);

            for i = nBasis : -1 : 1 % deleting in reverse order
                if ~stay(i)
                    model.coefs(i+1) = [];
                    model.knotdims(i) = [];
                    model.knotsites(i) = [];
                    model.knotdirs(i) = [];
                    model.parents(i) = [];
                    model.parents = updateParents(model.parents, i);
                    if model.trainParams.cubic
                        model.t1(i,:) = [];
                        model.t2(i,:) = [];
                    end
                    if isfield(model, 'X')
                        model.X(:,i+1) = [];
                    end
                end
            end

            return
        end
        
        function parents = updateParents(app,parents, deletedIdx)
            % Updates direct parent indices after deletion of a basis function.
            parents(parents == deletedIdx) = 0;
            tmp = parents > deletedIdx;
            parents(tmp) = parents(tmp) - 1;
            return
        end

        function [model, time, resultsEval] = aresbuild(app,Xtr, Ytr, trainParams, ...
                weights, keepX, modelOld, dataEval, verbose)
            % aresbuild
            % Builds a regression model using the Multivariate Adaptive Regression
            % Splines method.
            %
            % Call:
            %   [model, time, resultsEval] = aresbuild(Xtr, Ytr, trainParams, ...
            %       weights, keepX, modelOld, dataEval, verbose)
            %
            % All the input arguments, except the first two, are optional. Empty values
            % are also accepted (the corresponding defaults will be used).
            %
            % Input:
            %   Xtr, Ytr      : Xtr is a matrix with rows corresponding to
            %                   observations and columns corresponding to input
            %                   variables. Ytr is either a column vector of response
            %                   values or, for multi-response data, a matrix with
            %                   columns corresponding to response variables. The
            %                   structure of the output of this function changes
            %                   depending on whether Ytr is a vector or a matrix (see
            %                   below).
            %                   Xtr type must be double. Ytr type must be double or
            %                   logical (internally converted to double). Categorical
            %                   variables in Xtr with more than two categories must be
            %                   replaced with synthetic binary variables before using
            %                   aresbuild (or any other ARESLab function), for example
            %                   using function dummyvar.
            %                   For multi-response data, each model will have the same
            %                   set of basis functions but different coefficients. The
            %                   models are built and pruned as usual but with the
            %                   Residual Sum of Squares and GCVs summed across all
            %                   responses. Since all the models are optimized
            %                   simultaneously, the results for each model won't be as
            %                   good as building the models independently. However, the
            %                   combined model may be better in other senses, depending
            %                   on what you are trying to achieve. For example, it
            %                   could be useful to select the set of basis functions
            %                   that is best across all responses.
            %                   It is recommended to pre-scale Xtr values to [0,1]
            %                   (Friedman, 1991a). This is because widely different
            %                   locations and scales for the input variables can cause
            %                   instabilities that could affect the quality of the
            %                   final model. The MARS method is (except for
            %                   numerics) invariant to the locations and scales of the
            %                   input variables. It is therefore reasonable to perform
            %                   a transformation that causes resulting locations and
            %                   scales to be most favourable from the point of view of
            %                   numeric stability (Friedman, 1991a).
            %                   For multi-response modelling, it is recommended to
            %                   pre-scale Ytr values so that each response variable
            %                   gets the appropriate weight during model building. A
            %                   variable with higher variance will influence the
            %                   results more than a variable with lower variance
            %                   (Milborrow, 2016).
            %   trainParams   : A structure of training parameters for the algorithm.
            %                   If not provided, default values will be used (see
            %                   function aresparams for details).
            %   weights       : A vector of observation weights. The length of the
            %                   vector must be the same as the number of observations
            %                   in Xtr and Ytr. The weights must be nonnegative.
            %   keepX         : Set to true to retain basis matrix model.X (see
            %                   description of model.X). For multi-response modelling,
            %                   the matrix will be replicated for each model. (default
            %                   value = false)
            %   modelOld      : If an already built ARES model is provided (whether
            %                   pruned or not), no forward phase will be done. Instead
            %                   the provided model will be taken directly to the
            %                   backward phase and pruned. This is useful for fast
            %                   tuning of parameters of the backward phase (c, cubic,
            %                   maxFinalFuncs). Note that this is also a much faster
            %                   way of changing a piecewise-linear model into a
            %                   piecewise-cubic model or vice versa instead of building
            %                   a new model from scratch. This argument is also used by
            %                   function arescvc for fast selection of the "best" value
            %                   for penalty c using Cross-Validation.
            %   dataEval      : A structure containing test data in fields X, Y, and,
            %                   optionally, weights. Used for getting evaluations for
            %                   the best candidate models of each size in the backward
            %                   pruning phase. For example, arescv uses it to help
            %                   choosing a good value for the number of basis functions
            %                   using Cross-Validation (see example of usage in user's
            %                   manual Section 3.3). Results are saved in fields R2test
            %                   and MSEtest of output argument resultsEval.
            %   verbose       : Whether to output additional information to console
            %                   (default value = true).
            %
            % Output:
            %   model         : A single ARES model for single-response Ytr or a cell
            %                   array of ARES models for multi-response Ytr. A
            %                   structure defining one model has the following fields:
            %     coefs       : Coefficients vector of the regression model (first, for
            %                   the intercept term, and then for all the rest of basis
            %                   functions). Because of the coefficient for the
            %                   intercept term, this vector is one row longer than the
            %                   others.
            %     knotdims	  : Cell array of indices of used input variables for knots
            %                   in each basis function.
            %     knotsites	  : Cell array of knot sites for each knot and used input
            %                   variable in each basis function. knotdims and knotsites
            %                   together contain all the information for locating the
            %                   knots. If a variable entered a basis function linearly
            %                   (i.e., without hinge function), the knot site for that
            %                   variable is set to minX.
            %     knotdirs	  : Cell array of directions (-1 or 1) of the hinge
            %                   functions for each used input variable in each basis
            %                   function. If a variable entered a basis function
            %                   linearly (i.e., without hinge function), the direction
            %                   for that variable is set to 2.
            %     parents     : Vector of indices of direct parents for each basis
            %                   function (0 if there is no direct parent).
            %     trainParams : A structure of training parameters for the algorithm.
            %                   The values are updated if chosen automatically. Except
            %                   useMinSpan, because in automatic mode it is calculated
            %                   for each parent basis function separately.
            %     MSE         : Mean Squared Error of the model in the training data.
            %     GCV         : Generalized Cross-Validation of the model in the
            %                   training data set. The value may also be Inf if model’s
            %                   effective number of parameters is larger than or equal
            %                   to the number of observations in the training data.
            %     t1, t2      : Matrix of sites for the additional side knots on the
            %                   left and on the right of the central knot.
            %     minX, maxX  : Vectors defining the ranges of the input variables
            %                   determined from the training data.
            %     isBinary    : A vector indicating binary input variables. Determined
            %                   automatically by counting unique values for each
            %                   variable in training data. Therefore a variable can
            %                   also be taken as binary by mistake if the data for some
            %                   reason included only two values for the variable. Note
            %                   that whether a variable is binary does not influence
            %                   building of the model. This vector is further used in
            %                   other functions to simplify printed equations.
            %     X           : Basis matrix. Contains values of basis functions
            %                   applied to Xtr. The number of columns in X is equal to
            %                   the number of rows in coefs, i.e., the first column is
            %                   for the intercept (all ones) and all the other columns
            %                   correspond to the basis functions defined by knotdims,
            %                   knotsites, knotdirs, t1, and t2. Each row corresponds
            %                   to a row in Xtr. Multiplying X by coefs gives ARES
            %                   prediction for Ytr. This variable is available only if
            %                   argument keepX is set to true.
            %   time          : Algorithm execution time (in seconds).
            %   resultsEval   : Model evaluation results from the backward pruning
            %                   phase. Fields R2test and MSEtest are available only if
            %                   input argument dataEval is not empty. The structure has
            %                   the following fields:
            %     MSE         : MSE (Mean Squared Error) in training data for the best
            %                   candidate model of each size.
            %     R2          : R2 (Coefficient of Determination) in training data for
            %                   the best candidate model of each size.
            %     GCV         : GCV (Generalized Cross-Validation) in training data for
            %                   the best candidate model of each size. Contains Inf
            %                   values for models with effective number of parameters
            %                   larger than the number of observations in training
            %                   data.
            %     R2GCV       : R2 estimated by GCV in training data for the best
            %                   candidate model of each size. Contains -Inf values for
            %                   models with effective number of parameters larger than
            %                   the number of observations in training data.
            %     R2test      : R2 in dataEval test data for the best candidate model
            %                   of each size.
            %     MSEtest     : MSE in dataEval test data for the best candidate model
            %                   of each size.
            %                   Note that if trainParams.cubic = true, values of these
            %                   fields are calculated using piecewise-linear models if
            %                   trainParams.cubicFastLevel = 2 and piecewise-cubic
            %                   models if trainParams.cubicFastLevel < 2.
            %     usedVars    : Logical matrix showing which input variables were used
            %                   in the best candidate model of each size.

            % =========================================================================
            % ARESLab: Adaptive Regression Splines toolbox for Matlab/Octave
            % Author: Gints Jekabsons (gints.jekabsons@rtu.lv)
            % URL: http://www.cs.rtu.lv/jekabsons/
            %
            % Copyright (C) 2009-2016  Gints Jekabsons
            %
            % This program is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or
            % (at your option) any later version.
            %
            % This program is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with this program. If not, see <http://www.gnu.org/licenses/>.
            % =========================================================================

            % Citing the ARESLab toolbox:
            % Jekabsons G., ARESLab: Adaptive Regression Splines toolbox for Matlab/
            % Octave, 2016, available at http://www.cs.rtu.lv/jekabsons/

            % Last update: May 15, 2016

            if nargin < 2
                error('Not enough input arguments.');
            end

            if isempty(Xtr) || isempty(Ytr)
                error('Training data is empty.');
            end
            if iscell(Xtr) || iscell(Ytr)
                error('Xtr and Ytr should not be cell arrays.');
            end
            if islogical(Ytr)
                Ytr = double(Ytr);
            elseif ~isfloat(Ytr)
                error('Ytr data type should be double or logical.');
            end
            if ~isfloat(Xtr)
                error('Xtr data type should be double.');
            end
            if any(any(isnan(Xtr)))
                error('ARESLab cannot handle missing values (NaN).');
            end

            [n, d] = size(Xtr); % number of observations and number of input variables
            [ny, dy] = size(Ytr); % number of observations and number of output variables
            if ny ~= n
                error('The number of rows in Xtr and Ytr should be equal.');
            end
            if (ny == 1) && (dy > 1)
                warning('Ytr has one row but more than one column.');
            end

            if (nargin < 3) || isempty(trainParams)
                trainParams = aresparams();
            end
            if (trainParams.cubic) && (trainParams.selfInteractions > 1)
                trainParams.selfInteractions = 1;
                warning('trainParams.selfInteractions value changed to 1 due to piecewise-cubic setting.');
            end
            if trainParams.maxInteractions < 0
                trainParams.maxInteractions = d; % for maximal interactivity (except if selfInteractions are used)
            end
            if trainParams.cubic
                doCubicFastLevel = trainParams.cubicFastLevel;
                if trainParams.cubicFastLevel > 0
                    trainParams.cubic = false; % let's turn it off until the backward phase or the final model
                end
            else
                doCubicFastLevel = -1; % no piecewise-cubic modelling
            end
            if (trainParams.endSpanAdjust < 1)
                trainParams.endSpanAdjust = 1;
                warning('trainParams.endSpanAdjust value too small. Changed to 1.');
            end

            if (trainParams.fastK < 3)
                trainParams.fastK = 3;
                warning('trainParams.fastK value too small. Changed to 3.');
            end
            if (trainParams.fastBeta < 0)
                trainParams.fastBeta = 0;
                warning('trainParams.fastBeta value too small. Changed to 0.');
            end
            if (trainParams.fastH < 1)
                trainParams.fastH = 1;
                warning('trainParams.fastH value too small. Changed to 1.');
            end

            if trainParams.useMinSpan == 0
                trainParams.useMinSpan = 1; % 1 and 0 is the same here (no minspan)
            end

            if (nargin < 4)
                weights = [];
            else
                if (~isempty(weights)) && ...
                        ((size(weights,1) ~= n) || (size(weights,2) ~= 1))
                    error('weights vector is of wrong size.');
                end
                sumWeights = sum(weights);
            end
            if (nargin < 5) || isempty(keepX)
                keepX = false;
            end
            if nargin < 6
                modelOld = [];
            else
                if ~isempty(modelOld)
                    if (dy > 1) && (length(modelOld) ~= dy)
                        error('modelOld should contain as many models as there are columns in Ytr.');
                    end
                    if ((dy == 1) && (length(modelOld.minX) ~= d)) || ...
                            ((dy > 1) && (length(modelOld{1}.minX) ~= d))
                        error('The number of columns in Xtr is different from the number when the modelOld was built.');
                    end
                end
            end
            if (nargin < 7)
                dataEval = [];
            end
            if (nargin < 8) || isempty(verbose)
                verbose = true;
            end

            requestingResultsEval = nargout >= 3;
            resultsEval = [];

            if ~isempty(trainParams.noInteract)
                if ~isempty(trainParams.yesInteract)
                    error('trainParams.noInteract and trainParams.yesInteract cannot be used at the same time.');
                end
                if size(trainParams.noInteract,2) ~= 2
                    error('trainParams.noInteract should have two columns.');
                end
                trainParams.noInteract = [trainParams.noInteract; trainParams.noInteract(:,2) trainParams.noInteract(:,1)];
                trainParams.noInteract = unique(trainParams.noInteract, 'rows');
            elseif ~isempty(trainParams.yesInteract)
                if size(trainParams.yesInteract,2) ~= 2
                    error('trainParams.yesInteract should have two columns.');
                end
                trainParams.yesInteract = [trainParams.yesInteract; trainParams.yesInteract(:,2) trainParams.yesInteract(:,1)];
                trainParams.yesInteract = unique(trainParams.yesInteract, 'rows');
            end

            if trainParams.maxFuncs >= 0
                maxIters = floor((trainParams.maxFuncs - 1) / 2); % because basis functions are added two at a time
            else
                trainParams.maxFuncs = min(200, max(20, 2 * d)) + 1; % default number of basis functions
                maxIters = floor(trainParams.maxFuncs / 2); % because basis functions are added two at a time
                if verbose, fprintf('Setting trainParams.maxFuncs to %d\n', trainParams.maxFuncs); end
            end

            if (trainParams.c < 0)
                if trainParams.maxInteractions > 1
                    trainParams.c = 3;
                else
                    trainParams.c = 2; % penalty coefficient for additive modelling
                end
                if verbose, fprintf('Setting trainParams.c to %d\n', trainParams.c); end
            end

            if ~any([0 1 2] == trainParams.allowLinear)
                error('Expected values for trainParams.allowLinear are 0, 1 or 2.');
            end

            if verbose, fprintf('Building ARES model...\n'); end
            ttt = tic;

            if isempty(weights)
                YtrMean = mean(Ytr,1);
            else
                YtrMean = zeros(1,dy);
                for k = 1 : dy
                    YtrMean(k) = sum(Ytr(:,k) .* weights) / sumWeights; % Ytr weighted mean
                end
            end
            YtrVarN = zeros(1,dy);
            if isempty(weights)
                for k = 1 : dy
                    YtrVarN(k) = sum((Ytr(:,k) - YtrMean(k)) .^ 2); % Ytr variance * n (i.e., null SSE)
                end
                YtrVar = YtrVarN / n; % Ytr variance (i.e., null MSE)
            else
                for k = 1 : dy
                    YtrVarN(k) = sum(((Ytr(:,k) - YtrMean(k)) .^ 2) .* weights); % (i.e., null SSE)
                end
                YtrVar = YtrVarN / sumWeights; % Ytr weighted variance (i.e., null MSE)
            end

            minX = min(Xtr);
            maxX = max(Xtr);
            if isempty(modelOld)
                isBinary = [];
            else
                if dy == 1
                    isBinary = modelOld.isBinary;
                else
                    isBinary = modelOld{1}.isBinary;
                end
            end

            if trainParams.useEndSpan < 0
                trainParams.useEndSpan = getEndSpan(d); % automatic
            end

            if dy > 1
                modelsY = cell(dy, 1);
            end

            if isempty(modelOld)
                X = ones(n,1);
                err = 1; % normalized error for the constant model
                model.MSE = Inf;
                model.GCV = Inf;
                if dy == 1
                    model.coefs = YtrMean;
                else
                    model.coefs = YtrMean(1);
                    for k = 1 : dy
                        modelsY{k}.coefs = YtrMean(k);
                        modelsY{k}.MSE = Inf;
                        modelsY{k}.GCV = Inf;
                    end
                end
                model.knotdims = {};
                model.knotsites = {};
                model.knotdirs = {};
                model.parents = [];
                model.trainParams = [];
            else
                % modelOld is the initial model
                if dy == 1
                    model = modelOld;
                else
                    model = modelOld{1};
                    for k = 1 : dy
                        modelsY{k}.coefs = modelOld{k}.coefs;
                        modelsY{k}.MSE = modelOld{k}.MSE;
                        modelsY{k}.GCV = modelOld{k}.GCV;
                    end
                end
            end

            origWarningState = warning;
            if exist('OCTAVE_VERSION', 'builtin')
                warning('off', 'Octave:nearly-singular-matrix');
                warning('off', 'Octave:singular-matrix');
            else
                warning('off', 'MATLAB:nearlySingularMatrix');
                warning('off', 'MATLAB:singularMatrix');
            end

            if trainParams.useEndSpan * 2 >= n
                warning('trainParams.useEndSpan * 2 >= n');
                if isempty(modelOld)
                    isBinary = false(1,d);
                    if dy == 1
                        model.MSE = YtrVar;
                        model.GCV = gcv(length(model.coefs), model.MSE, n, trainParams.c);
                    else
                        model.MSE = 0;
                        model.GCV = 0;
                        for k = 1 : dy
                            modelsY{k}.MSE = YtrVar(k);
                            modelsY{k}.GCV = gcv(length(model.coefs), modelsY{k}.MSE, n, trainParams.c);
                            model.MSE = model.MSE + modelsY{k}.MSE;
                            model.GCV = model.GCV + modelsY{k}.GCV;
                        end
                    end
                    if trainParams.cubic
                        model.t1 = [];
                        model.t2 = [];
                    end
                end
            else

                % FORWARD PHASE

                if isempty(modelOld) % no forward phase when modelOld is used

                    if verbose && (maxIters < 1)
                        fprintf('Forward phase  .');
                    end

                    % create sorted lists of observations for knot placements
                    [sortedXtr, sortedXtrInd] = sort(Xtr);
                    % check whether any input variable could be binary
                    isBinary = false(1,d);
                    for i = 1 : d
                        %isBinary(i) = all((sortedXtr(:,i) == sortedXtr(1,i)) | (sortedXtr(:,i) == sortedXtr(end,i)));
                        isBinary(i) = numel(unique(sortedXtr(:,i))) == 2;
                    end
                    % is endSpan is used, throw away observations at the ends of the intervals
                    if trainParams.useEndSpan ~= 0
                        sortedXtr = sortedXtr(1+trainParams.useEndSpan:end-trainParams.useEndSpan,:);
                        sortedXtrInd = sortedXtrInd(1+trainParams.useEndSpan:end-trainParams.useEndSpan,:);
                    end

                    if verbose
                        sizeInfGCV = NaN;
                        countKnots = zeros(1,d);
                    end

                    if trainParams.cubic
                        tmp_t1 = [];
                        tmp_t2 = [];
                    end
                    basisFunctionList = {}; % will contain candidate basis functions
                    numNewFuncs = 0; % how many basis functions added in the last iteration
                    if (trainParams.newVarPenalty > 0)
                        dimsInModel = []; % lists all input variables the model uses
                    end

                    if (trainParams.fastK < Inf)
                        % we could preallocate space here but benchmarking shows that
                        % there is not much to be gained from this
                        fastParent = {};
                        fastMinErrAdj = [];
                        fastIterComputedErr = [];
                        fastNumBasis = 0;
                    end

                    % the main loop of the forward phase
                    for currIter = 1 : maxIters
                        % create list of all possible daughter basis functions
                        [basisFunctionList, idxStart1, idxEnd1, idxStart2, idxEnd2] = ...
                            createList(basisFunctionList, Xtr, sortedXtr, sortedXtrInd, ...
                            n, d, model, numNewFuncs, trainParams, minX, maxX);

                        % stop the forward phase if basisFunctionList is empty
                        if isempty(basisFunctionList)
                            if trainParams.cubic
                                t1 = tmp_t1;
                                t2 = tmp_t2;
                            end
                            if verbose
                                if (currIter == 1)
                                    fprintf('Forward phase  .');
                                end
                                fprintf('\nTermination condition is met: no more basis functions to add.');
                            end
                            break;
                        end

                        % count the number of knot locations for the basis functions
                        if verbose && (currIter == 1)
                            for i = 1 : size(basisFunctionList,2)
                                countKnots(1,basisFunctionList{1,i}) = countKnots(1,basisFunctionList{1,i}) + 1;
                            end
                            fprintf('Approx number of available knot locations (controlled by useMinSpan and useEndSpan):%s\n', ...
                                sprintf(' x%d:%d', [1:length(countKnots); countKnots]));
                            fprintf('Forward phase  .');
                        end

                        % preallocate space
                        % (we could allocate less but this is simpler)
                        tmpErr = inf(1,size(basisFunctionList,2));
                        if dy == 1
                            tmpCoefs = inf(length(model.coefs)+2, size(basisFunctionList,2));
                        else
                            tmpErrVar = zeros(1,size(basisFunctionList,2));
                            tmpCoefs = cell(dy, 1);
                            for k = 1 : dy
                                tmpCoefs{k} = inf(length(model.coefs)+2, size(basisFunctionList,2));
                            end
                        end
                        if trainParams.cubic
                            Xtmp = zeros(n,size(X,2)+2);
                        else
                            Xtmp = [X zeros(n,2)];
                        end

                        if (trainParams.fastK < Inf) % using the Fast MARS algorithm

                            % initialize info about the two new parent basis functions
                            if ~isempty(basisFunctionList)
                                if (idxStart1 > 0)
                                    fastNumBasis = fastNumBasis + 1;
                                    fastParent{fastNumBasis}.idxStart = idxStart1;
                                    fastParent{fastNumBasis}.idxEnd = idxEnd1;
                                    fastMinErrAdj(fastNumBasis) = -Inf;
                                    fastIterComputedErr(fastNumBasis) = currIter;
                                    fastParent{fastNumBasis}.iterComputedAllDims = -Inf;
                                end
                                if (idxStart2 > 0)
                                    fastNumBasis = fastNumBasis + 1;
                                    fastParent{fastNumBasis}.idxStart = idxStart2;
                                    fastParent{fastNumBasis}.idxEnd = idxEnd2;
                                    fastMinErrAdj(fastNumBasis) = -Inf;
                                    fastIterComputedErr(fastNumBasis) = currIter;
                                    fastParent{fastNumBasis}.iterComputedAllDims = -Inf;
                                end
                            end

                            % make a selection of parent basis functions to try
                            lenR = length(fastParent);
                            if lenR > trainParams.fastK
                                % ranking and prioritizing
                                [~, idxR] = sort(fastMinErrAdj, 2, 'descend'); % sort by err
                                idxR(idxR) = 1:lenR; % get ranks in the original order
                                idxInf = fastMinErrAdj == -Inf; % special case
                                idxR(idxInf) = idxR(idxInf) + 1E6; % so that -Inf is never outprioritized
                                priority = idxR + trainParams.fastBeta * (repmat(currIter, 1, lenR) - fastIterComputedErr); % calculate priorities
                                [~, idxP] = sort(priority, 2); % sort (with equal priorities, most recent will be first)
                                indToCalc = idxP(end:-1:end-trainParams.fastK+1); % indices of parents to try
                            else
                                indToCalc = 1 : lenR;
                            end
                            fastIterComputedErr(indToCalc) = currIter;

                            bestParent = -1;
                            bestErrAdj = Inf;
                            % try the selected parent basis functions
                            for ir = indToCalc
                                % let's see if only a preselected dimension should be checked
                                checkPreselectedDimOnly = currIter - fastParent{ir}.iterComputedAllDims < trainParams.fastH;
                                if (~checkPreselectedDimOnly)
                                    fastParent{ir}.iterComputedAllDims = currIter;
                                end
                                idxStart = fastParent{ir}.idxStart;
                                idxEnd = fastParent{ir}.idxEnd;
                                % try all the reflected pairs (daughter basis functions) in the list
                                for i = idxStart : idxEnd
                                    if  checkPreselectedDimOnly && (basisFunctionList{1,i}(end) ~= fastParent{ir}.bestDim)
                                        %tmpErr(i) = Inf; % unnecessary because the array is already initialized with Inf
                                        continue;
                                    end
                                    if trainParams.cubic
                                        [t1, t2, diff] = findsideknots(model, basisFunctionList{1,i}, basisFunctionList{2,i}, ...
                                            d, minX, maxX, tmp_t1, tmp_t2);
                                        Xtmp(:,1:end-2) = X;
                                        % update basis functions with the updated side knots
                                        for j = diff
                                            Xtmp(:,j+1) = createbasisfunction(Xtr, Xtmp, model.knotdims{j}, model.knotsites{j}, ...
                                                model.knotdirs{j}, model.parents(j), minX, maxX, t1(j,:), t2(j,:));
                                        end
                                        % New basis function
                                        dirs = basisFunctionList{3,i};
                                        Xtmp(:,end-1) = createbasisfunction(Xtr, Xtmp, basisFunctionList{1,i}, basisFunctionList{2,i}, ...
                                            dirs, basisFunctionList{4,i}, minX, maxX, t1(end,:), t2(end,:));
                                        ok1 = ~isnan(Xtmp(1,end-1));
                                        % Reflected partner
                                        dirs(end) = -dirs(end);
                                        Xtmp(:,end) = createbasisfunction(Xtr, Xtmp, basisFunctionList{1,i}, basisFunctionList{2,i}, ...
                                            dirs, basisFunctionList{4,i}, minX, maxX, t1(end,:), t2(end,:));
                                        ok2 = ~isnan(Xtmp(1,end));
                                    else
                                        % New basis function
                                        dirs = basisFunctionList{3,i};
                                        Xtmp(:,end-1) = createbasisfunction(Xtr, Xtmp, basisFunctionList{1,i}, ...
                                            basisFunctionList{2,i}, dirs, basisFunctionList{4,i}, minX, maxX);
                                        ok1 = ~isnan(Xtmp(1,end-1));
                                        % Reflected partner
                                        dirs(end) = -dirs(end);
                                        Xtmp(:,end) = createbasisfunction(Xtr, Xtmp, basisFunctionList{1,i}, ...
                                            basisFunctionList{2,i}, dirs, basisFunctionList{4,i}, minX, maxX);
                                        ok2 = ~isnan(Xtmp(1,end));
                                    end
                                    if ok1 && ok2 % both basis functions created
                                        if dy == 1
                                            [tmpCoefs(:,i), tmpErr(i)] = lreg(Xtmp, Ytr, weights);
                                        else
                                            tmpErr(i) = 0;
                                            tmpErrVar(i,:) = 0;
                                            for k = 1 : dy
                                                [tmpCoefs{k}(:,i), sse] = lreg(Xtmp, Ytr(:,k), weights);
                                                tmpErr(i) = tmpErr(i) + sse;
                                                tmpErrVar(i) = tmpErrVar(i) + sse / YtrVarN(k);
                                            end
                                            %tmpErr(i) = tmpErr(i) / dy;
                                        end
                                    elseif ok1 || ok2 % one of the basis functions not created
                                        if dy == 1
                                            if (ok1)
                                                [coefs, tmpErr(i)] = lreg(Xtmp(:, 1:end-1), Ytr, weights);
                                            else
                                                [coefs, tmpErr(i)] = lreg([Xtmp(:, 1:end-2) Xtmp(:, end)], Ytr, weights);
                                            end
                                            tmpCoefs(:,i) = [coefs; NaN];
                                        else
                                            tmpErr(i) = 0;
                                            tmpErrVar(i,:) = 0;
                                            for k = 1 : dy
                                                if (ok1)
                                                    [coefs, sse] = lreg(Xtmp(:, 1:end-1), Ytr(:,k), weights);
                                                else
                                                    [coefs, sse] = lreg([Xtmp(:, 1:end-2) Xtmp(:, end)], Ytr(:,k), weights);
                                                end
                                                tmpCoefs{k}(:,i) = [coefs; NaN];
                                                tmpErr(i) = tmpErr(i) + sse;
                                                tmpErrVar(i) = tmpErrVar(i) + sse / YtrVarN(k);
                                            end
                                            %tmpErr(i) = tmpErr(i) / dy;
                                        end
                                        %else % no basis function created
                                        %    tmpErr(i) = Inf; % unnecessary because the array is already initialized as Inf
                                    end
                                end

                                % find the best pair of daughter basis functions from this parent
                                isNewVarPenalty = ((trainParams.newVarPenalty > 0) && (currIter > 1));
                                isPreferLinear = trainParams.allowLinear == 2;
                                if isNewVarPenalty || isPreferLinear
                                    adjust = ones(1, idxEnd - idxStart + 1);
                                end
                                if isNewVarPenalty
                                    % score adjustment due to new variable entering the model
                                    for i = idxStart : idxEnd
                                        if (~isempty(setdiff(basisFunctionList{1,i}, dimsInModel)))
                                            adjust(i - idxStart + 1) = 1 + trainParams.newVarPenalty;
                                        end
                                    end
                                end
                                if isPreferLinear
                                    % score adjustment due to preference of variables entering linearly
                                    gcvCorrection = gcv(length(model.coefs) + 2, 1, n, trainParams.c);
                                    if isfinite(gcvCorrection)
                                        gcvCorrection = 1 / gcvCorrection * gcv(length(model.coefs) + 1, 1, n, trainParams.c);
                                        for i = idxStart : idxEnd
                                            if basisFunctionList{3,i}(end) == 2
                                                adjust(i - idxStart + 1) = adjust(i - idxStart + 1) * gcvCorrection;
                                            end
                                        end
                                    end
                                end
                                if isNewVarPenalty || isPreferLinear
                                    % the adjusted values are used for selection only,
                                    % they are not used in any further calculations
                                    [newErrAdj, ind] = min(tmpErr(idxStart:idxEnd) .* adjust);
                                else
                                    [newErrAdj, ind] = min(tmpErr(idxStart:idxEnd));
                                end
                                ind = ind + idxStart - 1;
                                if dy == 1
                                    newErr = tmpErr(ind);
                                else
                                    newErr = tmpErrVar(ind) / dy;
                                end
                                fastParent{ir}.bestDim = basisFunctionList{1,ind}(end);
                                fastParent{ir}.bestIdxInList = ind;
                                fastParent{ir}.bestErr = newErr;
                                fastMinErrAdj(ir) = newErrAdj;
                                % continue the search for best daughters from all parents
                                if bestErrAdj > newErrAdj
                                    bestErrAdj = newErrAdj;
                                    bestParent = ir;
                                end
                            end
                            % we have found the best pair of daughter basis functions
                            ind = fastParent{bestParent}.bestIdxInList;
                            if dy == 1
                                newErr = fastParent{bestParent}.bestErr / YtrVarN;
                            else
                                newErr = fastParent{bestParent}.bestErr;
                            end
                            % reset the parent so that full optimization is done in next iteration
                            fastMinErrAdj(bestParent) = -Inf;
                            %fastParent{bestParent}.bestDim = -1;
                            fastParent{bestParent}.iterComputedAllDims = -Inf;

                        else % else of "if (trainParams.fastK < Inf)"

                            % try all the reflected pairs in the list
                            for i = 1 : size(basisFunctionList,2)
                                if trainParams.cubic
                                    [t1, t2, diff] = findsideknots(model, basisFunctionList{1,i}, basisFunctionList{2,i}, ...
                                        d, minX, maxX, tmp_t1, tmp_t2);
                                    Xtmp(:,1:end-2) = X;
                                    % update basis functions with the updated side knots
                                    for j = diff
                                        Xtmp(:,j+1) = createbasisfunction(Xtr, Xtmp, model.knotdims{j}, model.knotsites{j}, ...
                                            model.knotdirs{j}, model.parents(j), minX, maxX, t1(j,:), t2(j,:));
                                    end
                                    % New basis function
                                    dirs = basisFunctionList{3,i};
                                    Xtmp(:,end-1) = createbasisfunction(Xtr, Xtmp, basisFunctionList{1,i}, basisFunctionList{2,i}, ...
                                        dirs, basisFunctionList{4,i}, minX, maxX, t1(end,:), t2(end,:));
                                    ok1 = ~isnan(Xtmp(1,end-1));
                                    % Reflected partner
                                    dirs(end) = -dirs(end);
                                    Xtmp(:,end) = createbasisfunction(Xtr, Xtmp, basisFunctionList{1,i}, basisFunctionList{2,i}, ...
                                        dirs, basisFunctionList{4,i}, minX, maxX, t1(end,:), t2(end,:));
                                    ok2 = ~isnan(Xtmp(1,end));
                                else
                                    % New basis function
                                    dirs = basisFunctionList{3,i};
                                    Xtmp(:,end-1) = createbasisfunction(Xtr, Xtmp, basisFunctionList{1,i}, ...
                                        basisFunctionList{2,i}, dirs, basisFunctionList{4,i}, minX, maxX);
                                    ok1 = ~isnan(Xtmp(1,end-1));
                                    % Reflected partner
                                    dirs(end) = -dirs(end);
                                    Xtmp(:,end) = createbasisfunction(Xtr, Xtmp, basisFunctionList{1,i}, ...
                                        basisFunctionList{2,i}, dirs, basisFunctionList{4,i}, minX, maxX);
                                    ok2 = ~isnan(Xtmp(1,end));
                                end
                                if ok1 && ok2 % both basis functions created
                                    if dy == 1
                                        [tmpCoefs(:,i), tmpErr(i)] = lreg(Xtmp, Ytr, weights);
                                    else
                                        tmpErr(i) = 0;
                                        tmpErrVar(i,:) = 0;
                                        for k = 1 : dy
                                            [tmpCoefs{k}(:,i), sse] = lreg(Xtmp, Ytr(:,k), weights);
                                            tmpErr(i) = tmpErr(i) + sse;
                                            tmpErrVar(i) = tmpErrVar(i) + sse / YtrVarN(k);
                                        end
                                        %tmpErr(i) = tmpErr(i) / dy;
                                    end
                                elseif ok1 || ok2 % one of the basis functions not created
                                    if dy == 1
                                        if (ok1)
                                            [coefs, tmpErr(i)] = lreg(Xtmp(:, 1:end-1), Ytr, weights);
                                        else
                                            [coefs, tmpErr(i)] = lreg([Xtmp(:, 1:end-2) Xtmp(:, end)], Ytr, weights);
                                        end
                                        tmpCoefs(:,i) = [coefs; NaN];
                                    else
                                        tmpErr(i) = 0;
                                        tmpErrVar(i,:) = 0;
                                        for k = 1 : dy
                                            if (ok1)
                                                [coefs, sse] = lreg(Xtmp(:, 1:end-1), Ytr(:,k), weights);
                                            else
                                                [coefs, sse] = lreg([Xtmp(:, 1:end-2) Xtmp(:, end)], Ytr(:,k), weights);
                                            end
                                            tmpCoefs{k}(:,i) = [coefs; NaN];
                                            tmpErr(i) = tmpErr(i) + sse;
                                            tmpErrVar(i) = tmpErrVar(i) + sse / YtrVarN(k);
                                        end
                                        %tmpErr(i) = tmpErr(i) / dy;
                                    end
                                    %else % no basis function created
                                    %    tmpErr(i) = Inf; % unnecessary because the array is already initialized as Inf
                                end
                            end

                            % find the best pair of daughter basis functions
                            isNewVarPenalty = (trainParams.newVarPenalty > 0) && (currIter > 1);
                            isPreferLinear = trainParams.allowLinear == 2;
                            if isNewVarPenalty || isPreferLinear
                                adjust = ones(1,size(basisFunctionList,2));
                            end
                            if isNewVarPenalty
                                % score adjustment due to new variable entering the model
                                for i = 1 : size(basisFunctionList,2)
                                    if (~isempty(setdiff(basisFunctionList{1,i}, dimsInModel)))
                                        adjust(i) = 1 + trainParams.newVarPenalty;
                                    end
                                end
                            end
                            if isPreferLinear
                                % score adjustment due to preference of variables entering linearly
                                gcvCorrection = gcv(length(model.coefs) + 2, 1, n, trainParams.c);
                                if isfinite(gcvCorrection)
                                    gcvCorrection = 1 / gcvCorrection * gcv(length(model.coefs) + 1, 1, n, trainParams.c);
                                    for i = 1 : size(basisFunctionList,2)
                                        if basisFunctionList{3,i}(end) == 2
                                            adjust(i) = adjust(i) * gcvCorrection;
                                        end
                                    end
                                end
                            end
                            if isNewVarPenalty || isPreferLinear
                                % the adjusted values are used for selection only,
                                % they are not used in any further calculations
                                [~, ind] = min(tmpErr .* adjust);
                            else
                                [~, ind] = min(tmpErr);
                            end
                            if dy == 1
                                newErr = tmpErr(ind) / YtrVarN;
                            else
                                newErr = tmpErrVar(ind) / dy;
                            end

                        end % end of "if (trainParams.fastK < Inf)"

                        % stop the forward phase if no correct model was created or
                        % if the decrease in error is below the threshold
                        if isnan(newErr) || (err - newErr < trainParams.threshold)
                            if trainParams.cubic
                                t1 = tmp_t1;
                                t2 = tmp_t2;
                            end
                            if verbose
                                if isnan(newErr)
                                    fprintf('\nTermination condition is met: more complex models could not be created.');
                                else
                                    fprintf('\nTermination condition is met: R2 improvement is below threshold.');
                                end
                            end
                            break;
                        end

                        if trainParams.cubic
                            [t1, t2, diff] = findsideknots(model, basisFunctionList{1,ind}, basisFunctionList{2,ind}, ...
                                d, minX, maxX, tmp_t1, tmp_t2);
                            % update basis functions with the updated side knots
                            for j = diff
                                X(:,j+1) = createbasisfunction(Xtr, X, model.knotdims{j}, model.knotsites{j}, ...
                                    model.knotdirs{j}, model.parents(j), minX, maxX, t1(j,:), t2(j,:));
                            end
                            % Add the new basis function
                            dirs = basisFunctionList{3, ind};
                            Xn = createbasisfunction(Xtr, X, basisFunctionList{1,ind}, basisFunctionList{2,ind}, ...
                                dirs, basisFunctionList{4,ind}, minX, maxX, t1(end,:), t2(end,:));
                            if isnan(Xn(1)), Xn = []; end
                            % Add the reflected partner
                            dirs(end) = -dirs(end);
                            Xn2 = createbasisfunction(Xtr, X, basisFunctionList{1,ind}, basisFunctionList{2,ind}, ...
                                dirs, basisFunctionList{4,ind}, minX, maxX, t1(end,:), t2(end,:));
                            if isnan(Xn2(1)), Xn2 = []; end
                            X = [X Xn Xn2];
                            if ~isempty(Xn) && ~isempty(Xn2) % both basis functions are created
                                t1(end+1,:) = t1(end,:);
                                t2(end+1,:) = t2(end,:);
                            end
                        else
                            dirs = basisFunctionList{3, ind};
                            % Add the new basis function
                            Xn = createbasisfunction(Xtr, X, basisFunctionList{1,ind}, ...
                                basisFunctionList{2,ind}, dirs, basisFunctionList{4,ind}, minX, maxX);
                            if isnan(Xn(1)), Xn = []; end
                            % Add the reflected partner
                            dirs(end) = -dirs(end);
                            Xn2 = createbasisfunction(Xtr, X, basisFunctionList{1,ind}, ...
                                basisFunctionList{2,ind}, dirs, basisFunctionList{4,ind}, minX, maxX);
                            if isnan(Xn2(1)), Xn2 = []; end
                            X = [X Xn Xn2];
                        end

                        if dy == 1
                            model.coefs = tmpCoefs(:,ind);
                        else
                            model.coefs = tmpCoefs{1}(:,ind);
                            for k = 1 : dy
                                modelsY{k}.coefs = tmpCoefs{k}(:,ind);
                            end
                        end

                        % add the basis functions to the model
                        numNewFuncs = 0;
                        dirs = basisFunctionList{3, ind};
                        if ~isempty(Xn)
                            model.knotdims{end+1,1} = basisFunctionList{1, ind};
                            model.knotsites{end+1,1} = basisFunctionList{2, ind};
                            model.knotdirs{end+1,1} = dirs;
                            model.parents(end+1,1) = basisFunctionList{4, ind};
                            numNewFuncs = numNewFuncs + 1;
                            if (trainParams.newVarPenalty > 0)
                                dimsInModel = union(dimsInModel, basisFunctionList{1, ind});
                            end
                        else
                            model.coefs(end) = [];
                            if dy > 1
                                for k = 1 : dy
                                    modelsY{k}.coefs(end) = [];
                                end
                            end
                        end
                        if ~isempty(Xn2)
                            dirs(end) = -dirs(end);
                            model.knotdims{end+1,1} = basisFunctionList{1, ind};
                            model.knotsites{end+1,1} = basisFunctionList{2, ind};
                            model.knotdirs{end+1,1} = dirs;
                            model.parents(end+1,1) = basisFunctionList{4, ind};
                            numNewFuncs = numNewFuncs + 1;
                            if (trainParams.newVarPenalty > 0)
                                dimsInModel = union(dimsInModel, basisFunctionList{1, ind});
                            end
                        else
                            model.coefs(end) = [];
                            if dy > 1
                                for k = 1 : dy
                                    modelsY{k}.coefs(end) = [];
                                end
                            end
                        end

                        if verbose
                            if getENP(length(model.coefs), trainParams.c) >= n
                                fprintf('xx'); % indicates that GCV would be Inf
                                if isnan(sizeInfGCV)
                                    sizeInfGCV = currIter * 2 + 1;
                                end
                            else
                                fprintf('..');
                            end
                        end

                        % stop the forward phase if newErr is too small or if the
                        % number of model's basis functions (including intercept term)
                        % in the next iteration is expected to be > n
                        err = newErr;
                        if newErr < trainParams.threshold
                            if verbose
                                fprintf('\nTermination condition is met: R2 >= 1 - threshold.');
                            end
                            break;
                        end
                        if length(model.coefs) + 2 > n
                            if verbose
                                fprintf('\nTermination condition is met: number of basis functions reached the number of observations in data.');
                            end
                            break;
                        end
                        if trainParams.terminateWhenInfGCV && (getENP(length(model.coefs), trainParams.c) >= n)
                            if verbose
                                fprintf('\nTermination condition is met: effective number of parameters reached the number of observations in data (GCV would be Inf).');
                            end
                            break;
                        end

                        if trainParams.cubic
                            tmp_t1 = t1;
                            tmp_t2 = t2;
                        end
                        basisFunctionList(:,ind) = [];

                        if (trainParams.fastK < Inf)
                            % update references according to the index of parent basis function
                            for ir = 1 : length(fastParent)
                                if fastParent{ir}.idxStart > ind
                                    fastParent{ir}.idxStart = fastParent{ir}.idxStart - 1;
                                end
                                if fastParent{ir}.idxEnd >= ind
                                    fastParent{ir}.idxEnd = fastParent{ir}.idxEnd - 1;
                                end
                                if fastParent{ir}.bestIdxInList > ind
                                    fastParent{ir}.bestIdxInList = fastParent{ir}.bestIdxInList - 1;
                                end
                            end
                            for ir = 1 : length(fastParent)
                                if fastParent{ir}.idxStart > fastParent{ir}.idxEnd
                                    fastParent(ir) = [];
                                    fastMinErrAdj(ir) = [];
                                    fastIterComputedErr(ir) = [];
                                    fastNumBasis = fastNumBasis - 1;
                                    break;
                                end
                            end
                        end

                    end % end of the main loop

                    if verbose, fprintf('\n'); end

                    if verbose && (~isnan(sizeInfGCV)) && (length(model.coefs) >= 5)
                        percent = (length(model.coefs) - sizeInfGCV) / length(model.coefs);
                        if percent >= 0.2
                            warning('The last %d%% iterations have models with GCV = Inf. Depending on your data, you might want to try either enabling terminateWhenInfGCV, lowering maxFuncs, or lowering c.', round(percent * 100));
                        end
                    end

                end % end of "isempty(modelOld)"

                if isempty(modelOld)
                    if verbose && trainParams.prune
                        fprintf('Number of basis functions in the model after forward phase: %d\n', length(model.coefs));
                    end

                    if (doCubicFastLevel == 1) || ...
                            ((doCubicFastLevel >= 2) && (~trainParams.prune)) % for this level, if there is no pruning, we force calculations for cubic
                        % turn the cubic modelling on
                        trainParams.cubic = true;
                        [t1, t2] = findsideknots(model, [], [], d, minX, maxX, [], []);
                        % update all the basis functions
                        for i = 1 : length(model.knotdims)
                            X(:,i+1) = createbasisfunction(Xtr, X, model.knotdims{i}, model.knotsites{i}, ...
                                model.knotdirs{i}, model.parents(i), minX, maxX, t1(i,:), t2(i,:));
                        end
                        if dy == 1
                            [model.coefs, model.MSE] = lreg(X, Ytr, weights);
                            if isempty(weights)
                                model.MSE = model.MSE / n;
                            else
                                model.MSE = model.MSE / sumWeights;
                            end
                        else
                            for k = 1 : dy
                                [model.coefs, modelsY{k}.MSE] = lreg(X, Ytr(:,k), weights);
                                modelsY{k}.coefs = model.coefs;
                                if isempty(weights)
                                    modelsY{k}.MSE = modelsY{k}.MSE / n;
                                else
                                    modelsY{k}.MSE = modelsY{k}.MSE / sumWeights;
                                end
                            end
                        end
                    else
                        if dy == 1
                            %if isempty(weights)
                            %    model.MSE = sum((Ytr-X*model.coefs).^2) / n;
                            %else
                            %    model.MSE = sum((Ytr-X*model.coefs).^2.*weights) / sumWeights;
                            %end
                            model.MSE = err * YtrVar; % faster. works because err is sse/YtrVarN
                        else
                            for k = 1 : dy
                                if isempty(weights)
                                    modelsY{k}.MSE = sum((Ytr(:,k)-X*modelsY{k}.coefs).^2) / n;
                                else
                                    modelsY{k}.MSE = sum((Ytr(:,k)-X*modelsY{k}.coefs).^2.*weights) / sumWeights;
                                end
                            end
                        end
                    end
                    if dy == 1
                        model.GCV = gcv(length(model.coefs), model.MSE, n, trainParams.c);
                    else
                        model.GCV = 0;
                        for k = 1 : dy
                            modelsY{k}.GCV = gcv(length(model.coefs), modelsY{k}.MSE, n, trainParams.c);
                            model.GCV = model.GCV + modelsY{k}.GCV;
                        end
                    end
                    if trainParams.cubic
                        model.t1 = t1;
                        model.t2 = t2;
                    end
                end

                % BACKWARD PHASE

                if trainParams.prune

                    if verbose, fprintf('Backward phase .'); end

                    if ~isempty(modelOld) % create basis functions from scratch when modelOld is used
                        if (doCubicFastLevel == -1) || (doCubicFastLevel >= 2) % either no cubic or not yet cubic
                            if (dy == 1) && isfield(modelOld, 'X')
                                X = modelOld.X;
                            elseif (dy > 1) && isfield(modelOld{1}, 'X')
                                X = modelOld{1}.X;
                            else
                                % create all basis functions (piecewise-linear) from scratch
                                X = ones(n,length(model.knotdims)+1);
                                for i = 1 : length(model.knotdims)
                                    X(:,i+1) = createbasisfunction(Xtr, X, model.knotdims{i}, model.knotsites{i}, ...
                                        model.knotdirs{i}, model.parents(i), minX, maxX);
                                end
                                if dy == 1
                                    [model.coefs, model.MSE] = lreg(X, Ytr, weights);
                                    if isempty(weights)
                                        model.MSE = model.MSE / n;
                                    else
                                        model.MSE = model.MSE / sumWeights;
                                    end
                                else
                                    for k = 1 : dy
                                        [model.coefs, modelsY{k}.MSE] = lreg(X, Ytr(:,k), weights);
                                        modelsY{k}.coefs = model.coefs;
                                        if isempty(weights)
                                            modelsY{k}.MSE = modelsY{k}.MSE / n;
                                        else
                                            modelsY{k}.MSE = modelsY{k}.MSE / sumWeights;
                                        end
                                    end
                                end
                            end
                        else % cubic modelling when doCubicFastLevel is set to 0 or 1
                            trainParams.cubic = true; % set to true once again because the value in modelOld is lost
                            t1 = model.t1;
                            t2 = model.t2;
                            if (dy == 1) && isfield(modelOld, 'X')
                                X = modelOld.X;
                            elseif (dy > 1) && isfield(modelOld{1}, 'X')
                                X = modelOld{1}.X;
                            else
                                % create all basis functions (piecewise-cubic) from scratch
                                X = ones(n,length(model.knotdims)+1);
                                for i = 1 : length(model.knotdims)
                                    X(:,i+1) = createbasisfunction(Xtr, X, model.knotdims{i}, model.knotsites{i}, ...
                                        model.knotdirs{i}, model.parents(i), minX, maxX, t1(i,:), t2(i,:));
                                end
                            end
                        end
                        % recalculate GCV in case c has changed (e.g., if aresbuild is called from arescvc) or GCV was not yet calculated
                        if dy == 1
                            model.GCV = gcv(length(model.coefs), model.MSE, n, trainParams.c);
                        else
                            model.GCV = 0;
                            for k = 1 : dy
                                modelsY{k}.GCV = gcv(length(model.coefs), modelsY{k}.MSE, n, trainParams.c);
                                model.GCV = model.GCV + modelsY{k}.GCV;
                            end
                        end
                    end

                    models = {model};
                    if dy == 1
                        mses = model.MSE;
                    else
                        modelsYAll = {modelsY};
                    end
                    gcvs = model.GCV; % for multi-response data, this is a sum of GCVs
                    if (dy > 1) && requestingResultsEval
                        msesAll = zeros(1,dy);
                        for k = 1 : dy
                            msesAll(1,k) = modelsY{k}.MSE;
                        end
                        gcvsAll = zeros(1,dy);
                        for k = 1 : dy
                            gcvsAll(1,k) = modelsY{k}.GCV;
                        end
                    end


                    if requestingResultsEval
                        resultsEval.usedVars = false(length(model.knotdims)+1,d);
                        resultsEval.usedVars(length(model.knotdims)+1,:) = getUsedVariables(model.knotdims, d);
                    end

                    % the main loop of the backward phase
                    for j = 1 : length(model.knotdims)
                        tmpErr = zeros(1, length(model.knotdims));
                        if dy == 1
                            tmpCoefs = inf(length(model.coefs)-1, length(model.knotdims));
                        else
                            tmpGCV = inf(1, length(model.knotdims));
                            if requestingResultsEval
                                tmpMSEAll = inf(length(model.knotdims), dy);
                                tmpGCVAll = inf(length(model.knotdims), dy);
                            end
                            tmpCoefs = cell(dy, 1);
                            for k = 1 : dy
                                tmpCoefs{k} = inf(length(model.coefs)-1, length(model.knotdims));
                            end
                        end

                        % try to delete basis functions one at a time
                        for jj = 1 : length(model.knotdims)
                            Xtmp = X;
                            Xtmp(:,jj+1) = [];
                            if trainParams.cubic
                                % create a temporary model without the basis function
                                tmp_t1 = t1;
                                tmp_t1(jj,:) = [];
                                tmp_t2 = t2;
                                tmp_t2(jj,:) = [];
                                tmp_model.knotdims = model.knotdims;
                                tmp_model.knotdims(jj) = [];
                                tmp_model.knotsites = model.knotsites;
                                tmp_model.knotsites(jj) = [];
                                tmp_model.knotdirs = model.knotdirs;
                                tmp_model.knotdirs(jj) = [];
                                tmp_model.parents = model.parents;
                                tmp_model.parents(jj) = [];
                                tmp_model.parents = updateParents(tmp_model.parents, jj);
                                [tmp_t1, tmp_t2, diff] = findsideknots(tmp_model, [], [], d, minX, maxX, tmp_t1, tmp_t2);
                                % update basis functions that have their side knots moved
                                for i = diff
                                    Xtmp(:,i+1) = createbasisfunction(Xtr, Xtmp, tmp_model.knotdims{i}, tmp_model.knotsites{i}, ...
                                        tmp_model.knotdirs{i}, tmp_model.parents(i), minX, maxX, tmp_t1(i,:), tmp_t2(i,:));
                                end
                            end
                            if dy == 1
                                [coefs, tmpErr(jj)] = lreg(Xtmp, Ytr, weights);
                                tmpCoefs(:,jj) = coefs;
                            else
                                tmpGCV(jj) = 0;
                                for k = 1 : dy
                                    [coefs, err] = lreg(Xtmp, Ytr(:,k), weights);
                                    tmpErr(jj) = tmpErr(jj) + err;
                                    tmpCoefs{k}(:,jj) = coefs;
                                    if isempty(weights)
                                        err = err / n;
                                    else
                                        err = err / sumWeights;
                                    end
                                    if requestingResultsEval
                                        tmpMSEAll(jj,k) = err;
                                    end
                                    err = gcv(length(model.coefs) - 1, err, n, trainParams.c); % "-1" is because we delete one basis function
                                    tmpGCV(jj) = tmpGCV(jj) + err;
                                    if requestingResultsEval
                                        tmpGCVAll(jj,k) = err;
                                    end
                                end
                            end
                        end

                        [~, ind] = min(tmpErr); % find the best modification
                        X(:,ind+1) = [];
                        if dy == 1
                            model.coefs = tmpCoefs(:,ind);
                        else
                            model.coefs = tmpCoefs{1}(:,ind);
                            for k = 1 : dy
                                modelsY{k}.coefs = tmpCoefs{k}(:,ind);
                            end
                        end
                        model.knotdims(ind) = [];
                        model.knotsites(ind) = [];
                        model.knotdirs(ind) = [];
                        model.parents(ind) = [];
                        model.parents = updateParents(model.parents, ind);

                        if requestingResultsEval
                            resultsEval.usedVars(length(model.knotdims)+1,:) = getUsedVariables(model.knotdims, d);
                        end

                        if trainParams.cubic
                            t1(ind,:) = [];
                            t2(ind,:) = [];
                            [t1, t2, diff] = findsideknots(model, [], [], d, minX, maxX, t1, t2);
                            % update basis functions that have their side knots moved
                            for i = diff
                                X(:,i+1) = createbasisfunction(Xtr, X, model.knotdims{i}, model.knotsites{i}, ...
                                    model.knotdirs{i}, model.parents(i), minX, maxX, t1(i,:), t2(i,:));
                            end
                            model.t1 = t1;
                            model.t2 = t2;
                        end

                        models{end+1} = model;
                        if dy == 1
                            if isempty(weights)
                                mses(end+1) = tmpErr(ind) / n;
                            else
                                mses(end+1) = tmpErr(ind) / sumWeights;
                            end
                            gcvs(end+1) = gcv(length(model.coefs), mses(end), n, trainParams.c);
                        else
                            modelsYAll{end+1} = modelsY;
                            gcvs(end+1) = tmpGCV(ind);
                            if requestingResultsEval
                                msesAll(end+1,1:dy) = tmpMSEAll(ind,:);
                                gcvsAll(end+1,1:dy) = tmpGCVAll(ind,:);
                            end
                        end

                        if verbose, fprintf('.'); end
                    end % end of the main loop

                    % now choose the best pruned model
                    if trainParams.maxFinalFuncs <= 1
                        g = gcvs(end);
                        ind = length(gcvs);
                    else
                        if dy == 1
                            [sgcvs, sind] = sort(gcvs);
                            gcvNull = gcv(1, YtrVar, n, 0);
                            if trainParams.maxFinalFuncs >= length(models{1}.coefs) % if even the biggest model is not bigger than maxFinalFuncs
                                g = sgcvs(1); % in the sorted list, 1st is the best
                                ind = sind(1);
                            else
                                [g, ind] = min(gcvs(end-trainParams.maxFinalFuncs+1:end));
                                ind = ind + length(gcvs) - trainParams.maxFinalFuncs;
                            end
                            % select smaller model, if it is only negligibly worse
                            gBest = g;
                            indBest = ind;
                            for i = 2 : numel(sgcvs)
                                if (sgcvs(i) / gcvNull - 1e-10 <= g / gcvNull)
                                    if (sind(i) > ind)
                                        gBest = sgcvs(i);
                                        indBest = sind(i);
                                    end
                                else
                                    break;
                                end
                            end
                            g = gBest;
                            ind = indBest;
                        else
                            if trainParams.maxFinalFuncs >= length(models{1}.coefs) % if even the biggest model is not bigger than maxFinalFuncs
                                [g, ind] = min(gcvs);
                            else
                                [g, ind] = min(gcvs(end-trainParams.maxFinalFuncs+1:end));
                                ind = ind + length(gcvs) - trainParams.maxFinalFuncs;
                            end
                        end
                    end
                    model = models{ind};
                    if dy > 1
                        modelsY = modelsYAll{ind};
                    end

                    if requestingResultsEval
                        availableDataEval = ~isempty(dataEval);
                        if availableDataEval
                            nDataEval = size(dataEval.Y,1);
                            dataEvalHasWeights = isfield(dataEval, 'weights');
                            if dataEvalHasWeights
                                if isempty(dataEval.weights)
                                    dataEvalHasWeights = false;
                                else
                                    sumDataEvalWeights = sum(dataEval.weights);
                                end
                            end
                        end
                        if dy == 1
                            if availableDataEval
                                % Calculate MSEtest in the test data for each model size
                                resultsEval.MSEtest = zeros(length(models),1);
                                for j = 1 : length(models)
                                    models{j}.trainParams = trainParams;
                                    models{j}.minX = minX;
                                    models{j}.maxX = maxX;
                                    if ~dataEvalHasWeights
                                        resultsEval.MSEtest(j) = ...
                                            sum((dataEval.Y - arespredict(models{j}, dataEval.X)).^2) / nDataEval;
                                    else
                                        resultsEval.MSEtest(j) = ...
                                            sum((dataEval.Y - arespredict(models{j}, dataEval.X)).^2.*dataEval.weights) / sumDataEvalWeights;
                                    end
                                end
                            end
                            % Save GCV and R2GCV for each model size
                            resultsEval.MSE = mses';
                            resultsEval.R2 = 1 - resultsEval.MSE / YtrVar;
                            resultsEval.GCV = gcvs';
                            resultsEval.R2GCV = 1 - resultsEval.GCV / gcv(1, YtrVar, n, 0);
                        else
                            % Calculate MSEtest in the test data for each model size
                            if availableDataEval
                                resultsEval.MSEtest = zeros(length(models),dy);
                                for j = 1 : length(models)
                                    models{j}.trainParams = trainParams;
                                    models{j}.minX = minX;
                                    models{j}.maxX = maxX;
                                    modelsYTestTmp = modelsYAll{j};
                                    for k = 1 : dy
                                        models{j}.coefs = modelsYTestTmp{k}.coefs;
                                        if ~dataEvalHasWeights
                                            resultsEval.MSEtest(j,k) = ...
                                                sum((dataEval.Y(:,k) - arespredict(models{j}, dataEval.X)).^2) / nDataEval;
                                        else
                                            resultsEval.MSEtest(j,k) = ...
                                                sum((dataEval.Y(:,k) - arespredict(models{j}, dataEval.X)).^2.*dataEval.weights) / sumDataEvalWeights;
                                        end
                                    end
                                end
                            end
                            % Save GCV and R2GCV for each model size
                            resultsEval.MSE = msesAll;
                            resultsEval.R2 = 1 - resultsEval.MSE ./ repmat(YtrVar, size(resultsEval.MSE,1), 1);
                            resultsEval.GCV = gcvsAll;
                            GCVnull = zeros(size(resultsEval.GCV,1), dy);
                            for k = 1 : dy
                                GCVnull(:,k) = gcv(1, YtrVar(k), n, 0);
                            end
                            resultsEval.R2GCV = 1 - resultsEval.GCV ./ GCVnull;
                            % mean accross models
                            resultsEval.MSE = mean(resultsEval.MSE,2);
                            resultsEval.R2 = mean(resultsEval.R2,2);
                            resultsEval.GCV = mean(resultsEval.GCV,2);
                            resultsEval.R2GCV = mean(resultsEval.R2GCV,2);
                        end
                        % Flip so that model size is ascending
                        resultsEval.MSE = flip(resultsEval.MSE);
                        resultsEval.R2 = flip(resultsEval.R2);
                        resultsEval.GCV = flip(resultsEval.GCV);
                        %resultsEval.GCV(isinf(resultsEval.GCV)) = NaN;
                        resultsEval.R2GCV = flip(resultsEval.R2GCV);
                        %resultsEval.R2GCV(isinf(resultsEval.R2GCV)) = NaN;
                        if availableDataEval
                            % Calculate R2test
                            resultsEval.R2test = nan(size(resultsEval.MSEtest));
                            if ~dataEvalHasWeights
                                dataEvalYMean = mean(dataEval.Y,1);
                                for k = 1 : dy
                                    dataEvalYtrVar = sum((dataEval.Y(:,k) - dataEvalYMean(k)) .^ 2) / nDataEval;
                                    if (dataEvalYtrVar > eps)
                                        resultsEval.R2test(:,k) = 1 - resultsEval.MSEtest(:,k) / dataEvalYtrVar;
                                    else
                                        resultsEval.R2test(:) = NaN;
                                        break;
                                    end
                                end
                            else
                                for k = 1 : dy
                                    dataEvalYMean = sum(dataEval.Y(:,k) .* dataEval.weights) / sumDataEvalWeights;
                                    dataEvalYtrVar = sum(((dataEval.Y(:,k) - dataEvalYMean) .^ 2) .* dataEval.weights) / sumDataEvalWeights;
                                    if (dataEvalYtrVar > eps)
                                        resultsEval.R2test(:,k) = 1 - resultsEval.MSEtest(:,k) / dataEvalYtrVar;
                                    else
                                        resultsEval.R2test(:) = NaN;
                                        break;
                                    end
                                end
                            end
                            % Mean across models
                            resultsEval.MSEtest = mean(resultsEval.MSEtest,2);
                            resultsEval.R2test = mean(resultsEval.R2test,2);
                            % Flip so that model size is ascending
                            resultsEval.MSEtest = flip(resultsEval.MSEtest);
                            resultsEval.R2test = flip(resultsEval.R2test);
                        end
                    end

                    if doCubicFastLevel >= 2
                        % turn the cubic modelling on
                        trainParams.cubic = true;
                        [t1, t2] = findsideknots(model, [], [], d, minX, maxX, [], []);
                        % update all the basis functions
                        X = ones(n,length(model.coefs));
                        for i = 1 : length(model.knotdims)
                            X(:,i+1) = createbasisfunction(Xtr, X, model.knotdims{i}, model.knotsites{i}, ...
                                model.knotdirs{i}, model.parents(i), minX, maxX, t1(i,:), t2(i,:));
                        end
                        model.t1 = t1;
                        model.t2 = t2;
                        if dy == 1
                            [model.coefs, model.MSE] = lreg(X, Ytr, weights);
                            if isempty(weights)
                                model.MSE = model.MSE / n;
                            else
                                model.MSE = model.MSE / sumWeights;
                            end
                            model.GCV = gcv(length(model.coefs), model.MSE, n, trainParams.c);
                        else
                            for k = 1 : dy
                                [modelsY{k}.coefs, modelsY{k}.MSE] = lreg(X, Ytr(:,k), weights);
                                if isempty(weights)
                                    modelsY{k}.MSE = modelsY{k}.MSE / n;
                                else
                                    modelsY{k}.MSE = modelsY{k}.MSE / sumWeights;
                                end
                                modelsY{k}.GCV = gcv(length(model.coefs), modelsY{k}.MSE, n, trainParams.c);
                            end
                        end
                    else
                        if dy == 1
                            model.MSE = mses(ind);
                            model.GCV = g;
                        end
                        if keepX || (dy > 1)
                            % recreate all basis functions from scratch just for keepX
                            % or to be able to recalculate MSE and GCV for multiple Ys
                            X = ones(n,length(model.coefs));
                            if trainParams.cubic
                                for i = 1 : length(model.knotdims)
                                    X(:,i+1) = createbasisfunction(Xtr, X, model.knotdims{i}, model.knotsites{i}, ...
                                        model.knotdirs{i}, model.parents(i), minX, maxX, model.t1(i,:), model.t2(i,:));
                                end
                            else
                                for i = 1 : length(model.knotdims)
                                    X(:,i+1) = createbasisfunction(Xtr, X, model.knotdims{i}, model.knotsites{i}, ...
                                        model.knotdirs{i}, model.parents(i), minX, maxX);
                                end
                            end
                        end
                        if dy > 1
                            for k = 1 : dy
                                if isempty(weights)
                                    modelsY{k}.MSE = sum((Ytr(:,k)-X*modelsY{k}.coefs).^2) / n;
                                else
                                    modelsY{k}.MSE = sum((Ytr(:,k)-X*modelsY{k}.coefs).^2.*weights) / sumWeights;
                                end
                                modelsY{k}.GCV = gcv(length(model.coefs), modelsY{k}.MSE, n, trainParams.c);
                            end
                        end
                    end

                    if verbose, fprintf('\n'); end

                end % end of "trainParams.prune"

            end % end of "if useEndSpan*2 >= n"

            warning(origWarningState);

            model.trainParams = trainParams;
            model.minX = minX;
            model.maxX = maxX;
            model.isBinary = isBinary;

            if keepX
                model.X = X;
            end

            time = toc(ttt);
            if verbose
                fprintf('Number of basis functions in the final model: %d\n', length(model.coefs));
                fprintf('Total effective number of parameters: %0.1f\n', getENP(length(model.coefs), model.trainParams.c));
                maxDeg = 0;
                vars = [];
                if ~isempty(model.knotdims)
                    for i = 1 : length(model.knotdims)
                        vars = union(vars, model.knotdims{i});
                        if length(model.knotdims{i}) > maxDeg
                            maxDeg = length(model.knotdims{i});
                        end
                    end
                end
                fprintf('Highest degree of interactions: %d\n', maxDeg);
                if ~isempty(vars)
                    listStr = '';
                    for i = 1:length(vars)
                        listStr = [listStr 'x' int2str(vars(i))];
                        if i < length(vars)
                            listStr = [listStr ', '];
                        end
                    end
                    fprintf('Number of input variables in the model: %d (%s)\n', length(vars), listStr);
                else
                    fprintf('Number of input variables in the model: 0\n');
                end
                fprintf('Execution time: %0.2f seconds\n', time);
            end

            if dy > 1
                for k = 1 : dy
                    coefs = modelsY{k}.coefs;
                    MSE = modelsY{k}.MSE;
                    GCV = modelsY{k}.GCV;
                    modelsY{k} = model;
                    modelsY{k}.coefs = coefs;
                    modelsY{k}.MSE = MSE;
                    modelsY{k}.GCV = GCV;
                end
                model = modelsY;
            end

            return
        end
        %==========================================================================

        function enp = getENP(~,nBasis, c)
            % Calculates model's effective number of parameters
            enp = nBasis + c * (nBasis - 1) / 2;
            return
        end

        function g = gcv(~,nBasis, MSE, n, c)
            % Calculates GCV from model complexity, its Mean Squared Error, number of
            % observations n, and penalty c.
            enp = nBasis + c * (nBasis - 1) / 2; % model's effective number of parameters
            if enp >= n
                g = Inf;
            else
                p = 1 - enp / n;
                g = MSE / (p * p);
            end
            return
        end

        function [basisFunctionList, idxStart1, idxEnd1, idxStart2, idxEnd2] = ...
                createList(app,basisFunctionList_old, Xtr, sortedXtr, sortedXtrInd, n, d, ...
                model, numNewFuncs, trainParams, minX, maxX)
            % Takes the old list of basis functions and adds new ones according to the
            % current model. If the old list is empty, adds all basis functions without
            % interactions. If it's non-empty, adds only basis functions with
            % interactions which result from the last numNewFuncs basis functions
            % (typically 2, sometimes 1).
            % For knot placement (and for calculation of minSpan) only those potential
            % knot sites are considered for which the parent basis function is larger
            % than zero.
            % Input argument Xtr should contain all the training observations;
            % sortedXtr should contain all the Xtr observations, except first and last
            % endSpan points, sorted in each column separately; sortedXtrInd should
            % contain indices of the sorted observations.

            % Create no-ineraction basis functions

            if (isempty(basisFunctionList_old)) && (numNewFuncs == 0)
                if trainParams.useMinSpan ~= 1
                    % make a list of knot sites allowed by minSpan
                    minSpan = getMinSpan(d, n, trainParams.useMinSpan);
                    % symmetrically center the knot sites
                    nAvail = n - trainParams.useEndSpan * 2;
                    if nAvail > minSpan % is there space for more than one knot?
                        nDiv = floor(nAvail / minSpan);
                        if nDiv == nAvail / minSpan
                            offset = floor(minSpan / 2);
                        else
                            offset = floor((nAvail - nDiv * minSpan) / 2);
                        end
                    else
                        offset = floor(nAvail / 2); % if space for only one knot, put it in center
                    end
                    allowed = mod(-offset:nAvail-1-offset, minSpan) == 0;
                end
                basisFunctionList = cell(4,0);
                counter = 0;
                for di = 1 : d % for each dimension
                    forceLinear = any(trainParams.forceLinear == di);
                    if (trainParams.allowLinear > 0) || forceLinear
                        counter = counter + 1;
                        basisFunctionList{1, counter} = di; % knotdims
                        basisFunctionList{2, counter} = minX(di);  % knotsites
                        basisFunctionList{3, counter} = 2;  % knotdirs
                        basisFunctionList{4, counter} = 0;  % parent
                        if forceLinear
                            continue;
                        end
                        ignoreEdges = true; % because var entering linearly is (almost) like putting a knot on the edge
                    else
                        ignoreEdges = false;
                    end
                    if trainParams.useMinSpan == 1
                        allowedSites = unique(sortedXtr(:,di))';
                    else
                        allowedSites = unique(sortedXtr(allowed,di))';
                    end
                    if ignoreEdges && ~isempty(allowedSites)
                        allowedSites = allowedSites((1+(allowedSites(1) == minX(di))):(end-(allowedSites(end) == maxX(di))));
                    end
                    % add new unique basis functions to the list (either all or except
                    % those which do not fall on the allowed knot sites)
                    for x = allowedSites
                        % we could use cell array of structs here but benchmarking
                        % showed that this is faster
                        counter = counter + 1;
                        basisFunctionList{1, counter} = di; % knotdims
                        basisFunctionList{2, counter} = x;  % knotsites
                        basisFunctionList{3, counter} = 1;  % knotdirs
                        basisFunctionList{4, counter} = 0;  % parent
                    end
                end
                if (counter > 0)
                    idxStart1 = 1;
                    idxEnd1 = counter;
                else
                    idxStart1 = -1;
                    idxEnd1 = -1;
                end
                idxStart2 = -1;
                idxEnd2 = -1;
                return
            end

            if (trainParams.maxInteractions < 2) || (numNewFuncs < 1)
                basisFunctionList = basisFunctionList_old;
                idxStart1 = -1;
                idxEnd1 = -1;
                idxStart2 = -1;
                idxEnd2 = -1;
                return
            end

            % Create basis functions with interactions

            % for basis functions with interactions we can make endSpan wider
            if (trainParams.endSpanAdjust > 1)
                endSpanToUse = round(trainParams.useEndSpan * trainParams.endSpanAdjust);
                if endSpanToUse * 2 >= n
                    endSpanToUse = floor(n / 2) - 1; % force always at least one knot
                end
            else
                endSpanToUse = trainParams.useEndSpan;
            end

            basisFunctionList = basisFunctionList_old;
            sizeOld = size(basisFunctionList_old,2);
            counter = sizeOld;
            start = length(model.knotdims) - (numNewFuncs-1);

            idxStart1 = counter + 1;
            idxEnd1 = -1;
            idxStart2 = -1;

            % loop through one or two last basis functions already in the model
            for j = start : length(model.knotdims)
                if (j > start)
                    if (counter >= idxStart1)
                        idxEnd1 = counter;
                    else
                        idxStart1 = -1;
                        idxEnd1 = -1;
                    end
                    idxStart2 = counter + 1;
                end
                if length(model.knotdims{j}) < trainParams.maxInteractions
                    allowedDims = 1 : d;
                    if trainParams.selfInteractions <= 1
                        % will not consider already used dimensions
                        allowedDims = setdiff(allowedDims, model.knotdims{j});
                    else
                        for i = 1 : d
                            if length(find(model.knotdims{j} == i)) >= trainParams.selfInteractions
                                allowedDims = setdiff(allowedDims, i);
                            end
                        end
                    end
                    if isempty(allowedDims)
                        continue
                    end
                    if (~isempty(trainParams.noInteract)) || (~isempty(trainParams.yesInteract))
                        if ~isempty(trainParams.noInteract)
                            allowed = true(1,d);
                            for i = model.knotdims{j}
                                allowed(trainParams.noInteract(trainParams.noInteract(:,1)==i,2)) = false;
                            end
                        else
                            allowed = false(1,d);
                            for i = model.knotdims{j}
                                allowed(trainParams.yesInteract(trainParams.yesInteract(:,1)==i,2)) = true;
                            end
                        end
                        allowedDims = setdiff(allowedDims, find(~allowed));
                        if isempty(allowedDims)
                            continue
                        end
                    end

                    if trainParams.useMinSpan ~= 1

                        % make a list of knot sites allowed by minSpan
                        nonzero = listNonZero(Xtr, model.knotdims{j}, model.knotsites{j}, model.knotdirs{j});
                        minSpan = getMinSpan(d, length(find(nonzero)), trainParams.useMinSpan);
                        if ~isfinite(minSpan)
                            continue
                        end
                        % symmetrically center the knot sites
                        nAvail = n - endSpanToUse * 2;
                        if nAvail > minSpan % is there space for more than one knot?
                            nDiv = floor(nAvail / minSpan);
                            if nDiv == nAvail / minSpan
                                offset = floor(minSpan / 2);
                            else
                                offset = floor((nAvail - nDiv * minSpan) / 2);
                            end
                        else
                            offset = floor(nAvail / 2); % if space for only one knot, put it in center
                        end
                        allowed = mod(-offset:nAvail-1-offset, minSpan) == 0;

                        for di = allowedDims % for each dimension
                            forceLinear = any(trainParams.forceLinear == di);
                            if (trainParams.allowLinear > 0) || forceLinear
                                counter = counter + 1;
                                basisFunctionList{1, counter} = [model.knotdims{j} di]; % knotdims
                                basisFunctionList{2, counter} = [model.knotsites{j} minX(di)];  % knotsites
                                basisFunctionList{3, counter} = [model.knotdirs{j} 2];  % knotdirs
                                basisFunctionList{4, counter} = j;  % parent
                                if forceLinear
                                    continue;
                                end
                                ignoreEdges = true; % because var entering linearly is (almost) like putting a knot on the edge
                            else
                                ignoreEdges = false;
                            end
                            % add new unique basis functions to the list (all except
                            % those which do not fall on the allowed knot sites and
                            % except those for which the parent basis function is zero
                            % on the knot site)
                            if (trainParams.endSpanAdjust > 1)
                                add = endSpanToUse - trainParams.useEndSpan;
                                allowed = allowed & nonzero(sortedXtrInd(1+add:end-add,di));
                            else
                                allowed = allowed & nonzero(sortedXtrInd(:,di));
                            end
                            allowedSites = unique(sortedXtr(allowed,di))';
                            if ignoreEdges && ~isempty(allowedSites)
                                allowedSites = allowedSites((1+(allowedSites(1) == minX(di))):(end-(allowedSites(end) == maxX(di))));
                            end
                            for x = allowedSites
                                counter = counter + 1;
                                basisFunctionList{1, counter} = [model.knotdims{j} di];
                                basisFunctionList{2, counter} = [model.knotsites{j} x];
                                basisFunctionList{3, counter} = [model.knotdirs{j} 1];
                                basisFunctionList{4, counter} = j;
                            end
                        end

                    else

                        nonzero = listNonZero(Xtr, model.knotdims{j}, model.knotsites{j}, model.knotdirs{j});
                        for di = allowedDims % for each dimension
                            forceLinear = any(trainParams.forceLinear == di);
                            if (trainParams.allowLinear > 0) || forceLinear
                                counter = counter + 1;
                                basisFunctionList{1, counter} = [model.knotdims{j} di]; % knotdims
                                basisFunctionList{2, counter} = [model.knotsites{j} minX(di)];  % knotsites
                                basisFunctionList{3, counter} = [model.knotdirs{j} 2];  % knotdirs
                                basisFunctionList{4, counter} = j;  % parent
                                if forceLinear
                                    continue;
                                end
                                ignoreEdges = true; % because var entering linearly is (almost) like putting a knot on the edge
                            else
                                ignoreEdges = false;
                            end
                            % add new unique basis functions to the list (all except
                            % those for which the parent basis function is zero on
                            % the knot site)
                            if (trainParams.endSpanAdjust > 1)
                                add = endSpanToUse - trainParams.useEndSpan;
                                allowedSites = unique(sortedXtr(nonzero(sortedXtrInd(1+add:end-add,di)),di))';
                            else
                                allowedSites = unique(sortedXtr(nonzero(sortedXtrInd(:,di)),di))';
                            end
                            if ignoreEdges && ~isempty(allowedSites)
                                allowedSites = allowedSites((1+(allowedSites(1) == minX(di))):(end-(allowedSites(end) == maxX(di))));
                            end
                            for x = allowedSites
                                counter = counter + 1;
                                basisFunctionList{1, counter} = [model.knotdims{j} di];
                                basisFunctionList{2, counter} = [model.knotsites{j} x];
                                basisFunctionList{3, counter} = [model.knotdirs{j} 1];
                                basisFunctionList{4, counter} = j;
                            end
                        end

                    end

                end
            end
            if (sizeOld == size(basisFunctionList,2)) % size didn't increase
                idxStart1 = -1;
                idxEnd1 = -1;
                idxStart2 = -1;
                idxEnd2 = -1;
            else
                if (idxStart1 > 0) && (idxEnd1 <= 0) % possible only if there is no second parent
                    idxEnd1 = counter;
                    idxEnd2 = -1;
                elseif (idxStart2 > 0) && (counter >= idxStart2)
                    idxEnd2 = counter;
                else
                    idxStart2 = -1;
                    idxEnd2 = -1;
                end
            end
            return
        end

        function nonzero = listNonZero(app,Xtr, knotdims, knotsites, knotdirs)
            % Lists nonzero (according to the parent basis function) sites where knots
            % may be placed. (Line 5 of Algorithm 2 in Friedman, 1991a)
            nonzero = true(1,size(Xtr,1));
            for j = 1 : size(Xtr,1)
                for i = 1 : length(knotdims)
                    z = Xtr(j,knotdims(i)) - knotsites(i);
                    if ((z >= 0) && (knotdirs(i) < 0)) || ...
                            ((z <= 0) && (knotdirs(i) > 0))
                        nonzero(j) = false;
                        break;
                    end
                end
            end
            return
        end

        function vars = getUsedVariables(app,knotdims, d)
            % Outputs a logical vector with true for variables that are included in the knotdims
            vars = false(1,d);
            if ~isempty(knotdims)
                list = [];
                for i = 1 : length(knotdims)
                    list = union(list, knotdims{i});
                end
                vars(list) = true;
            end
            return
        end

        function s = getEndSpan(~,d)
            % Calculation of endSpan so that potential knot sites that are too close to
            % the ends of data intervals are not considered.
            %s = floor(3 - log2(0.05/d));
            s = floor(7.32193 + log(d) / 0.69315); % precomputed version
            if s < 1, s = 1; end
            return
        end

        function s = getMinSpan(~,d, nz, param)
            % Calculation of minSpan so that only those potential knot sites are
            % considered which are at least minSpan apart. This increases resistance to
            % runs of correlated noise.
            % nz is the number of potential knot sites where the parent basis function
            % is nonzero.
            if nz == 0
                s = Inf;
            else
                if param < 0 % automatic
                    %s = floor(-log2(-log(1-0.05)/(d*nz)) / 2.5);
                    s = floor((2.9702 + log(d*nz)) / 1.7329); % precomputed version
                else
                    s = param;
                    if s > nz
                        s = Inf;
                    end
                end
                if s < 1, s = 1; end
            end
            return
        end

        function [coefs, err] = lreg(~,x, y, w)
            % Linear regression (unweighted and weighted)
            if isempty(w)
                coefs = (x' * x) \ (x' * y);
                err = sum((y-x*coefs).^2);
            else
                xw = bsxfun(@times, x, w)';
                coefs = (xw * x) \ (xw * y);
                err = sum((y-x*coefs).^2.*w); % later in code this is divided by sum of weights
            end
            return
        end

        function [resultsTotal, resultsFolds, resultsPruning] = arescv(app,X, Y, ...
                trainParams, k, shuffle, nCross, weights, testWithWeights, evalPruning, verbose)
            % arescv
            % Tests ARES performance using k-fold Cross-Validation.
            % The function has additional built-in capabilities for finding the "best"
            % number of basis functions for the final ARES model (maxFinalFuncs for
            % function aresparams). See example of usage in user's manual for details.
            %
            % Call:
            %   [resultsTotal, resultsFolds, resultsPruning] = arescv(X, Y, ...
            %       trainParams, k, shuffle, nCross, weights, testWithWeights, ...
            %       evalPruning, verbose)
            %
            % All the input arguments, except the first three, are optional. Empty
            % values are also accepted (the corresponding defaults will be used).
            % Note that, if argument shuffle is set to true, this function employs
            % random number generator for which you can set seed before calling the
            % function.
            %
            % Input:
            %   X, Y          : The data. See description of function aresbuild.
            %   trainParams   : A structure of training parameters (see function
            %                   aresparams for details).
            %   k             : Value of k for k-fold Cross-Validation. The typical
            %                   values are 5 or 10. For Leave-One-Out Cross-Validation
            %                   set k equal to n. (default value = 10)
            %   shuffle       : Whether to shuffle the order of observations before
            %                   performing Cross-Validation. (default value = true)
            %   nCross        : How many times to repeat Cross-Validation with
            %                   different data partitioning. This can be used to get
            %                   more stable results. Default value = 1, i.e., no
            %                   repetition. Useless if shuffle = false.
            %   weights       : A vector of weights for observations. See description
            %                   of function aresbuild.
            %   testWithWeights : Set to true to use weights vector for both, training
            %                   and testing. Set to false to use it for training only.
            %                   This argument has any effect only when weights vector
            %                   is provided. (default value = true)
            %   evalPruning   : Whether to evaluate all the candidate models of the
            %                   pruning phase. If set to true, the output argument
            %                   resultsPruning contains the results. See example of
            %                   usage in user's manual. (default value = false)
            %   verbose       : Whether to output additional information to console.
            %                   (default value = true)
            %
            % Output:
            %   resultsTotal  : A structure of Cross-Validation results. The results
            %                   are averaged across Cross-Validation folds and, in case
            %                   of multi-response data, also across multiple models.
            %   resultsFolds  : A structure of vectors or matrices (in case of multi-
            %                   response data) of results for each Cross-Validation
            %                   fold. Columns correspond to Cross-Validation folds.
            %                   Rows correspond to models.
            %   Both structures have the following fields:
            %     MAE         : Mean Absolute Error.
            %     MSE         : Mean Squared Error.
            %     RMSE        : Root Mean Squared Error.
            %     RRMSE       : Relative Root Mean Squared Error. Not reported for
            %                   Leave-One-Out Cross-Validation.
            %     R2          : Coefficient of Determination. Not reported for
            %                   Leave-One-Out Cross-Validation.
            %     nBasis      : Number of basis functions in model (including the
            %                   intercept term).
            %     nVars       : Number of input variables included in model.
            %     maxDeg      : Highest degree of variable interactions in model.
            %   resultsPruning: Available only if evalPruning = true. See example of
            %                   usage in the user's manual Section 3.3. The structure
            %                   has the following fields:
            %     GCV         : A matrix of GCV values for best candidate models of
            %                   each size at each Cross-Validation fold. The number of
            %                   rows is equal to k*nCross. Column index corresponds to
            %                   the number of basis functions in a model.
            %     meanGCV     : A vector of mean GCV values for each model size across
            %                   all Cross-Validation folds.
            %     nBasisGCV   : The number of basis functions (including the intercept
            %                   term) for which the mean GCV is minimum.
            %     MSEoof      : A matrix of out-of-fold MSE values for best candidate
            %                   models of each size at each Cross-Validation fold. The
            %                   number of rows for this matrix is equal to k*nCross.
            %                   Column index corresponds to the number of basis
            %                   functions in a model.
            %     meanMSEoof  : A vector of mean out-of-fold MSE values for each model
            %                   size across all Cross-Validation folds.
            %     nBasisMSEoof : The number of basis functions (including the intercept
            %                   term) for which the mean out-of-fold MSE is minimum.
            %     R2GCV       : A matrix of R2GCV (R2 estimated by GCV in training
            %                   data) values for best candidate models of each size at
            %                   each Cross-Validation fold. The number of rows is equal
            %                   to k*nCross. Column index corresponds to the number of
            %                   basis functions in a model.
            %     meanR2GCV   : A vector of mean R2GCV values for each model size
            %                   across all Cross-Validation folds.
            %     nBasisR2GCV : The number of basis functions (including the intercept
            %                   term) for which the mean R2GCV is maximum.
            %     R2oof       : A matrix of out-of-fold R2 values for best candidate
            %                   models of each size at each Cross-Validation fold. The
            %                   number of rows for this matrix is equal to k*nCross.
            %                   Column index corresponds to the number of basis
            %                   functions in a model.
            %     meanR2oof   : A vector of mean out-of-fold R2 values for each model
            %                   size across all Cross-Validation folds.
            %     nBasisR2oof : The number of basis functions (including the intercept
            %                   term) for which the mean out-of-fold R2 is maximum.

            % =========================================================================
            % ARESLab: Adaptive Regression Splines toolbox for Matlab/Octave
            % Author: Gints Jekabsons (gints.jekabsons@rtu.lv)
            % URL: http://www.cs.rtu.lv/jekabsons/
            %
            % Copyright (C) 2009-2016  Gints Jekabsons
            %
            % This program is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or
            % (at your option) any later version.
            %
            % This program is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with this program. If not, see <http://www.gnu.org/licenses/>.
            % =========================================================================

            % Last update: May 15, 2016

            if nargin < 3
                error('Not enough input arguments.');
            end

            if isempty(X) || isempty(Y)
                error('Data is empty.');
            end
            if iscell(X) || iscell(Y)
                error('X and Y should not be cell arrays.');
            end
            if islogical(Y)
                Y = double(Y);
            elseif ~isfloat(Y)
                error('Y data type should be double or logical.');
            end
            if ~isfloat(X)
                error('X data type should be double.');
            end
            if any(any(isnan(X)))
                error('ARESLab cannot handle missing values (NaN).');
            end

            [n, d] = size(X); % number of observations and number of input variables
            [ny, dy] = size(Y); % number of observations and number of output variables
            if ny ~= n
                error('The number of rows in X and Y should be equal.');
            end

            if (nargin < 4) || isempty(k)
                k = 10;
            end
            if k < 2
                error('k should not be smaller than 2.');
            end
            if k > n
                error('k should not be larger than the number of observations.');
            end
            if (nargin < 5) || isempty(shuffle)
                shuffle = true;
            end
            if (nargin < 6) || isempty(nCross)
                nCross = 1;
            else
                if (nCross > 1) && (~shuffle)
                    error('nCross>1 but shuffle=false');
                end
            end
            if (nargin < 7)
                weights = [];
            else
                if (~isempty(weights)) && ...
                        ((size(weights,1) ~= n) || (size(weights,2) ~= 1))
                    error('weights vector is of wrong size.');
                end
            end
            if (nargin < 8) || isempty(testWithWeights)
                testWithWeights = true;
            end
            if (nargin < 9) || isempty(evalPruning)
                evalPruning = false;
            else
                if evalPruning && (~trainParams.prune)
                    error('evalPruning=true but trainParams.prune=false');
                end
            end
            if (nargin < 10) || isempty(verbose)
                verbose = true;
            end

            resultsFolds.MAE = Inf(k*nCross,dy);
            resultsFolds.MSE = Inf(k*nCross,dy);
            resultsFolds.RMSE = Inf(k*nCross,dy);
            resultsFolds.RRMSE = Inf(k*nCross,dy);
            resultsFolds.R2 = -Inf(k*nCross,dy);
            resultsFolds.nBasis = NaN(k*nCross,dy);
            resultsFolds.nVars = zeros(k*nCross,dy);
            resultsFolds.maxDeg = zeros(k*nCross,dy);
            if evalPruning
                resultsPruning.GCV = NaN(k*nCross,1);
                resultsPruning.MSEoof = NaN(k*nCross,1);
                resultsPruning.meanGCV = NaN;
                resultsPruning.nBasisGCV = NaN;
                resultsPruning.meanMSEoof = NaN;
                resultsPruning.nBasisMSEoof = NaN;

                resultsPruning.R2GCV = NaN(k*nCross,1);
                resultsPruning.R2oof = NaN(k*nCross,1);
                resultsPruning.meanR2GCV = NaN;
                resultsPruning.nBasisR2GCV = NaN;
                resultsPruning.meanR2oof = NaN;
                resultsPruning.nBasisR2oof = NaN;
            else
                resultsPruning = [];
            end

            % Repetition of Cross-Validation nCross times
            for iCross = 1 : nCross
                [MAE, MSE, RMSE, RRMSE, R2, nBasis, nVars, maxDeg, evalResults] = ...
                    doCV(X, Y, trainParams, k, shuffle, weights, testWithWeights, evalPruning, verbose, n, d, dy);
                to = iCross * k;
                range = (to - k + 1) : to;
                resultsFolds.MAE(range,:) = MAE;
                resultsFolds.MSE(range,:) = MSE;
                resultsFolds.RMSE(range,:) = RMSE;
                resultsFolds.RRMSE(range,:) = RRMSE;
                resultsFolds.R2(range,:) = R2;
                resultsFolds.nBasis(range,:) = nBasis;
                resultsFolds.nVars(range,:) = nVars;
                resultsFolds.maxDeg(range,:) = maxDeg;
                if evalPruning
                    % Accomodate new data, even if bigger than expected
                    sizeOld = size(resultsPruning.GCV,2);
                    sizeNew = size(evalResults.GCV,2);
                    if sizeOld < sizeNew
                        add = NaN(size(resultsPruning.GCV,1),sizeNew-sizeOld);
                        resultsPruning.GCV = [resultsPruning.GCV add];
                        resultsPruning.MSEoof = [resultsPruning.MSEoof add];
                        resultsPruning.R2GCV = [resultsPruning.R2GCV add];
                        resultsPruning.R2oof = [resultsPruning.R2oof add];
                    end
                    resultsPruning.GCV(range,1:sizeNew) = evalResults.GCV;
                    resultsPruning.MSEoof(range,1:sizeNew) = evalResults.MSEoof;
                    resultsPruning.R2GCV(range,1:sizeNew) = evalResults.R2GCV;
                    resultsPruning.R2oof(range,1:sizeNew) = evalResults.R2oof;
                end
            end

            % first mean across models, then mean across folds
            resultsTotal.MAE = mean(mean(resultsFolds.MAE,2),1);
            resultsTotal.MSE = mean(mean(resultsFolds.MSE,2),1);
            resultsTotal.RMSE = mean(mean(resultsFolds.RMSE,2),1);
            resultsTotal.RRMSE = mean(mean(resultsFolds.RRMSE,2),1);
            resultsTotal.R2 = mean(mean(resultsFolds.R2,2),1);
            resultsTotal.nBasis = mean(mean(resultsFolds.nBasis,2),1);
            resultsTotal.nVars = mean(mean(resultsFolds.nVars,2),1);
            resultsTotal.maxDeg = mean(mean(resultsFolds.maxDeg,2),1);
            % mean across folds
            if evalPruning
                resultsPruning.meanGCV = mean(resultsPruning.GCV,1);
                [~, resultsPruning.nBasisGCV] = min(resultsPruning.meanGCV);
                resultsPruning.meanMSEoof = mean(resultsPruning.MSEoof,1);
                [~, resultsPruning.nBasisMSEoof] = min(resultsPruning.meanMSEoof);
                % For columns with some NaNs, calculate mean if at least half of the values are not NaN
                nansSum = sum(isnan(resultsPruning.MSEoof),1);
                nansIdx = find((nansSum > 0) & (nansSum <= floor(size(resultsPruning.MSEoof,1) / 2)));
                for i = nansIdx
                    where = isnan(resultsPruning.MSEoof(:,i));
                    resultsPruning.meanMSEoof(i) = mean(resultsPruning.MSEoof(~where,i),1);
                end

                resultsPruning.meanR2GCV = mean(resultsPruning.R2GCV,1);
                [~, resultsPruning.nBasisR2GCV] = max(resultsPruning.meanR2GCV);
                resultsPruning.meanR2oof = mean(resultsPruning.R2oof,1);
                [~, resultsPruning.nBasisR2oof] = max(resultsPruning.meanR2oof);
                % For columns with some NaNs, calculate mean if at least half of the values are not NaN
                nansSum = sum(isnan(resultsPruning.R2oof),1);
                nansIdx = find((nansSum > 0) & (nansSum <= floor(size(resultsPruning.R2oof,1) / 2)));
                for i = nansIdx
                    where = isnan(resultsPruning.R2oof(:,i));
                    resultsPruning.meanR2oof(i) = mean(resultsPruning.R2oof(~where,i),1);
                end
            end
            return
        end

        function [MAE, MSE, RMSE, RRMSE, R2, nBasis, nVars, maxDeg, evalResults] = ...
                doCV(app,X, Y, trainParams, k, shuffle, weights, testWithWeights, evalPruning, verbose, n, d, dy)

            if shuffle
                ind = randperm(n); % shuffle the data
            else
                ind = 1 : n;
            end

            % divide the data into k subsets (for compatibility with Octave, not using special Matlab functions)
            minsize = floor(n / k);
            sizes = repmat(minsize, k, 1);
            remainder = n - minsize * k;
            if remainder > 0
                sizes(1:remainder) = minsize + 1;
            end
            offsets = ones(k, 1);
            for i = 2 : k
                offsets(i) = offsets(i-1) + sizes(i-1);
            end

            if isempty(weights)
                w = [];
            end
            if isempty(weights) || (~testWithWeights)
                wtst = [];
            end

            MAE = Inf(k,dy);
            MSE = Inf(k,dy);
            RMSE = Inf(k,dy);
            RRMSE = Inf(k,dy);
            R2 = -Inf(k,dy);
            nBasis = NaN(k,dy);
            nVars = zeros(k,dy);
            maxDeg = zeros(k,dy);

            if evalPruning
                evalResults.GCV = NaN(k,1);
                evalResults.MSEoof = NaN(k,1);
                evalResults.R2GCV = NaN(k,1);
                evalResults.R2oof = NaN(k,1);
            else
                dataEval = [];
                evalResults = [];
            end

            % perform training and testing k times
            for i = 1 : k
                if verbose
                    disp(['Fold #' num2str(i)]);
                end
                Xtr = zeros(n-sizes(k-i+1), d);
                Ytr = zeros(n-sizes(k-i+1), dy);
                if ~isempty(weights)
                    w = zeros(n-sizes(k-i+1), 1);
                end
                currsize = 0;
                for j = 1 : k
                    if k-i+1 ~= j
                        idxtrain = ind(offsets(j):offsets(j)+sizes(j)-1);
                        Xtr(currsize+1:currsize+1+sizes(j)-1, :) = X(idxtrain, :);
                        Ytr(currsize+1:currsize+1+sizes(j)-1, :) = Y(idxtrain, :);
                        if ~isempty(weights)
                            w(currsize+1:currsize+1+sizes(j)-1, 1) = weights(idxtrain, 1);
                        end
                        currsize = currsize + sizes(j);
                    end
                end
                idxtst = ind(offsets(k-i+1):offsets(k-i+1)+sizes(k-i+1)-1);
                Xtst = X(idxtst, :);
                Ytst = Y(idxtst, :);
                if (~isempty(weights)) && testWithWeights
                    wtst = weights(idxtst, :);
                end
                if evalPruning
                    dataEval.X = Xtst;
                    dataEval.Y = Ytst;
                    dataEval.weights = wtst;
                end
                [model, ~, res] = aresbuild(Xtr, Ytr, trainParams, w, false, [], dataEval, verbose);
                if evalPruning
                    % Accomodate new data, even if bigger than expected
                    sizeOld = size(evalResults.GCV,2);
                    sizeNew = size(res.GCV,1);
                    if sizeOld < sizeNew
                        add = NaN(size(evalResults.GCV,1),sizeNew-sizeOld);
                        evalResults.GCV = [evalResults.GCV add];
                        evalResults.MSEoof = [evalResults.MSEoof add];
                        evalResults.R2GCV = [evalResults.R2GCV add];
                        evalResults.R2oof = [evalResults.R2oof add];
                    end
                    evalResults.GCV(i,1:sizeNew) = res.GCV;
                    evalResults.MSEoof(i,1:sizeNew) = res.MSEtest;
                    evalResults.R2GCV(i,1:sizeNew) = res.R2GCV;
                    evalResults.R2oof(i,1:sizeNew) = res.R2test;
                end
                res = arestest(model, Xtst, Ytst, wtst);
                MAE(i,:) = res.MAE;
                MSE(i,:) = res.MSE;
                RMSE(i,:) = res.RMSE;
                RRMSE(i,:) = res.RRMSE;
                R2(i,:) = res.R2;
                if dy == 1
                    modelCheck = model;
                else
                    modelCheck = model{1}; % analyze only one model because all are identical
                end
                nBasis(i,:) = length(modelCheck.coefs);
                if ~isempty(modelCheck.knotdims)
                    vars = [];
                    deg = 0;
                    for j = 1 : length(modelCheck.knotdims)
                        vars = union(vars, modelCheck.knotdims{j});
                        if length(modelCheck.knotdims{j}) > deg
                            deg = length(modelCheck.knotdims{j});
                        end
                    end
                    nVars(i,:) = length(vars);
                    maxDeg(i,:) = deg;
                end
            end
            return
        end

        function [cBest, results] = arescvc(app,X, Y, trainParams, cTry, k, shuffle, ...
                nCross, weights, testWithWeights, verbose)
            % arescvc
            % Finds the "best" value for penalty c of the Generalized Cross-Validation
            % criterion from a set of candidate values using Cross-Validation assuming
            % that all the other parameters of function aresparams would stay fixed.
            % For a better alternative to using this function, see Section 3.3 in
            % user's manual.
            %
            % Call:
            %   [cBest, results] = arescvc(X, Y, trainParams, cTry, k, shuffle, ...
            %   	nCross, weights, testWithWeights, verbose)
            %
            % All the input arguments, except the first three, are optional. Empty
            % values are also accepted (the corresponding defaults will be used).
            % Note that, if argument shuffle is set to true, this function employs
            % random number generator for which you can set seed before calling the
            % function.
            %
            % Input:
            %   X, Y          : The data. See description of function aresbuild.
            %   trainParams   : A structure of training parameters (see function
            %                   aresparams for details).
            %   cTry          : A set of candidate values for c. (default value = 1:5)
            %   k             : Value of k for k-fold Cross-Validation. The typical
            %                   values are 5 or 10. For Leave-One-Out Cross-Validation
            %                   set k equal to n. (default value = 10)
            %   shuffle       : Whether to shuffle the order of the observations before
            %                   performing Cross-Validation. (default value = true)
            %   nCross        : How many times to repeat Cross-Validation with
            %                   different data partitioning. This can be used to get
            %                   more stable results. Default value = 1, i.e., no
            %                   repetition. Useless if shuffle = false.
            %   weights       : A vector of weights for observations. See description
            %                   of function aresbuild.
            %   testWithWeights : Set to true to use weights vector for both, training
            %                   and testing. Set to false to use it for training only.
            %                   This argument has any effect only when weights vector
            %                   is provided. (default value = true)
            %   verbose       : Whether to output additional information to console.
            %                   (default value = true)
            %
            % Output:
            %   cBest         : The best found value for penalty c.
            %   results       : A matrix with two columns. First column contains all
            %                   values from cTry. Second column contains the calculated
            %                   MSE values (averaged across all Cross-Validation folds)
            %                   for the corresponding cTry values.
            %
            % Remarks:
            % This function finds the "best" penalty c value in a clever way. In each
            % Cross-Validation iteration, the forward phase in aresbuild is done only
            % once while the backward phase is done separately for each cTry value. The
            % results will be the same as if each time a full model building process
            % would be performed because in the forward phase the GCV criterion is not
            % used. Except if aresparams parameter terminateWhenInfGCV is set to true -
            % in that case the results may sometimes slightly differ.

            % =========================================================================
            % ARESLab: Adaptive Regression Splines toolbox for Matlab/Octave
            % Author: Gints Jekabsons (gints.jekabsons@rtu.lv)
            % URL: http://www.cs.rtu.lv/jekabsons/
            %
            % Copyright (C) 2009-2016  Gints Jekabsons
            %
            % This program is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or
            % (at your option) any later version.
            %
            % This program is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with this program. If not, see <http://www.gnu.org/licenses/>.
            % =========================================================================

            % Last update: May 15, 2016

            if nargin < 3
                error('Not enough input arguments.');
            end

            if isempty(X) || isempty(Y)
                error('Data is empty.');
            end
            if iscell(X) || iscell(Y)
                error('X and Y should not be cell arrays.');
            end
            if islogical(Y)
                Y = double(Y);
            elseif ~isfloat(Y)
                error('Y data type should be double or logical.');
            end
            if ~isfloat(X)
                error('X data type should be double.');
            end
            if any(any(isnan(X)))
                error('ARESLab cannot handle missing values (NaN).');
            end

            [n, d] = size(X); % number of observations and number of input variables
            [ny, dy] = size(Y); % number of observations and number of output variables
            if ny ~= n
                error('The number of rows in X and Y should be equal.');
            end

            if ~trainParams.prune
                error('Model pruning is disabled (does not make sense because penalty c is for controlling pruning).');
            end

            if (nargin < 4) || isempty(cTry)
                cTry = 1:5;
            end
            cNum = length(cTry);
            if (nargin < 5) || isempty(k)
                k = 10;
            end
            if k < 2
                error('k should not be smaller than 2.');
            end
            if k > n
                error('k should not be larger than the number of observations.');
            end
            if (nargin < 6) || isempty(shuffle)
                shuffle = true;
            end
            if (nargin < 7) || isempty(nCross)
                nCross = 1;
            else
                if (nCross > 1) && (~shuffle)
                    error('nCross>1 but shuffle=false');
                end
            end
            if (nargin < 8)
                weights = [];
            else
                if (~isempty(weights)) && ...
                        ((size(weights,1) ~= n) || (size(weights,2) ~= 1))
                    error('weights vector is of wrong size.');
                end
            end
            if (nargin < 9) || isempty(testWithWeights)
                testWithWeights = true;
            end
            if (nargin < 10) || isempty(verbose)
                verbose = true;
            end

            MSE = zeros(cNum,k*nCross);
            % Repetition of Cross-Validation nCross times
            for iCross = 1 : nCross
                to = iCross * k;
                range = (to - k + 1) : to;
                MSE(:,range) = doCV(X, Y, trainParams, cTry, k, shuffle, weights, testWithWeights, verbose, n, d, dy, cNum);
            end
            MSE = mean(MSE,2); % mean across all folds
            [~, ind] = min(MSE);
            cBest = cTry(ind);
            results(:,1) = cTry;
            results(:,2) = MSE;
            return
        end

        function MSE = doCV2(app,X, Y, trainParams, cTry, k, shuffle, weights, testWithWeights, verbose, n, d, dy, cNum)

            if shuffle
                ind = randperm(n); % shuffle the data
            else
                ind = 1 : n;
            end

            % divide the data into k subsets (for compatibility with Octave, not using special Matlab functions)
            minsize = floor(n / k);
            sizes = repmat(minsize, k, 1);
            remainder = n - minsize * k;
            if remainder > 0
                sizes(1:remainder) = minsize + 1;
            end
            offsets = ones(k, 1);
            for i = 2 : k
                offsets(i) = offsets(i-1) + sizes(i-1);
            end

            if isempty(weights)
                w = [];
            end
            if isempty(weights) || (~testWithWeights)
                wtst = [];
            end

            % perform training and testing k times
            MSE = zeros(cNum,k);
            for i = 1 : k
                if verbose
                    disp(['Fold #' num2str(i)]);
                end
                Xtr = zeros(n-sizes(k-i+1), d);
                Ytr = zeros(n-sizes(k-i+1), dy);
                if ~isempty(weights)
                    w = zeros(n-sizes(k-i+1), 1);
                end
                currsize = 0;
                for j = 1 : k
                    if k-i+1 ~= j
                        idxtrain = ind(offsets(j):offsets(j)+sizes(j)-1);
                        Xtr(currsize+1 : currsize+1+sizes(j)-1, :) = X(idxtrain, :);
                        Ytr(currsize+1 : currsize+1+sizes(j)-1, :) = Y(idxtrain, :);
                        if ~isempty(weights)
                            w(currsize+1 : currsize+1+sizes(j)-1, 1) = weights(idxtrain, 1);
                        end
                        currsize = currsize + sizes(j);
                    end
                end
                idxtst = ind(offsets(k-i+1):offsets(k-i+1)+sizes(k-i+1)-1);
                Xtst = X(idxtst, :);
                Ytst = Y(idxtst, :);
                if (~isempty(weights)) && testWithWeights
                    wtst = weights(idxtst, :);
                end
                trainParams.prune = false;
                pretendLinear = trainParams.cubic && (trainParams.cubicFastLevel >= 2);
                if pretendLinear
                    % for doCubicFastLevel>=2 we will pretend that this is not cubic
                    % modelling so that aresbuild does not return a model that is
                    % forced to be cubic already before the turned-off backward phase
                    trainParams.cubic = false;
                end
                if trainParams.terminateWhenInfGCV
                    trainParams.c = min(cTry); % so that forward phase doesn't terminate too soon
                end
                modelUnpruned = aresbuild(Xtr, Ytr, trainParams, w, true, [], [], false); % unpruned model
                trainParams.prune = true;
                if pretendLinear
                    trainParams.cubic = true;
                end
                for j = 1 : cNum
                    trainParams.c = cTry(j);
                    model = aresbuild(Xtr, Ytr, trainParams, w, false, modelUnpruned, [], false); % pruned model
                    res = arestest(model, Xtst, Ytst, wtst);
                    if dy == 1
                        MSE(j,i) = res.MSE;
                    else
                        MSE(j,i) = mean(res.MSE);
                    end
                end
            end

            return
        end

        function model = aresdel(app,model, funcsToDel, Xtr, Ytr, weights)
            % aresdel
            % Deletes basis functions from ARES model, recalculates model's
            % coefficients and relocates additional knots for piecewise-cubic models
            % (as opposed to aresanovareduce which does not recalculate and relocate
            % anything).
            %
            % Call:
            %   model = aresdel(model, funcsToDel, Xtr, Ytr, weights)
            %
            % Input:
            %   model         : ARES model or, for multi-response modelling, a cell
            %                   array of ARES models.
            %   funcsToDel    : A vector of indices for basis functions to delete.
            %                   Intercept term is not indexed, i.e., the numbering is
            %                   the same as in model.knotdims, model.knotsites, and
            %                   model.knotdirs.
            %   Xtr, Ytr      : Training data observations. The same data that was used
            %                   when the model was built.
            %   weights       : Optional. A vector of weights for observations. The
            %                   same weights that were used when the model was built.
            %
            % Output:
            %   model         : Reduced ARES model.

            % =========================================================================
            % ARESLab: Adaptive Regression Splines toolbox for Matlab/Octave
            % Author: Gints Jekabsons (gints.jekabsons@rtu.lv)
            % URL: http://www.cs.rtu.lv/jekabsons/
            %
            % Copyright (C) 2009-2016  Gints Jekabsons
            %
            % This program is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or
            % (at your option) any later version.
            %
            % This program is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with this program. If not, see <http://www.gnu.org/licenses/>.
            % =========================================================================

            % Last update: May 15, 2016

            if nargin < 4
                error('Not enough input arguments.');
            end
            if isempty(funcsToDel)
                return;
            end

            if isempty(Xtr) || isempty(Ytr)
                error('Data is empty.');
            end
            if iscell(Xtr) || iscell(Ytr)
                error('Xtr and Ytr should not be cell arrays.');
            end
            if islogical(Ytr)
                Ytr = double(Ytr);
            elseif ~isfloat(Ytr)
                error('Ytr data type should be double or logical.');
            end
            if ~isfloat(Xtr)
                error('Xtr data type should be double.');
            end
            if any(any(isnan(Xtr)))
                error('ARESLab cannot handle missing values (NaN).');
            end

            funcsToDel = unique(funcsToDel(:)');
            [n, d] = size(Xtr); % number of observations and number of input variables
            if size(Ytr,1) ~= n
                error('The number of rows in Xtr and Ytr should be equal.');
            end
            numModels = length(model);
            if (numModels == 1) && iscell(model)
                model = model{1};
            end
            if (numModels ~= size(Ytr,2))
                error('model should contain as many models as there are columns in Ytr.');
            end
            if ((numModels == 1) && any(funcsToDel > length(model.knotdims))) || ...
                    ((numModels > 1) && any(funcsToDel > length(model{1}.knotdims)))
                error('funcsToDel contains one or more indices that are out of range.');
            end
            if ((numModels == 1) && (length(model.minX) ~= d)) || ...
                    ((numModels > 1) && (length(model{1}.minX) ~= d))
                error('The number of columns in Xtr is different from the number when the model was built.');
            end
            if (nargin < 5)
                weights = [];
            else
                if (~isempty(weights)) && ...
                        ((size(weights,1) ~= n) || (size(weights,2) ~= 1))
                    error('weights vector is of wrong size.');
                end
            end

            if numModels == 1
                model = delBF(model, funcsToDel, Xtr, Ytr, weights);
            else
                for k = 1 : numModels
                    model{k} = delBF(model{k}, funcsToDel, Xtr, Ytr(:,k), weights);
                end
            end
            return
        end

        function model = delBF(app,model, listDel, Xtr, Ytr, weights)
            % Deletes basis functions from one model
            listDel = sort(listDel, 2, 'descend');
            for i = listDel % deleting in reverse order
                %model.coefs(i+1) = []; % we don't need this because coefs are completely recalculated below
                model.knotdims(i) = [];
                model.knotsites(i) = [];
                model.knotdirs(i) = [];
                model.parents(i) = [];
                model.parents = updateParents(model.parents, i);
                if model.trainParams.cubic
                    model.t1(i,:) = [];
                    model.t2(i,:) = [];
                end
                if isfield(model, 'X')
                    model.X(:,i+1) = [];
                end
            end

            n = size(Xtr,1);

            if isfield(model, 'X') % in this case we can do it a bit faster
                % For piecewise-linear models, there is nothing to recalculate in model.X
                if model.trainParams.cubic
                    % correct the side knots for the modified model
                    [model.t1, model.t2, diff] = ...
                        findsideknots(model, [], [], size(model.t1,2), model.minX, model.maxX, model.t1, model.t2);
                    % recalculate only columns that have changed
                    for i = diff
                        model.X(:,i+1) = createbasisfunction(Xtr, model.X, model.knotdims{i}, model.knotsites{i}, ...
                            model.knotdirs{i}, 0, model.minX, model.maxX, model.t1(i,:), model.t2(i,:));
                    end
                end
                origWarningState = warning('off');
                [model.coefs, model.MSE] = lreg(model.X, Ytr, weights);
                warning(origWarningState);
            else
                X = ones(n,length(model.knotdims)+1);
                if model.trainParams.cubic
                    % correct the side knots for the modified model
                    [model.t1, model.t2] = findsideknots(model, [], [], size(model.t1,2), model.minX, model.maxX, [], []);
                    % calculate all columns
                    for i = 1 : length(model.knotdims)
                        X(:,i+1) = createbasisfunction(Xtr, X, model.knotdims{i}, model.knotsites{i}, ...
                            model.knotdirs{i}, 0, model.minX, model.maxX, model.t1(i,:), model.t2(i,:));
                    end
                else
                    for i = 1 : length(model.knotdims)
                        X(:,i+1) = createbasisfunction(Xtr, X, model.knotdims{i}, model.knotsites{i}, ...
                            model.knotdirs{i}, 0, model.minX, model.maxX);
                    end
                end
                origWarningState = warning('off');
                [model.coefs, model.MSE] = lreg(X, Ytr, weights);
                warning(origWarningState);
            end

            if isempty(weights)
                model.MSE = model.MSE / n;
            else
                model.MSE = model.MSE / sum(weights);
            end
            model.GCV = gcv(length(model.coefs), model.MSE, n, model.trainParams.c);
            return
        end

        function eq = areseq(app,model, precision, varNames, binarySimple, expandParentBF, cubicSmoothing)
            % areseq
            % Prints equations of ARES model.
            % For multi-response modelling, supply one submodel at a time.
            %
            % Call:
            %   eq = areseq(model, precision, varNames, binarySimple, ...
            %           expandParentBF, cubicSmoothing)
            %
            % All the input arguments, except the first one, are optional. Empty values
            % are also accepted (the corresponding defaults will be used).
            %
            % Input:
            %   model         : ARES model.
            %   precision     : Number of digits in the model coefficients and knot
            %                   sites. Default value = 15.
            %   varNames      : A cell array of variable names to show instead of the
            %                   generic ones.
            %   binarySimple  : Whether to simplify basis functions that use binary
            %                   input variables (default value = false). Note that
            %                   whether a variable is binary is automatically
            %                   determined during model building in aresbuild by
            %                   counting unique values for each variable in training
            %                   data. Therefore a variable can also be taken as binary
            %                   by mistake if the data for some reason includes only
            %                   two values for the variable. You can correct such
            %                   mistakes by editing model.isBinary. Also note that
            %                   whether a variable is binary does not influence
            %                   building of models. It's just used here to simplify
            %                   equations.
            %                   The argument has no effect if the model was allowed to
            %                   have input variables to enter linearly, because then
            %                   all binary variables are handled using linear functions
            %                   instead of hinge functions.
            %   expandParentBF : A basis function that involves multiplication of two
            %                   or more hinge functions can be defined simply as a
            %                   multiplication of an already existing basis function
            %                   (parent) and a new hinge function. Alternatively, it
            %                   can be defined as a multiplication of a number of hinge
            %                   functions. Set expandParentBF to false (default) for
            %                   the former behaviour and to true for the latter.
            %   cubicSmoothing : This is for piecewise-cubic models only. Set to
            %                   'short' (default) to show piecewise-cubic basis
            %                   functions in their short mathematical form (Equation 34
            %                   in Friedman, 1991a). Set to 'full' to show all
            %                   computations involved in calculating the response
            %                   value. Set to 'hide' to hide cubic smoothing and see
            %                   the model as if it would be piecewise-linear. It's
            %                   easier to understand the equations if smoothing is
            %                   hidden. Note that, while the model then looks like
            %                   piecewise-linear, the coefficients are for the actual
            %                   piecewise-cubic model.
            %
            % Output:
            %   eq            : A cell array of strings containing equations for
            %                   individual basis functions and the main model.

            % =========================================================================
            % ARESLab: Adaptive Regression Splines toolbox for Matlab/Octave
            % Author: Gints Jekabsons (gints.jekabsons@rtu.lv)
            % URL: http://www.cs.rtu.lv/jekabsons/
            %
            % Copyright (C) 2009-2016  Gints Jekabsons
            %
            % This program is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or
            % (at your option) any later version.
            %
            % This program is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with this program. If not, see <http://www.gnu.org/licenses/>.
            % =========================================================================

            % Last update: May 15, 2016

            if nargin < 1
                error('Not enough input arguments.');
            end
            if length(model) > 1
                error('This function works with single-response models only. You can supply one submodel at a time.');
            else
                if iscell(model)
                    model = model{1};
                end
            end
            if (nargin < 2) || isempty(precision)
                precision = 15;
            end
            if (nargin >= 3)
                if (~isempty(varNames)) && (length(varNames) ~= length(model.minX))
                    error('Wrong number of names in varNames.');
                end
            else
                varNames = [];
            end
            if (nargin < 4) || isempty(binarySimple)
                binarySimple = false;
            end
            if (nargin < 5) || isempty(expandParentBF)
                expandParentBF = false;
            end
            if (nargin < 6) || isempty(cubicSmoothing)
                cubicSmoothing = 'short';
            else
                if ~strcmpi(cubicSmoothing, {'hide' 'short' 'full'})
                    error('Wrong value for cubicSmoothing.');
                end
            end

            p = ['%.' num2str(precision) 'g'];
            eq = {};
            isPrint = nargout <= 0;

            % compose the individual basis functions
            for i = 1 : length(model.knotdims)
                func = ['BF' num2str(i) ' ='];
                if (~model.trainParams.cubic) || ~strcmpi(cubicSmoothing, 'full')
                    func = [func ' ' getbfstr(model, i, p, binarySimple, expandParentBF, varNames, strcmpi(cubicSmoothing, 'hide'))];
                else
                    if (~expandParentBF) && (model.parents(i) > 0)
                        func = [func ' BF' num2str(model.parents(i)) ' *'];
                        start = length(model.knotdims{i});
                    else
                        start = 1;
                    end
                    for j = start : length(model.knotdims{i})
                        % if the knot is on the very edge, treat the basis function as linear
                        if (model.knotdirs{i}(j) == 2) || ...
                                ((model.knotdirs{i}(j) > 0) && (model.knotsites{i}(j) <= model.minX(model.knotdims{i}(j)))) || ...
                                ((model.knotdirs{i}(j) < 0) && (model.knotsites{i}(j) >= model.maxX(model.knotdims{i}(j))))
                            func = [func ' ' getbfstr(model, i, p, binarySimple, expandParentBF, varNames, strcmpi(cubicSmoothing, 'hide'), j)];
                            continue;
                        end
                        t = num2str(model.knotsites{i}(j),p);
                        t1 = num2str(model.t1(i,model.knotdims{i}(j)),p);
                        t2 = num2str(model.t2(i,model.knotdims{i}(j)),p);
                        pp = ['p' num2str(i) '_' num2str(j)];
                        rr = ['r' num2str(i) '_' num2str(j)];
                        if isempty(varNames)
                            d = ['x' num2str(model.knotdims{i}(j),p)];
                        else
                            d = varNames{model.knotdims{i}(j)};
                        end
                        f = ['f' num2str(i) '_' num2str(j)];
                        if model.knotdirs{i}(j) > 0 % here the hinge function looks like "_/"
                            iff = ['if (' d ' <= ' t1 ') then ' f ' = 0'];
                            if isPrint, disp(iff); end
                            eq{end+1,1} = iff;

                            iff = ['if (' t1 ' < ' d ' < ' t2 ') then begin'];
                            if isPrint, disp(iff); end
                            eq{end+1,1} = iff;
                            pPoz = ['  ' pp ' = (2*(' t2 ') + (' t1 ') - 3*(' t ')) / ((' t2 ') - (' t1 '))^2'];
                            if isPrint, disp(pPoz); end
                            eq{end+1,1} = pPoz;
                            rPoz = ['  ' rr ' = (2*(' t ') - (' t2 ') - (' t1 ')) / ((' t2 ') - (' t1 '))^3'];
                            if isPrint, disp(rPoz); end
                            eq{end+1,1} = rPoz;
                            iff = ['  ' f ' = ' pp ' * (' d ' - (' t1 '))^2 + ' rr ' * (' d ' - (' t1 '))^3'];
                            if isPrint, disp(iff); end
                            eq{end+1,1} = iff;
                            iff = 'end';
                            if isPrint, disp(iff); end
                            eq{end+1,1} = iff;

                            iff = ['if (' d ' >= ' t2 ') then ' f ' = ' d ' - (' t ')'];
                            if isPrint, disp(iff); end
                            eq{end+1,1} = iff;

                            func = [func ' ' f];
                        else % here the hinge function looks like "\_"
                            iff = ['if (' d ' <= ' t1 ') then ' f ' = -(' d ' - (' t '))'];
                            if isPrint, disp(iff); end
                            eq{end+1,1} = iff;

                            iff = ['if (' t1 ' < ' d ' < ' t2 ') then begin'];
                            if isPrint, disp(iff); end
                            eq{end+1,1} = iff;
                            pNeg = ['  ' pp ' = (3*(' t ') - 2*(' t1 ') - (' t2 ')) / ((' t1 ') - (' t2 '))^2'];
                            if isPrint, disp(pNeg); end
                            eq{end+1,1} = pNeg;
                            rNeg = ['  ' rr ' = ((' t1 ') + (' t2 ') - 2*(' t ')) / ((' t1 ') - (' t2 '))^3'];
                            if isPrint, disp(rNeg); end
                            eq{end+1,1} = rNeg;
                            iff = ['  ' f ' = ' pp ' * (' d ' - (' t2 '))^2 + ' rr ' * (' d ' - (' t2 '))^3'];
                            if isPrint, disp(iff); end
                            eq{end+1,1} = iff;
                            iff = 'end';
                            if isPrint, disp(iff); end
                            eq{end+1,1} = iff;

                            iff = ['if (' d ' >= ' t2 ') then ' f ' = 0'];
                            if isPrint, disp(iff); end
                            eq{end+1,1} = iff;

                            func = [func ' ' f];
                        end

                        if j < length(model.knotdims{i})
                            func = [func ' *'];
                        end
                    end
                end
                if isPrint, disp(func); end
                eq{end+1,1} = func;
            end

            % compose the summation
            func = ['y = ' num2str(model.coefs(1),p)];
            for i = 1 : length(model.knotdims)
                if model.coefs(i+1) >= 0
                    func = [func ' +'];
                else
                    func = [func ' '];
                end
                func = [func num2str(model.coefs(i+1),p) '*BF' num2str(i)];
            end
            if isPrint, disp(func); end
            eq{end+1,1} = func;

            if model.trainParams.cubic
                if strcmpi(cubicSmoothing, 'hide')
                    if isPrint, fprintf('\n'); end
                    disp('WARNING: Piecewise-cubic spline smoothing hidden.');
                end
            end

            return
        end

        function knots = aresgetknots(app,model, variable)
            % aresgetknots
            % Gets all knot locations of an ARES model for the specified input
            % variable. A knot is added to the list only if the variable entered a
            % basis function non-linearly, i.e., using a hinge function.
            %
            % Call:
            %   knots = aresgetknots(model, variable)
            %
            % For datasets with one input variable, only the first input argument is
            % used. For datasets with more than one input variable, both input
            % arguments are required.
            %
            % Input:
            %   model         : ARES model or, for multi-response modelling, a cell
            %                   array of ARES models.
            %   variable      : Index of the input variable.
            %
            % Output:
            %   knots         : Column vector of knot locations.

            % =========================================================================
            % ARESLab: Adaptive Regression Splines toolbox for Matlab/Octave
            % Author: Gints Jekabsons (gints.jekabsons@rtu.lv)
            % URL: http://www.cs.rtu.lv/jekabsons/
            %
            % Copyright (C) 2009-2016  Gints Jekabsons
            %
            % This program is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or
            % (at your option) any later version.
            %
            % This program is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with this program. If not, see <http://www.gnu.org/licenses/>.
            % =========================================================================

            % Last update: May 2, 2016

            if (nargin < 1)
                error('Not enough input arguments.');
            end
            if length(model) > 1
                model = model{1};
            end
            if (nargin < 2) && (length(model.minX) > 1)
                error('Not enough input arguments.');
            end
            if length(model.minX) == 1
                variable = 1;
            else
                if length(variable) ~= 1
                    error('Input argument variable should be scalar.');
                end
            end

            knots = [];
            nBasis = length(model.knotsites);
            if nBasis > 0
                for i = 1 : nBasis
                    which = (model.knotdims{i} == variable) & (model.knotdirs{i} ~= 2);
                    knots = union(knots, model.knotsites{i}(which));
                end
                knots = knots(:);
            end
            return
        end

        function varImp = aresimp(app,model, Xtr, Ytr, resultsEval, weights)
            % aresimp
            % Performs input variable importance assessment and reports the results.
            % For details, see remarks on aresimp in user's manual.
            % For multi-response modelling, supply one submodel at a time.
            %
            % Call:
            %   varImp = aresimp(model, Xtr, Ytr, resultsEval, weights)
            %
            % The first three input arguments are required.
            %
            % Input:
            %   model         : ARES model.
            %   Xtr, Ytr      : Training data observations. The same data that was used
            %                   when the model was built.
            %   resultsEval   : resultsEval from function aresbuild. Do not use this
            %                   argument if model was modified by any function other
            %                   than aresbuild (i.e., aresdel or aresanovareduce).
            %   weights       : A vector of weights for observations. The same weights
            %                   that were used when the model was built.
            %
            % Output:
            %   varImp        : A matrix of estimated variable importance. Rows
            %                   correspond to input variables, columns correspond to
            %                   criterion used. If argument resultsEval is not supplied
            %                   or the model was not pruned, then 2nd, 3rd, and 4th
            %                   columns are NaN.

            % =========================================================================
            % ARESLab: Adaptive Regression Splines toolbox for Matlab/Octave
            % Author: Gints Jekabsons (gints.jekabsons@rtu.lv)
            % URL: http://www.cs.rtu.lv/jekabsons/
            %
            % Copyright (C) 2009-2016  Gints Jekabsons
            %
            % This program is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or
            % (at your option) any later version.
            %
            % This program is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with this program. If not, see <http://www.gnu.org/licenses/>.
            % =========================================================================

            % Last update: May 15, 2016

            if nargin < 3
                error('Not enough input arguments.');
            end

            if isempty(Xtr) || isempty(Ytr)
                error('Data is empty.');
            end
            if iscell(Xtr) || iscell(Ytr)
                error('Xtr and Ytr should not be cell arrays.');
            end
            if islogical(Ytr)
                Ytr = double(Ytr);
            elseif ~isfloat(Ytr)
                error('Ytr data type should be double or logical.');
            end
            if ~isfloat(Xtr)
                error('Xtr data type should be double.');
            end
            if any(any(isnan(Xtr)))
                error('ARESLab cannot handle missing values (NaN).');
            end

            [n, d] = size(Xtr); % number of observations and number of input variables
            if size(Ytr,1) ~= n
                error('The number of rows in Xtr and Ytr should be equal.');
            end
            if length(model) > 1
                error('This function works with single-response models only. You can supply one submodel at a time.');
            else
                if iscell(model)
                    model = model{1};
                end
            end
            if length(model.minX) ~= d
                error('The number of columns in Xtr is different from the number when the model was built.');
            end
            if size(Ytr,2) ~= 1
                error('Ytr should have one column.');
            end
            if (nargin < 4)
                resultsEval = [];
            end
            if (nargin < 5)
                weights = [];
            else
                if (~isempty(weights)) && ...
                        ((size(weights,1) ~= n) || (size(weights,2) ~= 1))
                    error('weights vector is of wrong size.');
                end
            end

            nVars = length(model.minX);
            nBasisExI = length(model.knotdims); % excluding intercept

            fprintf('Estimated input variable importance:\n');
            if isempty(resultsEval)
                fprintf('(columns nSubsets, subsRSS, and subsGCV not available with empty argument resultsEval)\n');
            end
            fprintf('Variable    delGCV');
            if ~isempty(resultsEval)
                fprintf('      nSubsets       subsRSS       subsGCV');
            end
            fprintf('\n');

            varImp = NaN(nVars,4);

            % The first criterion
            for v = 1 : nVars
                funcsToDel = [];
                for i = 1 : nBasisExI
                    if any(model.knotdims{i} == v)
                        funcsToDel = [funcsToDel i];
                    end
                end
                if ~isempty(funcsToDel)
                    modelReduced = aresdel(model, funcsToDel, Xtr, Ytr, weights);
                    varImp(v,1) = sqrt(modelReduced.GCV) - sqrt(model.GCV);
                else
                    varImp(v,1) = 0;
                end
            end
            if max(varImp(:,1)) ~= 0
                varImp(:,1) = varImp(:,1) ./ max(varImp(:,1)) * 100; % scaling
            end

            % The other three criteria
            if ~isempty(resultsEval)
                finalSize = length(model.knotdims);
                varImp(:,2) = sum(resultsEval.usedVars(2:finalSize,:),1);

                reduction = resultsEval.MSE(1:(finalSize-1)) - resultsEval.MSE(2:finalSize);
                for v = 1 : nVars
                    varImp(v,3) = sum(reduction(resultsEval.usedVars(2:finalSize,v)));
                end
                if max(varImp(:,3)) ~= 0
                    varImp(:,3) = varImp(:,3) ./ max(varImp(:,3)) * 100; % scaling
                end

                reduction = resultsEval.GCV(1:(finalSize-1)) - resultsEval.GCV(2:finalSize);
                for v = 1 : nVars
                    varImp(v,4) = sum(reduction(resultsEval.usedVars(2:finalSize,v)));
                end
                if max(varImp(:,4)) ~= 0
                    varImp(:,4) = varImp(:,4) ./ max(varImp(:,4)) * 100; % scaling
                end
            end

            % Printing results
            for v = 1 : nVars
                counterStr = num2str(v);
                counterStr = [counterStr repmat(' ', 1, 4-length(counterStr))];
                fprintf(counterStr);
                fprintf('%14.3f', varImp(v,1));
                if ~isempty(resultsEval)
                    fprintf('%14d%14.3f%14.3f', varImp(v,2), varImp(v,3), varImp(v,4));
                    if ~any(resultsEval.usedVars(2:finalSize,v))
                        fprintf('        unused');
                    end
                end
                fprintf('\n');
            end

            return
        end

        function aresinfo(app,model, Xtr, Ytr, weights, showBF, sortByGCV, binarySimple, expandParentBF, cubicAsLinear)
            % aresinfo
            % Takes an ARES model, prints each basis function together with MSE, GCV,
            % and R2GCV (GCV estimate of the Coefficient of Determination, R2) for a
            % model from which the basis function was removed. By default, the
            % functions are listed in the order of decreasing GCV - bigger is
            % better. This can be used to judge whether, in the specific context of the
            % given full model, a basis function is making an important contribution,
            % or whether it just slightly helps to improve the global GCV score. See
            % remarks below.
            % For multi-response modelling, supply one submodel at a time.
            %
            % Call:
            %   aresinfo(model, Xtr, Ytr, weights, showBF, sortByGCV, binarySimple, ...
            %       expandParentBF, cubicAsLinear)
            %
            % All the input arguments, except the first three, are optional. Empty
            % values are also accepted (the corresponding defaults will be used).
            %
            % Input:
            %   model         : ARES model.
            %   Xtr, Ytr      : Training data observations.
            %   weights       : A vector of weights for observations. The same weights
            %                   that were used when the model was built.
            %   showBF        : Whether to show equations of basis functions or just
            %                   list input variables the basis functions are using
            %                   (default value = true).
            %   sortByGCV     : Whether to list basis functions in the order of
            %                   decreasing GCV or in the order in which they were
            %                   included in the model (default value = true).
            %   binarySimple  : See description of input argument of the same name for
            %                   function areseq. (default value = false)
            %   expandParentBF : See description of input argument of the same name for
            %                   function areseq. (default value = false)
            %   cubicAsLinear : This is for piecewise-cubic models only. Set to
            %                   false (default) to show piecewise-cubic basis
            %                   functions in their own mathematical form (Equation 34
            %                   in Friedman, 1991a). Set to true to hide cubic
            %                   smoothing - see the basis functions as if the model
            %                   would be piecewise-linear. It's easier to understand
            %                   the equations if smoothing is hidden. Note that, while
            %                   the basis functions then look like from a piecewise-
            %                   linear model, the coefficients are from the actual
            %                   piecewise-cubic model.
            %
            % Remarks:
            % 1. If it is determined that by deleting a one specific basis function GCV
            %    would decrease (i.e., model would get better) or stay about the same,
            %    you will see an exclamation mark next to the GCV value of that basis
            %    function. This can happen either because the basis function is
            %    irrelevant or it's redundant with some other basis function(s) in the
            %    model. But note that if more than one basis function has such mark, it
            %    does not mean that all of them should be deleted at once or at all.
            %    Instead it means that you can try deleting them one after another
            %    (using function aresdel) starting from the least important one, each
            %    time recalculating this table, until all of the basis functions still
            %    left in model stop having that mark. This is similar to what the
            %    backward pruning phase does, except that it continues until model
            %    consists only of the intercept term and then selects the model with
            %    the best GCV from all tried sizes.
            % 2. If you are using piecewise-cubic modelling with the default value for
            %    parameter cubicFastLevel you may sometimes see that a basis function
            %    has an exclamation mark even though you didn't disable the backward
            %    pruning phase and therefore all irrelevant and redundant basis
            %    functions should be already deleted. This is because by default models
            %    are pruned as piecewise-linear and only after pruning they become
            %    piecewise-cubic therefore it's possible that a basis function
            %    inclusion of which previously slightly reduced GCV, suddenly slightly
            %    increases it.
            % 3. The column "hinges" shows types of functions that are multiplied to
            %    comprise the basis function. Hinge functions are shown as "_/" or
            %    "\_". Linear functions for variables that entered linearly are shown
            %    as "/". The functions are showed in the same order as in the column
            %    "basis function".

            % =========================================================================
            % ARESLab: Adaptive Regression Splines toolbox for Matlab/Octave
            % Author: Gints Jekabsons (gints.jekabsons@rtu.lv)
            % URL: http://www.cs.rtu.lv/jekabsons/
            %
            % Copyright (C) 2009-2016  Gints Jekabsons
            %
            % This program is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or
            % (at your option) any later version.
            %
            % This program is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with this program. If not, see <http://www.gnu.org/licenses/>.
            % =========================================================================

            % Last update: May 15, 2016

            if nargin < 3
                error('Not enough input arguments.');
            end

            if isempty(Xtr) || isempty(Ytr)
                error('Data is empty.');
            end
            if iscell(Xtr) || iscell(Ytr)
                error('Xtr and Ytr should not be cell arrays.');
            end
            if islogical(Ytr)
                Ytr = double(Ytr);
            elseif ~isfloat(Ytr)
                error('Ytr data type should be double or logical.');
            end
            if ~isfloat(Xtr)
                error('Xtr data type should be double.');
            end
            if any(any(isnan(Xtr)))
                error('ARESLab cannot handle missing values (NaN).');
            end

            [n, d] = size(Xtr); % number of observations and number of input variables
            if size(Ytr,1) ~= n
                error('The number of rows in Xtr and Ytr should be equal.');
            end
            if length(model) > 1
                error('This function works with single-response models only. You can supply one submodel at a time.');
            else
                if iscell(model)
                    model = model{1};
                end
            end
            if length(model.minX) ~= d
                error('The number of columns in Xtr is different from the number when the model was built.');
            end
            if size(Ytr,2) ~= 1
                error('Ytr should have one column.');
            end
            if (nargin < 4)
                weights = [];
            else
                if (~isempty(weights)) && ...
                        ((size(weights,1) ~= n) || (size(weights,2) ~= 1))
                    error('weights vector is of wrong size.');
                end
            end
            if (nargin < 5) || isempty(showBF)
                showBF = true;
            end
            if (nargin < 6) || isempty(sortByGCV)
                sortByGCV = true;
            end
            if (nargin < 7) || isempty(binarySimple)
                binarySimple = false;
            end
            if (nargin < 8) || isempty(expandParentBF)
                expandParentBF = false;
            end
            if (nargin < 9) || isempty(cubicAsLinear)
                cubicAsLinear = false;
            end

            gcvNull = gcv(1, var2(Ytr, weights), n, 0);

            maxDeg = 0;
            if ~isempty(model.knotdims)
                for i = 1 : length(model.knotdims)
                    if length(model.knotdims{i}) > maxDeg
                        maxDeg = length(model.knotdims{i});
                    end
                end
            end
            if maxDeg > 2
                strAdd = repmat(' ', 1, (maxDeg-2) * 3);
            else
                strAdd = '';
            end

            if model.trainParams.cubic
                fprintf('Type: piecewise-cubic\n');
            else
                fprintf('Type: piecewise-linear\n');
            end
            fprintf('MSE: %g\n', model.MSE);
            fprintf('GCV: %g\n', model.GCV);
            fprintf('R2GCV: %g\n', 1 - model.GCV / gcvNull);
            fprintf('Total number of basis functions (including intercept): %d\n', length(model.coefs));
            fprintf('Total effective number of parameters: %g\n', ...
                length(model.coefs) + model.trainParams.c * length(model.knotdims) / 2);
            fprintf('Basis functions:\n');
            fprintf(['BF          MSE          GCV        R2GCV           coef    hinges   ' strAdd]);
            if showBF
                fprintf('basis function\n');
            else
                fprintf('variable(s)\n');
            end
            % Gather all MSEs and GCVs
            mses = zeros(1,length(model.knotdims));
            gcvs = zeros(1,length(model.knotdims));
            for i = 1 : length(model.knotdims)
                modelReduced = aresdel(model, i, Xtr, Ytr, weights);
                mses(i) = modelReduced.MSE;
                gcvs(i) = modelReduced.GCV;
            end
            if sortByGCV
                [~, idx] = sort(gcvs, 'descend');
            else
                idx = 1 : length(model.knotdims);
            end
            fprintf(['0             -            -            -%15.5g             ' strAdd '(intercept)\n'], model.coefs(1));
            % Info about each basis function
            for i = idx
                iStr = num2str(i);
                iStr = [iStr repmat(' ', 1, 4-length(iStr))];
                fprintf(iStr);
                fprintf('%11.5f', mses(i));
                fprintf('%13.5f', gcvs(i));
                if gcvs(i) / gcvNull - 1e-10 <= model.GCV / gcvNull % the same result as with gcvs alone but allows that 1e-10 stuff
                    fprintf(' !');
                else
                    fprintf('  ');
                end
                fprintf('%11.5f', 1 - gcvs(i) / gcvNull);
                fprintf('%15.5g', model.coefs(i+1));

                fprintf('   ');
                for dirs = model.knotdirs{i}
                    if dirs == 2
                        fprintf(' / ');
                    elseif dirs > 0
                        fprintf(' _/');
                    else
                        fprintf(' \\_');
                    end
                end
                if length(model.knotdims{i}) < maxDeg
                    fprintf(repmat(' ', 1, (maxDeg-length(model.knotdims{i})) * 3)); % fill the rest with spaces
                end
                if maxDeg < 2
                    fprintf('   '); % fill the rest with spaces
                end

                if showBF
                    if (~model.trainParams.cubic) || cubicAsLinear
                        p = '%.5g';
                    else
                        p = '%.3g';
                    end
                    fprintf('    %s\n', getbfstr(model, i, p, binarySimple, expandParentBF, [], cubicAsLinear));
                else
                    fprintf('    ');
                    fprintf('%d ', model.knotdims{i});
                    fprintf('\n');
                end
            end
            return
        end

        function res = var2(~,values, weights)
            if isempty(weights)
                res = var(values, 1);
            else
                valuesMean = sum(values(:,1) .* weights) / sum(weights);
                res = sum(((values(:,1) - valuesMean) .^ 2) .* weights) / sum(weights);
            end
            return
        end

        function trainParams = aresparams(app,maxFuncs, c, cubic, cubicFastLevel, ...
                selfInteractions, maxInteractions, threshold, prune, fastK, fastBeta, ...
                fastH, useMinSpan, useEndSpan, maxFinalFuncs, endSpanAdjust, ...
                newVarPenalty, terminateWhenInfGCV, yesInteract, noInteract, ...
                allowLinear, forceLinear)
            % aresparams
            % Creates configuration for building ARES models. The output structure is
            % for further use with aresbuild, arescv, and arescvc functions.
            %
            % Call:
            %   trainParams = aresparams(maxFuncs, c, cubic, cubicFastLevel, ...
            %       selfInteractions, maxInteractions, threshold, prune, fastK, ...
            %       fastBeta, fastH, useMinSpan, useEndSpan, maxFinalFuncs, ...
            %       endSpanAdjust, newVarPenalty, terminateWhenInfGCV, yesInteract, ...
            %       noInteract, allowLinear, forceLinear)
            %
            % All the input arguments of this function are optional. Empty values are
            % also accepted (the corresponding defaults will be used).
            % Parameters prune and maxFinalFuncs are used in the backward pruning
            % phase. Parameters c and cubic may be used in both phases depending on
            % terminateWhenInfGCV, forceLinear, and cubicFastLevel. All other
            % parameters are used in the forward phase only.
            % For many applications, it can be expected that the most attention should
            % be paid to the following parameters: maxFuncs, maxInteractions, cubic, c,
            % and maxFinalFuncs. It is quite possible that the default values for
            % maxFuncs and maxInteractions will be far from optimal for your data.
            % But note that, if you are prepared to use Cross-Validation, choosing a
            % good value for maxFinalFuncs can sometimes release you from being too
            % pedantic about parameters maxFuncs and c, because you can set large
            % enough maxFuncs and not too large c and follow the example in Section 3.3
            % in user's manual.
            % If you have the necessary domain knowledge, it is recommended to also set
            % yesInteract, noInteract, allowLinear, and forceLinear.
            %
            % Input:
            %   maxFuncs      : The maximum number of basis functions included in model
            %                   in the forward building phase (before pruning in the
            %                   backward phase). Includes the intercept term. The
            %                   recommended value for this parameter is about two times
            %                   the expected number of basis functions in the final
            %                   model (Friedman, 1991a). Note that the algorithm may
            %                   also not reach this number if some other termination
            %                   condition happens first (see remarks on function
            %                   aresbuild in user's manual). The default value for
            %                   maxFuncs is -1 in which case it is calculated
            %                   automatically using formula min(200, max(20, 2d)) + 1,
            %                   where d is the number of input variables (Milborrow,
            %                   2016). This is fairly arbitrary but can be useful for
            %                   first experiments.
            %                   To enforce an upper bound on the final model size, use
            %                   maxFinalFuncs instead. This is because the forward
            %                   phase can see only one basis function ahead while the
            %                   backward pruning phase can choose any of the built
            %                   basis functions to include in the final model.
            %   c             : Generalized Cross-Validation (GCV) penalty per knot.
            %                   Larger values for c will lead to fewer knots (i.e., the
            %                   final model will have fewer basis functions). A value
            %                   of 0 penalizes only terms, not knots (can be useful,
            %                   e.g., with lots of data, low or no noise, and highly
            %                   structured underlying function of the data). Generally,
            %                   the choice of the value for c should greatly depend on
            %                   size of the dataset, how structured is the underlying
            %                   function, and how high is the noise level, and mildly
            %                   depend on the thoroughness of the optimization
            %                   procedure, i.e., on the parameters maxFuncs,
            %                   maxInteractions, and useMinSpan (Friedman, 1991a).
            %                   Simulation studies suggest values for c in the range of
            %                   about 2 to 4 (Friedman, 1991a). The default value for
            %                   this parameter is -1 in which case c is chosen
            %                   automatically using the following rule: if
            %                   maxInteractions = 1 (additive modelling) c = 2,
            %                   otherwise c = 3. These are the values recommended in
            %                   Friedman, 1991a.
            %   cubic         : Whether to use piecewise-cubic (true) or piecewise-
            %                   linear (false) type of modelling. In general, it is
            %                   expected that the piecewise-cubic modelling will give
            %                   better predictive performance for smoother and less
            %                   noisy data. (default value = true)
            %   cubicFastLevel : aresbuild implements three levels of piecewise-cubic
            %                   modelling. In level 0, cubic modelling for each
            %                   candidate model is done in both phases of the method
            %                   (slow). In level 1, cubic modelling is done only in the
            %                   backward phase (much faster). In level 2, cubic
            %                   modelling is done after both phases, only for the final
            %                   model (fastest). The default level is 2 (and it
            %                   corresponds to the recommendations in Friedman, 1991a).
            %                   Levels 0 and 1 may bring extra accuracy in the
            %                   situations when, e.g., the underlying function of the
            %                   data has sharp thresholds that for piecewise-cubic
            %                   modelling require knot placements different that those
            %                   required for piecewise-linear modelling.
            %   selfInteractions : This is experimental feature. The maximum degree of
            %                   self interactions for any input variable. It can be set
            %                   larger than 1 only for piecewise-linear modelling. The
            %                   default, and recommended, value = 1, no self
            %                   interactions.
            %   maxInteractions : The maximum degree of interactions between input
            %                   variables. Set to 1 (default) for additive modelling
            %                   (i.e., no interactions). For maximal interactivity
            %                   between the variables, set the parameter to
            %                   d * selfInteractions, where d is the number of input
            %                   variables – this way the modelling procedure will have
            %                   the most freedom building a complex model. Set to -1,
            %                   so that aresbuild sets it automatically equal to d
            %                   (maximal interactivity when self interactions are not
            %                   used).
            %   threshold     : One of the stopping criteria for the forward phase (see
            %                   remarks section of function aresbuild in user's manual
            %                   for details). Default value = 1e-4. For noise-free
            %                   data, the value may be lowered (e.g., to 1e-6) but
            %                   setting it to 0 can cause numerical issues and
            %                   instability.
            %   prune         : Whether to perform model pruning (the backward phase).
            %                   (default value = true)
            %   fastK         : Parameter (integer) for Fast MARS algorithm (Friedman,
            %                   1993, Section 3.0). Maximum number of parent basis
            %                   functions considered at each step of the forward phase.
            %                   Typical values for fastK are 20, 10, 5 (default value =
            %                   Inf, i.e., no Fast MARS). With lower fastK values
            %                   model building is faster at the expense of some
            %                   accuracy. Good starting values for exploratory work
            %                   are fastK = 20, fastBeta = 1, fastH = 5 (Friedman,
            %                   1993). Friedman in his paper concluded that changing
            %                   the values of fastK and fastH can have big effect on
            %                   training computation times but predictive performance
            %                   is largely unaffected over a wide range of their values
            %                   (Friedman, 1993).
            %   fastBeta      : Artificial ageing factor for Fast MARS algorithm
            %                   (Friedman, 1993, Section 3.1). Typical value for
            %                   fastBeta is 1 (default value = 0, i.e., no artificial
            %                   ageing). The parameter is ignored if fastK = Inf.
            %   fastH         : Parameter (integer) for Fast MARS algorithm (Friedman,
            %                   1993, Section 4.0). Number of iterations till next full
            %                   optimization over all input variables for each parent
            %                   basis function. Larger values make the search faster.
            %                   Typical values for fastH are 1, 5, 10 (default value =
            %                   1, i.e., full optimization in every iteration).
            %                   Computational reduction associated with increasing
            %                   fastH is most pronounced for data sets with many input
            %                   variables and when large fastK is used. There seems to
            %                   be little gain in increasing fastH beyond 5 (Friedman,
            %                   1993). The parameter is ignored if fastK = Inf.
            %   useMinSpan    : In order to lower the local variance of the estimates,
            %                   a minimum span is imposed that makes the method
            %                   resistant to runs of positive or negative error values
            %                   between knots (by taking every useMinSpan-th
            %                   observation for knot placement) (Friedman, 1991a).
            %                   Setting useMinSpan to -1 (default), enables automatic
            %                   mode (see remarks in user's manual). Setting useMinSpan
            %                   to 0 or 1, disables the protection so that all the
            %                   observations are considered for knot placement (except,
            %                   see useEndSpan). Setting useMinSpan to > 1, enables
            %                   manual tuning of the value. Disabling or lowering
            %                   useMinSpan may allow creating a model which is more
            %                   responsive to local variations in the data (can be
            %                   especially useful if the number of data observations is
            %                   small and noise is low) however this can also lead to
            %                   overfitting. For further information and examples of
            %                   usage, see Section 3.4 in user's manual.
            %   useEndSpan    : In order to lower the local variance of the estimates
            %                   near the ends of data intervals, a minimum span is
            %                   imposed that makes the method resistant to runs of
            %                   positive or negative error values between extreme knot
            %                   locations and the corresponding ends of data intervals
            %                   (by not allowing to place knots too near to the ends of
            %                   the intervals) (Friedman, 1991a). Setting useEndSpan to
            %                   -1 (default), enables automatic mode that chooses value
            %                   for this parameter depending on the number of input
            %                   variables (but never lower than 7). Setting useEndSpan
            %                   to 0, disables the protection so that all the
            %                   observations are considered for knot placement (except,
            %                   see useMinSpan). Setting useEndSpan to > 1, enables
            %                   manual tuning of the value. Disabling or lowering
            %                   useEndSpan may allow creating a model which is more
            %                   responsive to local variations near the edges of the
            %                   data (can be especially useful if the number of data
            %                   observations is small and noise is low) however this
            %                   can also lead to overfitting. For further information
            %                   and examples of usage, see remarks for aresparams and
            %                   Section 3.4 in user's manual.
            %   maxFinalFuncs : Maximum number of basis functions (including the
            %                   intercept term) in the final pruned model (default
            %                   value = Inf). Use this (rather than the maxFuncs
            %                   parameter) to enforce an upper bound on the final model
            %                   size. See Section 3.3 in user's manual for an example
            %                   on how to choose value for this parameter using Cross-
            %                   Validation.
            %   endSpanAdjust : For basis functions with variable interactions,
            %                   useEndSpan gets multiplied by this value. This reduces
            %                   probability of getting overfitted interaction terms
            %                   supported by just a few observations on the boundaries
            %                   of data intervals. Still, at least one knot will always
            %                   be allowed in the middle, even if endSpanAdjust would
            %                   prohibit it. Useful values range from 1 to 10. (default
            %                   value = 1, i.e., no adjustment)
            %   newVarPenalty : Penalty for adding a new variable to a model in the
            %                   forward phase. This is the gamma parameter of Eq. 74 in
            %                   the original paper (Friedman, 1991a). The higher is the
            %                   penalty, the more reluctant will be the forward phase
            %                   to add a new variable to the model - it will rather try
            %                   to use variables already in the model. This can be
            %                   useful when some of the variables are highly collinear.
            %                   As a result, the final model may be easier to interpret
            %                   although usually the built models also will have worse
            %                   predictive performance. Useful non-zero values
            %                   typically range from 0.01 to 0.2 (Milborrow, 2016).
            %                   (default value = 0, i.e., no penalty)
            %   terminateWhenInfGCV : Whether to check termination condition,
            %                   terminating forward phase when the effective number of
            %                   parameters of a model reaches the number of
            %                   observations in training data, i.e., when GCV for
            %                   such large models would be Inf (see remarks section in
            %                   description of function aresbuild on GCV in user's
            %                   manual). In such cases it could be pointless to
            %                   continue because larger models wouldn't be considered
            %                   as candidates for final model anyway. On the other
            %                   hand, some of the added basis functions could still
            %                   turn out to be useful for inclusion in final model.
            %                   Note that the effective number of parameters is not the
            %                   same as the number of regression coefficients, except
            %                   when c = 0 (in which case enabling terminateWhenInfGCV
            %                   has no additional effect). (default value = false)
            %   yesInteract   : A matrix indicating pairs of input variables that are
            %                   allowed to interact with each other in the ARES model.
            %                   The matrix must have two columns. Each row is a pair of
            %                   indices for the input variables. Default value = [].
            %                   Cannot be used together with noInteract.
            %   noInteract    : A matrix indicating pairs of input variables that are
            %                   not allowed to interact with each other in the ARES
            %                   model. The matrix must have two columns. Each row is a
            %                   pair of indices for the input variables. Default value
            %                   = []. Cannot be used together with yesInteract.
            %   allowLinear   : Whether to allow input variables to enter basis
            %                   functions linearly, i.e., without hinge functions.
            %                   Such basis functions are added to the model one at a
            %                   time, as opposed to basis functions with new hinges
            %                   that are added two at a time - one for each hinge of
            %                   the reflected pair. Set allowLinear to 0 (default), to
            %                   disallow variables entering linearly, i.e., consider
            %                   hinge functions only (except see forceLinear). Set to
            %                   1, to allow, and treat error reduction associated with
            %                   adding such basis function the same way as for a pair
            %                   of basis functions with new hinges. Set to 2, to prefer
            %                   variables entering basis functions linearly. This is
            %                   done by calculating error reduction of such basis
            %                   functions using GCV (instead of sum of squared error),
            %                   resulting in preference of adding a single basis
            %                   function instead of two even when this produces
            %                   slightly smaller error reduction. Note that the R
            %                   package earth (Milborrow, 2016) has options "0" and "1"
            %                   while py-earth (Rudy, 2016) has options "0" and "2".
            %   forceLinear   : A vector of indices of input variables that should be
            %                   forced to enter the model only linearly, i.e., without
            %                   hinge functions. This overrides allowLinear for the
            %                   listed variables. Note that forceLinear does not say
            %                   that a variable must enter the model; only that if it
            %                   enters, it enters linearly. Also note that it has
            %                   nothing to do with whether a variable is allowed to
            %                   interact with other variables. Default value = [].
            %
            % Output:
            %   trainParams   : A structure of parameters for further use with
            %                   aresbuild, arescv, and arescvc functions containing the
            %                   provided values (or defaults, if not provided).

            % =========================================================================
            % ARESLab: Adaptive Regression Splines toolbox for Matlab/Octave
            % Author: Gints Jekabsons (gints.jekabsons@rtu.lv)
            % URL: http://www.cs.rtu.lv/jekabsons/
            %
            % Copyright (C) 2009-2016  Gints Jekabsons
            %
            % This program is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or
            % (at your option) any later version.
            %
            % This program is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with this program. If not, see <http://www.gnu.org/licenses/>.
            % =========================================================================

            % Last update: May 15, 2016

            if (nargin < 1) || isempty(maxFuncs)
                trainParams.maxFuncs = -1; % automatic
            else
                if ischar(maxFuncs)
                    error('Bad argument value.');
                end
                trainParams.maxFuncs = maxFuncs;
            end

            if (nargin < 2) || isempty(c)
                trainParams.c = -1; % automatic
            else
                trainParams.c = c;
            end

            if (nargin < 3) || isempty(cubic)
                trainParams.cubic = true;
            else
                trainParams.cubic = cubic;
            end

            if (nargin < 4) || isempty(cubicFastLevel)
                trainParams.cubicFastLevel = 2;
            else
                trainParams.cubicFastLevel = cubicFastLevel;
            end

            if (nargin < 5) || isempty(selfInteractions)
                trainParams.selfInteractions = 1;
            else
                trainParams.selfInteractions = selfInteractions;
            end

            if (nargin < 6) || isempty(maxInteractions)
                trainParams.maxInteractions = 1; % applicable maximum is d * trainParams.selfInteractions
            else
                trainParams.maxInteractions = maxInteractions;
            end

            if (nargin < 7) || isempty(threshold)
                trainParams.threshold = 1e-4;
            else
                trainParams.threshold = threshold;
            end

            if (nargin < 8) || isempty(prune)
                trainParams.prune = true;
            else
                trainParams.prune = prune;
            end

            if (nargin < 9) || isempty(fastK)
                trainParams.fastK = Inf;
            else
                trainParams.fastK = fastK;
            end

            if (nargin < 10) || isempty(fastBeta)
                trainParams.fastBeta = 0;
            else
                trainParams.fastBeta = fastBeta;
            end

            if (nargin < 11) || isempty(fastH)
                trainParams.fastH = 1;
            else
                trainParams.fastH = fastH;
            end

            if (nargin < 12) || isempty(useMinSpan)
                trainParams.useMinSpan = -1; % automatic
            else
                if useMinSpan == 0
                    trainParams.useMinSpan = 1; % 1 and 0 is the same here (no minspan)
                else
                    trainParams.useMinSpan = useMinSpan;
                end
            end

            if (nargin < 13) || isempty(useEndSpan)
                trainParams.useEndSpan = -1; % automatic
            else
                trainParams.useEndSpan = useEndSpan;
            end

            if (nargin < 14) || isempty(maxFinalFuncs)
                trainParams.maxFinalFuncs = Inf;
            else
                trainParams.maxFinalFuncs = maxFinalFuncs;
            end

            if (nargin < 15) || isempty(endSpanAdjust)
                trainParams.endSpanAdjust = 1;
            else
                trainParams.endSpanAdjust = endSpanAdjust;
            end

            if (nargin < 16) || isempty(newVarPenalty)
                trainParams.newVarPenalty = 0;
            else
                trainParams.newVarPenalty = newVarPenalty;
            end

            if (nargin < 17) || isempty(terminateWhenInfGCV)
                trainParams.terminateWhenInfGCV = false;
            else
                trainParams.terminateWhenInfGCV = terminateWhenInfGCV;
            end

            if (nargin < 18) || isempty(yesInteract)
                trainParams.yesInteract = [];
            else
                trainParams.yesInteract = yesInteract;
            end

            if (nargin < 19) || isempty(noInteract)
                trainParams.noInteract = [];
            else
                trainParams.noInteract = noInteract;
            end

            if (nargin < 20) || isempty(allowLinear)
                trainParams.allowLinear = 0;
            else
                trainParams.allowLinear = allowLinear;
            end

            if (nargin < 21) || isempty(forceLinear)
                trainParams.forceLinear = [];
            else
                trainParams.forceLinear = forceLinear;
            end

            return
        end

        function trainParams = aresparams2(app,varargin)
            % aresparams2
            % Creates configuration for building ARES models. The output structure is
            % for further use with aresbuild, arescv, and arescvc functions.
            % This function is an alternative to function aresparams for supplying
            % parameters as name/value pairs.
            %
            % Call:
            %   trainParams = aresparams2(varargin)
            %
            % Input:
            %   varargin      : Name/value pairs for the parameters. For the list of
            %                   the names, see description of function aresparams.
            %
            % Output:
            %   trainParams   : A structure of parameters for further use with
            %                   aresbuild, arescv, and arescvc functions containing the
            %                   provided values (or defaults, if not provided).

            % =========================================================================
            % ARESLab: Adaptive Regression Splines toolbox for Matlab/Octave
            % Author: Gints Jekabsons (gints.jekabsons@rtu.lv)
            % URL: http://www.cs.rtu.lv/jekabsons/
            %
            % Copyright (C) 2009-2016  Gints Jekabsons
            %
            % This program is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or
            % (at your option) any later version.
            %
            % This program is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with this program. If not, see <http://www.gnu.org/licenses/>.
            % =========================================================================

            % Last update: January 9, 2016

            nArgs = length(varargin);
            if round(nArgs / 2) ~= nArgs / 2
                error('varargin should contain name/value pairs.');
            end

            trainParams = aresparams();
            paramNames = fieldnames(trainParams);
            paramNamesLower = lower(paramNames);

            for pair = reshape(varargin, 2, [])
                name = lower(pair{1});
                found = strcmp(name, paramNamesLower);
                if any(found)
                    trainParams.(paramNames{found}) = pair{2};
                else
                    error('%s is not a recognized parameter name.', name);
                end
            end

            return
        end

        function fh = aresplot(app,model, idx, vals, minX, maxX, gridSize, showKnots, varargin)
            % aresplot
            % Plots ARES model. For datasets with one input variable, plots the model
            % together with its knot locations. For datasets with more than one input
            % variable, plots 3D surface. If idx is not provided, checks if the model
            % uses more than one variable and, if not, plots in 2D even if the dataset
            % has more than one input variable.
            % For multi-response modelling, supply one submodel at a time.
            %
            % Call:
            %   fh = aresplot(model, idx, vals, minX, maxX, gridSize, showKnots, varargin)
            %
            % All the input arguments, except the first one, are optional. Empty values
            % are also accepted (the corresponding defaults will be used).
            %
            % Input:
            %   model         : ARES model.
            %   idx           : Only used when the number of input variables is larger
            %                   than two. This is a vector containing two indices for
            %                   the two variables values of which are to be varied in
            %                   the plot (default value = [1 2]).
            %   vals          : Only used when the number of input variables is larger
            %                   than two. This is a vector of fixed values for all the
            %                   input variables (except that the values for the varied
            %                   variables are not used). By default, continuous
            %                   variables are fixed at (minX + maxX) / 2 but binary
            %                   variables (according to model.isBinary) are fixed at
            %                   minX.
            %   minX, maxX    : Minimum and maximum values for each input variable
            %                   (this is the same type of data as in model.minX and
            %                   model.maxX). By default, those values are taken from
            %                   model.minX and model.maxX.
            %   gridSize      : Grid size for the plot. Default value is 400 for 2D
            %                   plots and 50 for 3D plots.
            %   showKnots     : Whether to show knots in the plot (default value is
            %                   true for data with one input variable and false
            %                   otherwise). Showing knot locations in 3D plots is
            %                   experimental feature. In a 3D plot, knots for basis
            %                   functions without interactions are represented as
            %                   planes with white edges while knots for basis functions
            %                   with interactions are represented as 90-degrees "broken
            %                   planes" with black edges. The directions of the broken
            %                   planes depend on directions of hinge functions in the
            %                   corresponding basis functions. Planes for each new knot
            %                   (or pair of knots) are shown using a new color and a
            %                   new vertical offset so that they are easier to
            %                   distinguish. Unfortunately, coincident planes flicker;
            %                   though it helps seeing that there is more than one
            %                   plane.
            %                   Note that, for input variables entering linearly (i.e.,
            %                   without hinge functions), 2D plots don't show any knots
            %                   but 3D plots show knots at minX.
            %   varargin      : Name/value pairs of arguments passed to function plot
            %                   (for 2D plots) and function surfc (for 3D plots). May
            %                   include 'XLim', 'YLim', and 'ZLim' which are separated
            %                   and passed to axes.
            %
            % Input:
            %   fh            : Handle to the created figure.

            % =========================================================================
            % ARESLab: Adaptive Regression Splines toolbox for Matlab/Octave
            % Author: Gints Jekabsons (gints.jekabsons@rtu.lv)
            % URL: http://www.cs.rtu.lv/jekabsons/
            %
            % Copyright (C) 2009-2016  Gints Jekabsons
            %
            % This program is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or
            % (at your option) any later version.
            %
            % This program is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with this program. If not, see <http://www.gnu.org/licenses/>.
            % =========================================================================

            % Last update: May 15, 2016

            if nargin < 1
                error('Not enough input arguments.');
            end
            if length(model) > 1
                error('This function works with single-response models only. You can supply one submodel at a time.');
            else
                if iscell(model)
                    model = model{1};
                end
            end
            if (nargin < 3)
                vals = [];
            end
            if (nargin < 4) || isempty(minX)
                minX = model.minX;
            else
                if length(minX) ~= length(model.minX)
                    error('Vector minX is of wrong size.');
                end
            end
            if (nargin < 5) || isempty(maxX)
                maxX = model.maxX;
            else
                if length(maxX) ~= length(model.maxX)
                    error('Vector maxX is of wrong size.');
                end
            end
            if (nargin < 6) || isempty(gridSize)
                gridSize = [];
            end
            if (nargin < 8) || isempty(varargin)
                varargin = [];
                vararginAxes = [];
            else
                cellfind = @(string)(@(cell_contents)(strcmpi(string,cell_contents)));
                vararginAxes = {};
                for str = {'XLim', 'YLim', 'ZLim'}
                    i = find(cellfun(cellfind(str), varargin));
                    if ~isempty(i)
                        vararginAxes = [vararginAxes varargin(i) varargin(i+1)];
                        varargin(i+1) = [];
                        varargin(i) = [];
                    end
                end
            end

            fh = [];

            if (length(model.minX) > 1) && ((nargin < 2) || isempty(idx))
                nBasis = length(model.knotsites);
                force2D = true;
                if nBasis > 0
                    variable = [];
                    for i = 1 : nBasis
                        variable = union(variable, model.knotdims{i});
                        if numel(variable) > 1
                            force2D = false;
                            break;
                        end
                    end
                else
                    variable = 1;
                end
            else
                force2D = false;
            end

            if (length(model.minX) <= 1) || force2D
                if isempty(gridSize)
                    gridSize = 400;
                end
                if force2D
                    minX = minX(variable);
                    maxX = maxX(variable);
                end
                nBasis = length(model.knotsites);
                if nBasis > 0
                    if model.trainParams.cubic || (model.trainParams.selfInteractions > 1)
                        step = (maxX - minX) / gridSize;
                        X = (minX:step:maxX)';
                    else
                        X = [minX; maxX]; % for piecewise-linear models there are no need in more than this
                    end
                    knotsX = [];
                    % Find all knot locations
                    for i = 1 : nBasis
                        newKnotX = model.knotsites{i};
                        %which = (newKnotX > minX) & (newKnotX < maxX);
                        which = (model.knotdirs{i} ~= 2); % not showing knot for variables entering linearly
                        knotsX = union(knotsX, newKnotX(which));
                    end
                    knotsX = knotsX(:);
                    X = union(X, knotsX); % adding knot locations to X so that the graph shows them correctly and accurately
                else
                    knotsX = [];
                    X = [minX; maxX];
                end
                fh = figure;
                if force2D
                    Xtmp = zeros(size(X,1), length(model.minX));
                    Xtmp(:,variable) = X;
                    Y = arespredict(model, Xtmp);
                else
                    Y = arespredict(model, X);
                end
                if isempty(varargin)
                    plot(X, Y);
                else
                    plot(X, Y, varargin{:});
                end
                grid on;
                if force2D
                    xlabel(['x' num2str(variable)]);
                else
                    xlabel('x');
                end
                ylabel('y');
                if (nargin < 7) || isempty(showKnots)
                    showKnots = true;
                end
                if showKnots && (~isempty(knotsX))
                    % Add all knots to the plot
                    hold on;
                    if force2D
                        Xtmp = zeros(size(knotsX,1), length(model.minX));
                        Xtmp(:,variable) = knotsX;
                        knotsY = arespredict(model, Xtmp);
                    else
                        knotsY = arespredict(model, knotsX);
                    end
                    plot(knotsX, knotsY, 'ko', 'MarkerSize', 8);
                    if ~isempty(vararginAxes)
                        set(gca, vararginAxes{:});
                    end
                    ylim = get(gca, 'ylim');
                    for i = 1 : length(knotsX)
                        plot([knotsX(i) knotsX(i)], [ylim(1) knotsY(i)], 'k--');
                    end
                    hold off;
                else
                    if ~isempty(vararginAxes)
                        set(gca, vararginAxes{:});
                    end
                end
                return;
            end

            if isempty(gridSize)
                gridSize = 50;
            end

            if (nargin < 2) || isempty(idx)
                ind1 = 1;
                ind2 = 2;
            else
                if numel(idx) ~= 2
                    error('Vector idx should have exactly two elements.');
                end
                ind1 = idx(1);
                ind2 = idx(2);
            end

            if isempty(vals)
                vals = (minX + maxX) ./ 2;
                vals(model.isBinary) = minX(model.isBinary);
            else
                if length(minX) ~= length(vals)
                    error('Vector vals is of wrong size.');
                end
            end

            % Creating grid
            step1 = (maxX(ind1) - minX(ind1)) / gridSize;
            step2 = (maxX(ind2) - minX(ind2)) / gridSize;
            [X1, X2] = meshgrid(minX(ind1):step1:maxX(ind1), minX(ind2):step2:maxX(ind2));
            XX1 = reshape(X1, numel(X1), 1);
            XX2 = reshape(X2, numel(X2), 1);

            % Fill other columns with the fixed values
            X = zeros(size(XX1,1), length(minX));
            X(:,ind1) = XX1;
            X(:,ind2) = XX2;
            for i = 1 : length(minX)
                if (i ~= ind1) && (i ~= ind2)
                    X(:,i) = vals(i);
                end
            end

            % Calculate Y and plot the surface
            YY = arespredict(model, X);
            Y = reshape(YY, size(X1,1), size(X2,2));
            fh = figure;

            if ~isempty(vararginAxes)
                % A workaround to always force the contour plot to be at ZLim
                zlimidx = find(strcmpi('ZLim', vararginAxes));
                if ~isempty(zlimidx)
                    zlim = vararginAxes{zlimidx + 1};
                    hp = plot3(X1(1), X2(1), zlim(1), '.');
                    grid on;
                    hold on;
                end
            end
            % Plotting surface
            if isempty(varargin)
                surfc(X1, X2, Y);
            else
                surfc(X1, X2, Y, varargin{:});
            end
            % Setting properties for axes
            if ~isempty(vararginAxes)
                set(gca, vararginAxes{:});
                if ~isempty(zlimidx)
                    hold off;
                    set(hp, 'Visible', 'off'); % hiding the workaround
                end
            end

            xlabel(['x' num2str(ind1)]);
            ylabel(['x' num2str(ind2)]);
            zlabel('y');

            % Visualization of knot locations for 3D plots
            if (nargin < 7) || isempty(showKnots)
                showKnots = false;
            end
            if showKnots
                if model.trainParams.selfInteractions > 1
                    disp('Cannot show knots when selfInteractions > 1.');
                    return;
                end
                nBasis = length(model.knotsites);
                if nBasis <= 0
                    return;
                end
                % First, let's count involved basis functions and check whether they
                % involve any basis functions other than those selected
                countUnique = 0;
                knotdimsOLD = [];
                knotsitesOLD = [];
                for i = 1 : nBasis
                    knotdims = model.knotdims{i};
                    %if (numel(knotdims) == 1) && (model.knotdirs{i} == 2)
                    %    continue; % not showing knots for single variables entering linearly
                    %end
                    which = (knotdims == ind1) | (knotdims == ind2);
                    if any(which) % whether the knots involve one or both of the selected variables
                        if any(~which) % whether the knots involve any other variable besides those selected
                            disp('Cannot show knots because one or more of the basis functions that involve at least');
                            disp('one SELECTED input variable also involve at least one OTHER input variable.');
                            disp('You can try deleting those basis functions before plotting.');
                            return;
                        end
                        knotdims = model.knotdims{i};
                        knotsites = model.knotsites{i};
                        if (numel(knotsites) ~= numel(knotsitesOLD)) || (~all(knotsites == knotsitesOLD)) || ~all(knotdims == knotdimsOLD)
                            % incrementing only if the new knots are not identical to
                            % the previous ones (because hinges may come in pairs)
                            countUnique = countUnique + 1;
                        end
                        knotdimsOLD = knotdims;
                        knotsitesOLD = knotsites;
                    end
                end
                if countUnique <= 0
                    return;
                end
                hold on;
                xlim = get(gca, 'xlim');
                ylim = get(gca, 'ylim');
                zlim = get(gca, 'zlim');
                hue = (0:(0.9/(countUnique-1)):0.9)'; % a different color for each new plane
                rgb = hsv2rgb([hue ones(size(hue)) ones(size(hue)) * 0.8]);
                countUnique = 0;
                knotdimsOLD = [];
                knotsitesOLD = [];
                for i = 1 : nBasis
                    knotdims = model.knotdims{i};
                    %if (numel(knotdims) == 1) && (model.knotdirs{i} == 2)
                    %    continue; % not showing knots for single variables entering linearly
                    %end
                    which = (knotdims == ind1) | (knotdims == ind2);
                    if any(which) % whether the knots involve one or both of the selected variables
                        knotsites = model.knotsites{i};
                        if (numel(knotsites) ~= numel(knotsitesOLD)) || (~all(knotsites == knotsitesOLD)) || ~all(knotdims == knotdimsOLD)
                            % incrementing only if the new knots are not identical to
                            % the previous ones (because hinges may come in pairs)
                            countUnique = countUnique + 1;
                        end
                        knotdimsOLD = knotdims;
                        knotsitesOLD = knotsites;
                        if numel(knotdims) == 1
                            if model.knotdirs{i} == 2 % variable is entering linearly
                                knotsites = minX(model.knotdims{i}); % so that the plane is always on the edge
                            end
                            % "unbroken" plane
                            if knotdims == ind1
                                x = [knotsites knotsites];
                                y = repmat([ylim(1) ylim(2)], 2, 1);
                                z = repmat([zlim(1); zlim(2)], 1, 2) + (zlim(2) - zlim(1)) * 0.012 * countUnique;
                                surf(x, y, z, 'FaceAlpha', 0.5, 'FaceColor', rgb(countUnique,:), 'LineWidth', 2, 'EdgeColor', 'w');
                            else
                                x = repmat([xlim(1) xlim(2)], 2, 1);
                                y = [knotsites knotsites];
                                z = repmat([zlim(1); zlim(2)], 1, 2) + (zlim(2) - zlim(1)) * 0.012 * countUnique;
                                surf(x, y, z, 'FaceAlpha', 0.5, 'FaceColor', rgb(countUnique,:), 'LineWidth', 2, 'EdgeColor', 'w');
                            end
                        else
                            % "broken" plane
                            knotdirs = model.knotdirs{i};
                            idx1 = find(knotdims == ind1, 1);
                            idx2 = find(knotdims == ind2, 1);

                            if model.knotdirs{idx1} == 2 % variable is entering linearly
                                knotsites(idx1) = minX(model.knotdims{idx1}); % so that the plane is always on the edge
                            end
                            if model.knotdirs{idx2} == 2 % variable is entering linearly
                                knotsites(idx2) = minX(model.knotdims{idx2}); % so that the plane is always on the edge
                            end

                            x = [knotsites(idx1) knotsites(idx1)];
                            if knotdirs(idx2) < 0
                                y = repmat([ylim(1) knotsites(idx2)], 2, 1);
                            else
                                y = repmat([knotsites(idx2) ylim(2)], 2, 1);
                            end
                            z = repmat([zlim(1); zlim(2)], 1, 2) + (zlim(2) - zlim(1)) * 0.012 * countUnique;
                            surf(x, y, z, 'FaceAlpha', 0.5, 'FaceColor', rgb(countUnique,:), 'LineWidth', 2, 'EdgeColor', 'k');

                            if knotdirs(idx1) < 0
                                x = repmat([xlim(1) knotsites(idx1)], 2, 1);
                            else
                                x = repmat([knotsites(idx1) xlim(2)], 2, 1);
                            end
                            y = [knotsites(idx2) knotsites(idx2)];
                            z = repmat([zlim(1); zlim(2)], 1, 2) + (zlim(2) - zlim(1)) * 0.012 * countUnique;
                            surf(x, y, z, 'FaceAlpha', 0.5, 'FaceColor', rgb(countUnique,:), 'LineWidth', 2, 'EdgeColor', 'k');
                        end
                    end
                end
            end
            return
        end

        function [Yq, BX] = arespredict(app,model, Xq)
            % arespredict
            % Predicts response values for the given query points Xq using ARES model.
            %
            % Call:
            %   [Yq, BX] = arespredict(model, Xq)
            %
            % Input:
            %   model         : ARES model or, for multi-response modelling, a cell
            %                   array of ARES models.
            %   Xq            : A matrix of query data points.
            %
            % Output:
            %   Yq            : Either a column vector of predicted response values or,
            %                   for multi-response modelling, a matrix with columns
            %                   corresponding to response variables.
            %   BX            : Basis matrix. Contains values of basis functions
            %                   applied to Xq.

            % =========================================================================
            % ARESLab: Adaptive Regression Splines toolbox for Matlab/Octave
            % Author: Gints Jekabsons (gints.jekabsons@rtu.lv)
            % URL: http://www.cs.rtu.lv/jekabsons/
            %
            % Copyright (C) 2009-2016  Gints Jekabsons
            %
            % This program is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or
            % (at your option) any later version.
            %
            % This program is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with this program. If not, see <http://www.gnu.org/licenses/>.
            % =========================================================================

            % Last update: May 15, 2016

            if nargin < 2
                error('Not enough input arguments.');
            end
            if iscell(Xq)
                error('Xq should not be cell array.');
            end
            if ~isfloat(Xq)
                error('Xq data type should be double.');
            end

            numModels = length(model);
            if (numModels > 1)
                models = model;
                model = models{1};
            else
                if iscell(model)
                    model = model{1};
                end
            end
            if length(model.minX) ~= size(Xq,2)
                error('The number of columns in Xq is different from the number when the model was built.');
            end

            BX = ones(size(Xq,1),length(model.knotdims)+1);
            if model.trainParams.cubic
                for i = 1 : length(model.knotdims)
                    BX(:,i+1) = createbasisfunction(Xq, BX, model.knotdims{i}, model.knotsites{i}, ...
                        model.knotdirs{i}, model.parents(i), model.minX, model.maxX, model.t1(i,:), model.t2(i,:));
                end
            else
                for i = 1 : length(model.knotdims)
                    BX(:,i+1) = createbasisfunction(Xq, BX, model.knotdims{i}, model.knotsites{i}, ...
                        model.knotdirs{i}, model.parents(i), model.minX, model.maxX);
                end
            end

            if numModels == 1
                Yq = BX * model.coefs;
            else
                Yq = zeros(size(Xq, 1), numModels);
                for k = 1 : numModels
                    Yq(:,k) = BX * models{k}.coefs;
                end
            end
            return
        end

        function results = arestest(app,model, Xtst, Ytst, weights)
            % arestest
            % Tests ARES model on a test data set (Xtst, Ytst).
            %
            % Call:
            %   results = arestest(model, Xtst, Ytst, weights)
            %
            % Input:
            %   model         : ARES model or, for multi-response modelling, a cell
            %                   array of ARES models.
            %   Xtst, Ytst    : Xtst is a matrix with rows corresponding to testing
            %                   observations, and columns corresponding to input
            %                   variables. Ytst is either a column vector of response
            %                   values or, for multi-response data, a matrix with
            %                   columns corresponding to response variables.
            %   weights       : Optional. A vector of weights for observations. See
            %                   description of function aresbuild.
            %
            % Output:
            %   results       : A structure of different error measures calculated on
            %                   the test data set. For multi-response data, all error
            %                   measures are given for each model separately in a row
            %                   vector. The structure has the following fields:
            %     MAE         : Mean Absolute Error.
            %     MSE         : Mean Squared Error.
            %     RMSE        : Root Mean Squared Error.
            %     RRMSE       : Relative Root Mean Squared Error.
            %     R2          : Coefficient of Determination.

            % =========================================================================
            % ARESLab: Adaptive Regression Splines toolbox for Matlab/Octave
            % Author: Gints Jekabsons (gints.jekabsons@rtu.lv)
            % URL: http://www.cs.rtu.lv/jekabsons/
            %
            % Copyright (C) 2009-2016  Gints Jekabsons
            %
            % This program is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or
            % (at your option) any later version.
            %
            % This program is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with this program. If not, see <http://www.gnu.org/licenses/>.
            % =========================================================================

            % Last update: May 15, 2016

            if nargin < 3
                error('Not enough input arguments.');
            end

            if isempty(Xtst) || isempty(Ytst)
                error('Data is empty.');
            end
            if iscell(Xtst) || iscell(Ytst)
                error('Xtst and Ytst should not be cell arrays.');
            end
            if islogical(Ytst)
                Ytst = double(Ytst);
            elseif ~isfloat(Ytst)
                error('Ytst data type should be double or logical.');
            end
            if ~isfloat(Xtst)
                error('Xtst data type should be double.');
            end
            if any(any(isnan(Xtst)))
                error('ARESLab cannot handle missing values (NaN).');
            end

            [n, dy] = size(Ytst); % number of observations and number of output variables
            if (size(Xtst, 1) ~= n)
                error('The number of rows in Xtst and Ytst should be equal.');
            end
            if (nargin < 4)
                weights = [];
            else
                if (~isempty(weights)) && ...
                        ((size(weights,1) ~= n) || (size(weights,2) ~= 1))
                    error('weights vector is of wrong size.');
                end
            end

            numModels = length(model);
            if dy ~= numModels
                error('The number of columns in Ytst should match the number of models.');
            end
            if ((numModels == 1) && (length(model.minX) ~= size(Xtst,2))) || ...
                    ((numModels > 1) && (length(model{1}.minX) ~= size(Xtst,2)))
                error('The number of columns in Xtst is different from the number when the model was built.');
            end

            if numModels == 1
                residuals = arespredict(model, Xtst) - Ytst;
                if isempty(weights)
                    results.MAE = mean(abs(residuals));
                    results.MSE = mean(residuals .^ 2);
                else
                    results.MAE = sum(abs(residuals) .* weights) / sum(weights);
                    results.MSE = sum(residuals .^ 2 .* weights) / sum(weights);
                end
                results.RMSE = sqrt(results.MSE);
                if n > 1
                    variance = var(Ytst, 1);
                    results.RRMSE = results.RMSE / sqrt(variance);
                    results.R2 = 1 - results.MSE / variance;
                else
                    results.RRMSE = Inf;
                    results.R2 = -Inf;
                end
            else
                results.MAE = Inf(1,dy);
                results.MSE = Inf(1,dy);
                results.RMSE = Inf(1,dy);
                results.RRMSE = Inf(1,dy);
                results.R2 = -Inf(1,dy);
                Yq = arespredict(model, Xtst);
                for i = 1 : dy
                    residuals = Yq(:,i) - Ytst(:,i);
                    if isempty(weights)
                        results.MAE(i) = mean(abs(residuals));
                        results.MSE(i) = mean(residuals .^ 2);
                    else
                        results.MAE(i) = sum(abs(residuals) .* weights) / sum(weights);
                        results.MSE(i) = sum(residuals .^ 2 .* weights) / sum(weights);
                    end
                    results.RMSE(i) = sqrt(results.MSE(i));
                    if n > 1
                        variance = var(Ytst(:,i), 1);
                        results.RRMSE(i) = results.RMSE(i) / sqrt(variance);
                        results.R2(i) = 1 - results.MSE(i) / variance;
                    end
                end
            end

            return
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TaucPlotGUI_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end