classdef UniversalTaucPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TaucPlotFittingUIFigure      matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        FOMDCodesLabel               matlab.ui.control.Label
        DACode                       matlab.ui.control.Label
        BandGapValuesLabel           matlab.ui.control.Label
        IACode                       matlab.ui.control.Label
        IABandGapLabel               matlab.ui.control.Label
        DABandGapLabel               matlab.ui.control.Label
        LoadReflectanceDataButton    matlab.ui.control.Button
        StartFitButton               matlab.ui.control.Button
        LoadCreateError              matlab.ui.control.Label
        LoadAbsDataButton            matlab.ui.control.Button
        CenterPanel                  matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        UIAxes_1                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        BaselineSegmentSNGandSEGOnlyLabel  matlab.ui.control.Label
        GridLayout8                  matlab.ui.container.GridLayout
        BaselineIndexEditField       matlab.ui.control.NumericEditField
        BaselineIndexEditFieldLabel  matlab.ui.control.Label
        GridLayout7                  matlab.ui.container.GridLayout
        SegmentationCheckBox         matlab.ui.control.CheckBox
        MARSCheckBox                 matlab.ui.control.CheckBox
        SNGCheckBox                  matlab.ui.control.CheckBox
        ExportDataButton             matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        GoodnessofFitFOMDCodesSNGOnlyLabel  matlab.ui.control.Label
        GridLayout5                  matlab.ui.container.GridLayout
        UpperFitLimitEditField       matlab.ui.control.NumericEditField
        UpperFitLimitEditFieldLabel  matlab.ui.control.Label
        LowerFitLimitEditField       matlab.ui.control.NumericEditField
        LowerFitLimitEditFieldLabel  matlab.ui.control.Label
        AbsorptionCutoffLimitsLabel  matlab.ui.control.Label
        FittingMethodLabel           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        FilePath % Holds file path
        Wavelength % Holds x data from imported csv (wavelength)
        Absorption % Holds y data from imported csv (absorption intensities)
        Shade % Holds rectangle graphic objects that are shaded
        ShadeArea % Holds x1 and x2 positions of both rectangles
        XMin % Holds minimum x data value
        XMax % Holds maximum x data value
        YMin % Holds minimum y data value
        YMax % Holds maximum y data value
        UpperFit % Holds upper fit line x value
        LowerFit % Holds lower fit line x value
        ROILine % Holds graphical (ROI) line objects
        left_line_index % Holds the left index value of the point ROI line sits on
        right_line_index % Holds the right index value of the point ROI line sits on
        OriginalAbs % Holds the original absorption data
        absp % Holds line object of original abs data
        smoothed % Holds line object of smoothed abs data
        DAbgs = [] % Holds all DA band gap values for each method
        IAbgs = [] % Holds all DA band gap values for each method

        SNGdata = [] % Holds fitdata for SNG method
        SNGregion = [] % Holds Tauc region and baseline for SNG method

        MARSdata = [] % Holds fitdata for MARS method
        MARSregion = [] % Holds Tauc region and baseline for MARS method

        SEGdata = []
        SEGregion = [] % Holds Tauc region and baseline for Segmentation method

        IAData % A handle to determine whether or not the Tauc data should be drawn each method (should only be once)
        DAData % A handle to determine whether or not the Tauc data should be drawn each method (should only be once)
        FitsAndBases % A handle to hold all useless hidden gobjects so we can delete them
        Bases % A handle to hold the baseline index values

    end

    methods (Access = private)

        function clearProperties(app)
            % Delete visible handles
            delete(app.UIAxes_2.Children);
            delete(app.UIAxes_3.Children);

            % Delete invisible handles
            delete(app.FitsAndBases);
            delete(app.IAData);
            delete(app.DAData);

            app.SNGdata = [];
            app.SNGregion = [];
            app.MARSdata = [];
            app.MARSregion = [];
            app.SEGdata = [];
            app.SEGregion = [];

            app.DAbgs = [];
            app.IAbgs = [];

            app.IAData = gobjects(1);
            app.DAData = gobjects(1);

            app.DABandGapLabel.Text = "DA Bandgap Energy (eV): ";
            app.IABandGapLabel.Text = "IA Bandgap Energy (eV): ";
            app.DACode.Text = "DA Code(s): ";
            app.IACode.Text = "IA Code(s): ";

            % app.LoadCreateError.Text = "";
        end

        function [linfitd,fomd,data] = runuvvis(app,data,inputvars)

            % Create empty maps to populate
            pfomd = containers.Map;
            plinfitd = containers.Map;

            % This cycles through analysis types (DA & IA) and creates maps for each populated by fitresult
            for bgtyp = inputvars('analysis_types')

                % Create nested maps for each type
                pfomd(bgtyp{1,1})=containers.Map;
                plinfitd(bgtyp{1,1})=containers.Map;

                % Check for nan/missing values
                if any(ismissing(data(bgtyp{1,1})),2)
                    code = pfomd(bgtyp{1,1});
                    code('bgcode_0') = 8; %#ok<NASGU> 
                    continue;
                end

                % Implementation of second round of filtering in cases where peaks exist is currently unsupported
                if inputvars('use_absderivs_forpeaks')
                    if identifypeaks(app,data,'abs',inputvars('abs_minallowedslope'),inputvars('max_absolute_2ndderiv'))
                        code = pfomd(bgtyp);
                        code('bgcode_0') = 7; %#ok<NASGU> 
                        continue;
                    end
                end

                try
                    [plinfitd(bgtyp{1,1}),pfomd(bgtyp{1,1})] = fitresult(app,data,bgtyp,inputvars('maxbgspersmp'),...
                        inputvars('num_knots'),inputvars('tol'),inputvars('min_allowedslope'),...
                        inputvars('min_bgTP_diff'),inputvars('min_bkgrdslope'),...
                        inputvars('min_bgbkgrdslopediff'),inputvars('min_finseglength'),...
                        inputvars('min_bgTP_finseg_diff'),...
                        inputvars('min_bgfinalseglength'),inputvars('max_merge_differentialTP'),...
                        inputvars('merge_linsegslopediff_percent'),inputvars('maxtol'),...
                        inputvars('min_knotdist'),'increasing',"off");
                catch e
                    disp(e);
                    return
                end
            end

            fomd = containers.Map;
            linfitd = containers.Map;

            % Populate maps for each analysis type and fomd/linfitd
            for bgtyp = keys(pfomd)
                for k = keys(pfomd(bgtyp{1,1}))
                    val = pfomd(bgtyp{1,1});
                    fomd([bgtyp{1,1},'_',k{1,1}]) = val(k{1,1});
                end
            end

            for bgtyp = keys(plinfitd)
                for k = keys(plinfitd(bgtyp{1,1}))
                    val = plinfitd(bgtyp{1,1});
                    linfitd([bgtyp{1,1},'_',k{1,1}]) = val(k{1,1});
                end
            end

            for k = keys(linfitd)
                cells = split(k,'_');
                strs = string(cells);

                if strcmp(strs(end),'knots') || strcmp(strs(end), 'slopes')
                    for idx = 1:length(linfitd(k{1,1}))
                        val = linfitd(k{1,1});
                        linfitd([k{1,1},'_',num2str(idx-1)]) = val(idx);
                    end
                end
            end

            % Remove nonscalar value entries
            for k = keys(linfitd)
                if ~isscalar(linfitd(k{1,1}))
                    remove(linfitd, k{1,1});
                end
            end

        end

        function [linfitd, fomd] = fitresult(app, data, bgtyp, max_numbgs, num_knots, tol, min_allowedslope, min_bgTP_diff, min_bkgrdslope, ...
                min_bgbkgrdslopediff, min_finseglength, min_bgTP_finseg_diff, min_bgfinalseglength, max_merge_differentialTP, merge_linsegslopediff_percent, ...
                maxtol, min_knotdist, xorder, dispresult)

            % This function creates data for each bg type and interacts with fitting, residual measurement functions to identify the best linear piecewise parameters which are then sent to calc_bandgap for bandgap calculations.
            datarray = data(bgtyp{1,1});
            energy = data('hv');

            if min(datarray) < 0.03
                yoffset = -min(datarray) + 0.03;
            else
                yoffset = 0;
            end

            new_offset = datarray + yoffset;

            % Typically, x is a local solution to the problem when exitflag is positive
            for maxiter = [1000,30000]
                [x,~,~,exitflag,~,~,~] = linearfit(app, energy, new_offset, num_knots, min_knotdist, xorder, containers.Map({'maxiter', 'disp'}, [maxiter,dispresult]), tol);
                if exitflag > 0
                    break
                end
            end

            if exitflag <= 0
                maxiter = 2000;
                for i = log10(tol):log10(maxtol)+1
                    newtol = 10^i;
                    [x,~,~,exitflag,~,~,~] = linearfit(app, energy, new_offset, num_knots, min_knotdist, xorder, containers.Map({'maxiter', 'disp'}, [maxiter,dispresult]), newtol);
                    if exitflag > 0
                        break
                    end
                end
            end

            if exitflag <= 0
                linfitd = containers.Map;
                fomd = containers.Map('bgcode_0',9);
            else
                % Set temporary parameters to solution vector x from linearfit function (lsqcurvefit)
                tempparams = x;
                tempparams(1) = tempparams(1) - yoffset;
                tempparams = mergelinsegs(app, tempparams, num_knots, max_merge_differentialTP, merge_linsegslopediff_percent);

                try
                    [linfitd,fomd] = calc_bandgap(app, tempparams, numel(tempparams)/2, max_numbgs, min_allowedslope, min_bgTP_diff, min_bkgrdslope, min_bgbkgrdslopediff, min_finseglength, min_bgTP_finseg_diff, min_bgfinalseglength, bgtyp, energy);
                catch e
                    disp(e);
                    return
                end

                data(string([bgtyp{1,1},'_linfit'])) = linpiecewise(app,[linfitd('y0'); linfitd('knots'); linfitd('slopes')], energy); %#ok<NASGU> 
            end
        end

        function [x,resnorm,residual,exitflag,output,lambda, jacobian] = linearfit(app, xdata, ydata, num_knots, ~, xorder, options, tol)
            % xorder: if loc in data represents smaller value of energy to higher value of energy then xorder is increasing otherwise decreasing
            % first constraint in cons is to ensure minimum knot distance min_knotdist. Second equality constraint indicates that the first and the last knot are fixed to the first and last values of x
            % Initially knot locations are evenly spread out between xvalues of interest and all slopes are set to zero. tolerance is the tolerance for convergence of linear properties.
            if xorder == "increasing"
                sign = 1; %#ok<NASGU> 
            else
                sign = -1; %#ok<NASGU> 
            end

            init_x = xdata(1):(xdata(end)-xdata(1))/(num_knots-1):xdata(end);
            init_x = init_x';

            if numel(init_x)~=num_knots
                init_x = [init_x;xdata(end)];
            else
                init_x(end)=xdata(end);
            end

            init_idxs = ones(size(init_x)); % Preallocate for speed
            for i = 1:numel(init_x)
                [~,init_idxs(i,:)] = min(abs(xdata-init_x(i)));
            end

            init_slopes = ones([size(init_idxs,1)-1 1]); % Preallocate for speed
            for i = 1:length(init_idxs)-1
                init_slopes(i,:) = (ydata(init_idxs(i+1))-ydata(init_idxs(i)))/(xdata(init_idxs(i+1))-xdata(init_idxs(i)));
            end

            init_params = [ydata(1); init_x; init_slopes];

            opts = optimoptions("lsqcurvefit","Algorithm","levenberg-marquardt", "FunctionTolerance",tol,"MaxIterations",str2double(options('maxiter')),"Display",options('disp'),"StepTolerance",tol);
            [x,resnorm,residual,exitflag,output,lambda,jacobian] = lsqcurvefit(@(params,x)linpiecewise(app,params,x), init_params, xdata, ydata, [], [], opts);
        end

        function params = mergelinsegs(~, params, num_knots, max_merge_differentialTP, merge_linsegslopediff_percent)

            knots = params(2:num_knots+1);
            slopes = params(num_knots+2:(2*num_knots));

            % Turn merge_linsegslopediff_percent into fraction
            if merge_linsegslopediff_percent >= 1
                merge_linsegslopediff_percent = merge_linsegslopediff_percent/100;
            end

            while true
                num_merges = 0;
                j = 1;
                loc = 1;

                newslopes = zeros(size(slopes));
                newknots = zeros(size(knots));

                newslopes(1) = slopes(1);
                newknots(1:2) = knots(1:2);

                while loc < size(slopes,1)
                    medianslope = (newslopes(j)*(newknots(j+1) - newknots(j)) + slopes(loc+1)*(knots(loc+2) - knots(loc+1)))/(knots(loc+2)-newknots(j));
                    differentialTPdiff = (medianslope - newslopes(j))*(newknots(j+1)-newknots(j));
                    TPdiff = newslopes(j)*(newknots(j+1)-newknots(j));
                    TPdiffn = slopes(loc+1)*(knots(loc+2)-knots(loc+1));
                    if abs(differentialTPdiff) < min(max_merge_differentialTP,merge_linsegslopediff_percent*(TPdiff+TPdiffn))
                        newslopes(j) = medianslope;
                        slopes(loc) = medianslope;
                        slopes(loc+1) = medianslope;
                        newknots(j+1) = knots(loc+2);
                        loc = loc+1;
                        num_merges = num_merges + 1;
                    else
                        j = j+1;
                        loc = loc+1;
                        newslopes(j) = slopes(loc);
                        newknots(j) = knots(loc);
                        newknots(j+1) = knots(loc+1);
                    end
                end

                newslopes(j+1:end) = [];
                newknots(j+2:end) = [];
                slopes = newslopes;
                knots = newknots;

                if num_merges == 0
                    break
                end
            end

            num_knots = j+2; %#ok<NASGU> 
            params = [params(1,1);knots;slopes];
        end

        function [linfitd,fomd] = calc_bandgap(app,params,num_knots,max_numbgs,min_allowedslope,min_bgTP_diff,min_bkgrdslope,min_bgbkgrdslopediff,min_finseglength,min_bgTP_finseg_diff,min_bgfinalseglength,bgtyp,data)
            % Function for SNG only
            % Calculate min and max of energy for limits
            xmin = min(data);
            xmax = max(data);

            % Use bg_type to determine axes
            if bgtyp == "DA"
                ax = app.UIAxes_2;
                name = "SNG_{DA}";
            elseif bgtyp == "IA"
                ax = app.UIAxes_3;
                name = "SNG_{IA}";
            end

            knots = params(2:num_knots+1);
            slopes = params(num_knots+2:2*num_knots);
            num_slopes = size(slopes,1);

            bgknots_lower = [];
            bkgrdknots_lower = [];
            bg = [];
            abs_expl = [];
            bgcode = [];

            if num_slopes == 1
                uialert(app.TaucPlotFittingUIFigure,"Only one slope was found. Please adjust fit limits and try again", "One Slope Only","Icon","error");
                return
            end

            if (knots(num_slopes) - knots(num_slopes-1)) >= min_finseglength
                num_segments = num_slopes;
            else
                num_segments = num_slopes - 1;
            end

            tot_segs = num_segments;

            for i = 1:tot_segs - 1
                if slopes(i) < min_allowedslope
                    num_segments = i;
                    break
                end
            end

            if num_segments == tot_segs
                for i = 2:num_segments - 1
                    if slopes(i) > slopes(i-1) && slopes(i) > 0 && slopes(i-1) > min_bkgrdslope
                        TPdiff = (knots(i+1) - knots(i))*slopes(i);

                        if TPdiff >= min_bgTP_diff
                            if slopes(i) > slopes(i+1)
                                bgknots_lower(end+1) = i;
                                abs_expl(end + 1) = TPdiff;
                                bgcode(end+1) = 0;
                            end
                        end
                    end
                end

                if numel(bgknots_lower) == 0
                    j = num_segments;

                    if slopes(j) > slopes(j-1) && slopes(j) > 0 && slopes(j-1) > min_bkgrdslope
                        TPdiff = (knots(j+1) - knots(j))*slopes(j);
                        if TPdiff >= min_bgTP_finseg_diff && knots(j+1) - knots(j) > min_bgfinalseglength
                            bgknots_lower(end+1) = j;
                            abs_expl(end + 1) = TPdiff;
                            bgcode(end+1) = 6;
                        end
                    end
                end

                if numel(bgknots_lower) ~= 0
                    for i=1:size(bgknots_lower,1)
                        if i==1
                            low_limit = -1;
                        else
                            low_limit = bgknots_lower(i-1);
                        end

                        if bgknots_lower(i) - 1 == low_limit
                            delete(bgknots_lower(i:end));
                            delete(abs_expl(i:end));
                            delete(bgcode(i:end));
                            break;
                        end

                        % A band gap segments bkgrnd segment cannot be previous band gap segment
                        for loc = bgknots_lower(i)-1:-1:low_limit+2 %IS THIS ALWAYS CORRECT?? Might have to implement when low_limit > 0 or < 0
                            if slopes(loc) < slopes(loc+1) && slopes(loc) > min_bkgrdslope
                                if loc == low_limit + 2 %SHOULD THIS BE + 1 OR + 2
                                    bkgrdknots_lower(end+1) = loc;
                                    % This block gets executed when the bkgrd segment is just to the right of the previous band gap segment
                                    break
                                else
                                    continue
                                end
                            elseif loc ~= bgknots_lower(i) - 1
                                if app.BaselineIndexEditField.Visible == 0
                                    % CHANGE BASELINE SEGMENT HERE
                                    bkgrdknots_lower(end+1) = loc -1; %+ 1; % This determines lin segment of baseline
                                    app.BaselineIndexEditField.Value = bkgrdknots_lower(end);
                                    app.BaselineIndexEditField.Tooltip = ("Min Index: " + num2str(loc-1) + ", Max Index: " + num2str(loc+1));
                                    app.Bases = [loc-1,loc+1];
                                else
                                    bkgrdknots_lower(end+1) = app.BaselineIndexEditField.Value;
                                end
                                break
                            else
                                temp = size(bgknots_lower,1);
                                delete(bgknots_lower(i:end));
                                delete(abs_expl(i:end));
                                delete(bgcode(i:end));

                                i = temp; %#ok<FXSET> 

                                %This block should never get executed because any bgknot will have corresponding bkgrdknot except
                                %when the previous segment is also a band gap segment taken care of bgknots_lower[i]-1==low_limit block above
                            end
                        end
                    end
                end

                if numel(bgknots_lower) ~= 0
                    if ~(numel(bgcode) == 1 && bgcode(1) == 1)
                        if ~(((numel(abs_expl) == numel(bgknots_lower) && (numel(bgknots_lower) == numel(bkgrdknots_lower))) && numel(bgcode(bgcode ~= 1)) == numel(bkgrdknots_lower)))
                            error('abs_expl,bgknots_lower,bgcodes and bkgrdknots_lower do not have the same size');
                        end
                    end

                    for i = 1:size(bkgrdknots_lower,1)
                        if (slopes(bgknots_lower(i)) - slopes(bkgrdknots_lower(i))) < min_bgbkgrdslopediff

                            bgknots_lower(i) = -1000;
                            bkgrdknots_lower(i) = -1000;
                            abs_expl(i) = -1000;

                            bgcode(end+1) = 4;
                        end
                    end

                    % Now erase marked values from variables if there are any
                    bgknots_lower = bgknots_lower(bgknots_lower ~= -1000);
                    bkgrdknots_lower = bkgrdknots_lower(bkgrdknots_lower ~= -1000);
                    abs_expl = abs_expl(abs_expl ~= -1000);

                    if numel(bgknots_lower) == 0
                        bgknots_lower = NaN;
                        bkgrdknots_lower = NaN;
                        bg = NaN;
                        abs_expl = NaN;

                        bgcode(end+1) = 5; %#ok<NASGU> 
                    else
                        % Main block of code for calculating Band Gap Energy
                        sz = size(bgknots_lower,1);
                        bg = ones(sz,1);

                        for i = 1:sz
                            y1 = linpiecewise(app,params,knots(bgknots_lower,i));
                            y2 = linpiecewise(app,params,knots(bkgrdknots_lower,i));

                            m1 = slopes(bgknots_lower(i));
                            m2 = slopes(bkgrdknots_lower(i));

                            x1 = knots(bgknots_lower(i));
                            x2 = knots(bkgrdknots_lower(i));

                            % Create line segments from point and slope
                            tauc_seg = @(x) m1*(x-x1) + y1; 
                            baseline = @(x) m2*(x-x2) + y2; 

                            % Calculate array values for lines
                            taucY = tauc_seg(app.SNGdata(:,1));
                            baseY = baseline(app.SNGdata(:,1));

                            % Plot linear segments
                            line(ax,app.SNGdata(:,1),taucY,'Color','r','DisplayName',name);
                            app.FitsAndBases(end+1,1) = line(ax,app.SNGdata(:,1),baseY,'Color','r','LineStyle','-.','HandleVisibility','off');

                            % Assign segments to property for exportation
                            app.SNGregion = [app.SNGregion, taucY, baseY];

                            % Reset plot to look nice
                            ax.XLim = [xmin, xmax];
                            ax.YLim = [0, 1];

                            bg(i) = (y1-y2-(m1*x1-m2*x2))/(m2-m1);
                        end
                    end
                else
                    bgknots_lower = NaN;
                    bkgrdknots_lower = NaN;
                    bg = NaN;
                    abs_expl = NaN;
                    bgcode(end+1) = 3; %#ok<NASGU> 
                end

            elseif num_segments ~= tot_segs
                if numel(bgcode) == 0
                    bgknots_lower = NaN;
                    bkgrdknots_lower = NaN;
                    bg = NaN;
                    abs_expl = NaN;
                    bgcode(end+1) = 1; %#ok<NASGU> 
                end
            end

            fomd = containers.Map('KeyType','char','ValueType','any');
            for lstk = {'bgknots_lower','bkgrdknots_lower', 'bg','abs_expl','bgcode'}
                for idx = 1:round(min(length(bg),max_numbgs))
                    keys = string([lstk{1,1},'_',num2str(idx-1)]);
                    vals = eval(lstk{1,1});
                    fomd(keys) = vals;
                end
            end

            linfitd = containers.Map('KeyType','char','ValueType','any');
            for lstk = {'bgknots_lower','bkgrdknots_lower'}
                for idx = 1:min(length(bg),max_numbgs)
                    keys = string([lstk{1,1},'_',num2str(idx-1)]);
                    vals = eval(lstk{1,1});
                    linfitd(keys) = vals;
                end
            end

            % Add on new key-value pairs after loop
            linfitd('knots') = knots;
            linfitd('slopes') = slopes;
            linfitd('y0') = params(1);


            if ~all(isnan(abs_expl))
                for idx = 1:min(length(bg),max_numbgs)
                    if ~isnan(fomd(string(['abs_expl','_',num2str(idx-1)])))
                        [~,x] = max(fomd(string(['abs_expl','_',num2str(idx-1)]))); %MARK
                    end
                end
                %MARK
                fomd('bg_repr') = fomd(['bg_',num2str(x-1)]);
                fomd('bgslope_repr') = slopes(bgknots_lower(x));
                fomd('bkgrdslope_repr') = slopes(bkgrdknots_lower(x));
                fomd('bgcode_repr') = fomd(['bgcode_',num2str(x-1)]);
            else
                fomd('bg_repr') = nan;
                fomd('bgslope_repr') = nan;
                fomd('bkgrdslope_repr') = nan;
                fomd('bgcode_repr') = nan;
            end

            if length(bg) == 1 && fomd('bgcode_0') == 0
                fomd('bgcode0_only') = fomd('bg_0'); %MARK
            else
                fomd('bgcode0_only') = nan;
            end
        end

        function fitdata = linpiecewise(~,params,x)
            % params: zeroth index corresponds to the yfit value at x=x0. Next num_knots indices correspond to the knot positions. Next num_knots-1 indices correspond
            % to slopes for the linear regions.  Piecewise linear addition to obtain the value of fitdata at a specific value of x.
            one_col = ones(size(x));
            fitdata = one_col*params(1);
            num_knots = round(length(params)/2);

            for i=2:num_knots
                fitdata = fitdata + params(num_knots+i)*(x>=params(i)).*(min([one_col*params(i+1),x],[],2) - params(i));
            end

        end

        function logical = identifypeaks(~,data,typ,abs_minallowedslope,max_allowed_2ndderiv)
            condition = (abs(data(typ+'_2ndderiv')) >= max_allowed_2ndderiv) && (abs(data(typ+'_1stderiv')) <= abs_minallowedslope);
            where = condition(condition>0);
            logical = length(where); %  THIS COULD EASILY BE WRONG
            %return length(logical) % > 0? FIX THIS is it numel??
        end

        function shader(app)
            % Update Fit Limits
            app.UpperFit.Value = app.ShadeArea(1,1);
            app.LowerFit.Value = app.ShadeArea(2,1);

            RGarea = app.ShadeArea;

            % Create new areas
            app.Shade = gobjects(2,1);
            app.Shade(1,1) = rectangle(app.UIAxes_1,"Position", [app.XMin app.YMin abs(RGarea(1,1)-app.XMin) app.YMax-app.YMin],'FaceColor',[0.75 0.75 0.75]);
            app.Shade(2,1) = rectangle(app.UIAxes_1,"Position", [RGarea(2,1) app.YMin abs(app.XMax-RGarea(2,1)) app.YMax-app.YMin],'FaceColor',[0.75 0.75 0.75]);

            % Set default axes boundaries
            app.UIAxes_1.XLim = [app.XMin app.XMax];
            app.UIAxes_1.YLim = [app.YMin app.YMax];

            % Implement drawline for either side of the area
            x = app.XMin;
            y = app.YMin-1;
            w = app.XMax-app.XMin;
            h = (app.YMax*1.01)-(app.YMin-1);

            % Create ROI lines
            app.ROILine = gobjects(2,1);
            app.ROILine(1,1) = images.roi.Line(app.UIAxes_1, "Position", [RGarea(1,1) app.UIAxes_1.YLim(1)-1; RGarea(1,1) app.UIAxes_1.YLim(2)*1.01], "Deletable",false,"InteractionsAllowed","Translate",'Color', [0 0 0], "DrawingArea", [x y w h],'Tag','Left');
            app.ROILine(2,1) = images.roi.Line(app.UIAxes_1, "Position", [RGarea(2,1) app.UIAxes_1.YLim(1)-1; RGarea(2,1) app.UIAxes_1.YLim(2)*1.01], "Deletable",false,"InteractionsAllowed","Translate",'Color', [0 0 0], "DrawingArea", [x y w h],'Tag','Right');

            % Add callbacks for when point moves and is let go
            addlistener(app.ROILine(1,1), "MovingROI", @(src,event)ROIChangedCallback(app,src,event));
            addlistener(app.ROILine(2,1), "MovingROI", @(src,event)ROIChangedCallback(app,src,event));

            addlistener(app.ROILine(1,1), "ROIMoved", @(src,event)ROIChangedCallback(app,src,event));
            addlistener(app.ROILine(2,1), "ROIMoved", @(src,event)ROIChangedCallback(app,src,event));

        end

        function ROIChangedCallback(app,src,event)
            switch event.EventName
                case 'MovingROI'
                    if src.Tag == "Left"
                        app.ShadeArea(1,1) = event.CurrentPosition(1);
                        app.LowerFitLimitEditField.Value = event.CurrentPosition(1);
                    else
                        app.ShadeArea(2,1) = event.CurrentPosition(2);
                        app.UpperFitLimitEditField.Value = event.CurrentPosition(2);
                    end

                    % Continue as normal
                    updateAreas(app);
                case 'ROIMoved'
                    if src.Tag == "Left"
                        value = app.LowerFitLimitEditField.Value;

                        % Code to snap to nearest data point
                        app.left_line_index = closest(app,value);
                        close = app.Wavelength(app.left_line_index);

                        app.LowerFitLimitEditField.Value = close;

                        app.ROILine(1,1).Position = [close app.UIAxes_1.YLim(1)-1; close app.UIAxes_1.YLim(2)*1.01];
                        app.ShadeArea = [close; app.ShadeArea(2,1)];
                        updateAreas(app);
                    else
                        value = app.UpperFitLimitEditField.Value;

                        % Code to snap to nearest data point
                        app.right_line_index = closest(app,value);
                        close = app.Wavelength(app.right_line_index);

                        app.UpperFitLimitEditField.Value = close;

                        app.ROILine(2,1).Position = [close app.UIAxes_1.YLim(1)-1; close app.UIAxes_1.YLim(2)*1.01];
                        app.ShadeArea = [app.ShadeArea(1,1); close];
                        updateAreas(app);
                    end
            end
        end

        function updateAreas(app)
            % Update both shaded areas up until XLims
            RGarea = app.ShadeArea;
            height = app.YMax-app.YMin;

            % Update new areas
            app.Shade(1,1).Position = [app.XMin app.YMin RGarea(1,1)-app.XMin height];
            app.Shade(2,1).Position = [RGarea(2,1) app.YMin app.XMax-RGarea(2,1) height];
        end

        function index = closest(app,value)
            % Code to snap to nearest data point by given index
            [~, index] = min(abs(app.Wavelength - value));
        end

        function SNG_method(app,Energy,YData)
            % Create "absorption coefficient" equivalent
            TaucY = YData.*Energy;

            % Caculate varying transitions with different exponents
            DA = TaucY.^2;
            normD = max(DA);
            DA = DA./normD;

            IA = TaucY.^(1/2);
            normI = max(IA);
            IA = IA./normI;

            app.SNGdata = [Energy,DA,IA];

            % Plot Tauc data
            if isa(app.DAData,'matlab.graphics.GraphicsPlaceholder')
                app.DAData = line(app.UIAxes_2,Energy,DA,'Color','k','HandleVisibility','off'); % Tauc data DA
            end

            if isa(app.IAData,'matlab.graphics.GraphicsPlaceholder')
                app.IAData = line(app.UIAxes_3,Energy,IA,'Color','k','HandleVisibility','off'); % Tauc data IA
            end

            [~,DA] = flipIndices(app,Energy,DA);
            [Energy,IA] = flipIndices(app,Energy,IA);

            % Create dictionary equivalents with example data
            k = {'lower_wl', 'upper_wl', 'num_knots', 'min_knotdist', 'tol', 'maxtol', 'merge_linsegslopediff_percent', 'max_merge_differentialTP', ...
                'min_allowedslope', 'min_finseglength', 'min_bgTP_diff', 'min_bkgrdslope', 'min_bgbkgrdslopediff', 'min_bgTP_finseg_diff', 'min_bgfinalseglength', ...
                'analysis_types', 'maxbgspersmp', 'chkoutput_types', 'abs_minallowedslope', 'max_absolute_2ndderiv', 'use_absderivs_forpeaks'};
            v = {390, 940, 8, 0.05, 1e-06, 1e-03, 5.0, 10000.0, -2, 0.1, 0.2, -0.05, 0.2, 0.2, 0.2, {'DA','IA'}, 4, {'DA','IA'}, -Inf, Inf, false};
            inputparamsd = containers.Map(k,v);

            % Create map of energy and transition data
            x = {'hv', 'DA', 'IA'};
            y = {Energy, DA, IA};
            data = containers.Map(x, y);

            % Pass into fitting
            try
                [~,fomd,data] = runuvvis(app,data,inputparamsd); % Usually [linfitd,fomd,data] = ...
            catch e
                disp(e);
                return
            end

            % Used for debugging and testing
            % LINFITDKEYS = keys(linfitd);
            % LINFITDKEYS = LINFITDKEYS';
            % LINFITDVALS = values(linfitd);
            % LINFITDVALS = LINFITDVALS';
            % 
            % FOMDKEYS = keys(fomd);
            % FOMDKEYS = FOMDKEYS';
            % FOMDVALS = values(fomd);
            % FOMDVALS = FOMDVALS';
            % 
            % DATAKEYS = keys(data);
            % DATAKEYS = DATAKEYS';
            % DATAVALS = values(data);
            % DATAVALS = DATAVALS';

            DA_bg = fomd("DA_bg_0");
            IA_bg = fomd("IA_bg_0");
            
            if isnan(DA_bg)
                DA_bg = "NaN";
            end

            if isnan(IA_bg)
                IA_bg = "NaN";
            end
            
            % Update labels
            app.DAbgs(end+1) = DA_bg;
            app.IAbgs(end+1) = IA_bg;

            app.DACode.Text = "DA Code(s): " + newline + fomd("DA_bgcode_0");
            app.IACode.Text = "IA Code(s): " + newline + fomd("IA_bgcode_0");

%             app.SNGdata = [data('hv'), data('DA'), data('IA')];
            
            % Plot accompanying fit data after calculated
            app.FitsAndBases(end+1,1) = line(app.UIAxes_2,data('hv'),data('DA_linfit'),'Color','r','HandleVisibility','off'); % Fit data DA
            app.FitsAndBases(end+1,1) = line(app.UIAxes_3,data('hv'),data('IA_linfit'),'Color','r','HandleVisibility','off'); % Fit data IA
        end

        function bandgap = mars_method(app,energy,absorption_coefficient,bg_type,show_graph)

            % Use bg_type to determine axes
            if bg_type == "direct"
                ax = app.UIAxes_2;
                name = "MARS_{DA}";
            elseif bg_type == "indirect"
                ax = app.UIAxes_3;
                name = "MARS_{IA}";
            end

            % Normalize absorption coeff data for full range of data
            Norm = max(absorption_coefficient);
            Norm_abs_corrected = absorption_coefficient./Norm;

            [Energy,Abs] = flipIndices(app,energy,Norm_abs_corrected);

            % Assign min and max for limits
            xmin = min(Energy);
            xmax = max(Energy);

            % Assign old variable to cut data
            direct_abs_corrected = Abs;
            
            try
                % trainParams = aresparams2('cubic',false,'maxFuncs',[],'useMinSpan',-1,'useEndSpan',-1,'threshold',1e-5,'prune',true); % Set parameters other than default
                trainParams = aresparams2('cubic',false,'threshold',1e-5);

                [model, ~, ~] = aresbuild(Energy,direct_abs_corrected,trainParams);
                % aresinfo(model,energy,direct_abs_corrected); % Extra info like (Verbose=2)
            catch ValueError
                disp(ValueError);
                uialert(app.TaucPlotFittingUIFigure,"Problem in MARS fitting parameters!","MARS Problem","Icon","Error");
                return
            end

            basislist = areseq(model);%,[],[],[],[],"hide"); %<-- for linear when cubic % basislist = [model.basis_];
            strarray = string(basislist(1:end-1)); % Turn eqn list into string array

            sz = size(strarray,1);
            energy_elbows = ones(sz,1);
            energy_elbows(1:2) = [xmin; xmax];

            for i = 1:sz
                try
                    str = strarray(i); % Iterate through each basis eqn
                    out = regexp(str,"\d+\.\d+","match"); % Only take num
                    new = str2double(out); % Turn into scalar value

                    if new ~= energy_elbows
                        energy_elbows(i) = new; % Add num to list
                    end

                    % Split the equations at the equal sign and assign eqn
                    eqn = strsplit(str, " = ");
                    BF{i,1} = eqn(2);

                catch IndexError
                    disp(IndexError);
                    continue
                end
            end

            [y_hat, ~] = arespredict(model,Energy);

            if show_graph == true
                % aresplot(model);
                if isa(app.DAData,'matlab.graphics.GraphicsPlaceholder') && bg_type == "direct"
                    app.DAData = line(ax,energy,Norm_abs_corrected,'Color','k','HandleVisibility','off'); % Tauc data DA
                end

                if isa(app.IAData,'matlab.graphics.GraphicsPlaceholder') && bg_type == "indirect"
                    app.IAData = line(ax,energy,Norm_abs_corrected,'Color','k','HandleVisibility','off'); % Tauc data IA
                end

                app.FitsAndBases(end+1,1) = line(ax,Energy,y_hat,'Color','b','Marker','.','HandleVisibility','off'); % Fit data

                % Assign data to property for exportation
                app.MARSdata(:,1) = energy;
                app.MARSdata = [app.MARSdata, Norm_abs_corrected];
            end

            % Split final expression in basislist to get end function
            last = strsplit(string(basislist(end))," = ");
            export = last(2);

            % Replace each BF with actual basis function
            for i = 1:sz
                % Add this condition so that last element is replaced w/o space
                if i ~= sz
                    old = strcat("BF",num2str(i)," ");
                else
                    old = strcat("BF",num2str(i));
                end
                export = strrep(export,old,BF{i,1});
            end
            
            % Create exported expression into function
            lastfunc = str2func(['@(x1)',convertStringsToChars(export)]);
            direct_abs_elbows = feval(lastfunc,energy_elbows);

            elbows_list = [];
            elbows_list(:,1) = energy_elbows;
            elbows_list(:,2) = direct_abs_elbows;

            % Sort
            elbows_list = sortrows(elbows_list);

            lin_segs = [];

            for point = 1:size(elbows_list,1)-1
                que = [];
                for i = 1:size(Energy,1)
                    if Energy(i,1) > elbows_list(point,1) && Energy(i,1) < elbows_list(point+1,1)
                        que = [que;Energy(i,1)]; %#ok<AGROW> 
                    end
                end

                num_pts = size(que,1);

                x_length = elbows_list(point+1,1)-elbows_list(point,1);
                length = ((elbows_list(point+1,1)-elbows_list(point,1))^2 + (elbows_list(point+1,2)-elbows_list(point,2))^2)^(1/2);
                slope = (elbows_list(point+1,2)-elbows_list(point,2))/(elbows_list(point+1,1)-elbows_list(point,1));
                y_intercept = elbows_list(point+1,2)-(slope*elbows_list(point+1,1));

                x_intercept = (-1*y_intercept)/slope;
                weighting_factor = (slope^2)*(x_length^(-2))*(abs(length)^(1/2))*num_pts; %x_length*2 from python??. Not what is stated in paper

                try
                    if x_intercept > 0 && slope > 0 && num_pts > 10
                        lin_segs = [lin_segs;[x_length,length,slope,y_intercept,x_intercept,weighting_factor]];
                    end
                catch TypeError
                    disp(TypeError);
                    disp("Weird complex zoo error..");
                    continue
                end
            end

            lin_segs = sortrows(lin_segs,6); % Sort by weighting factor
            winner = lin_segs(end,:); % Choose max weighting_factor

            base = lin_segs(1,:); % Choose min weighting_factor 
            % Or choose min slope
            % [~,I] = min(lin_segs(:,3));
            % base = lin_segs(I,:);

%             adj_energy = linspace(xmin,xmax,size(y_hat,1));
%             adj_energy = adj_energy';

            adj_winner = (energy*winner(3))+winner(4);
            adj_base = (energy*base(3)+base(4));

            if show_graph == true
                line(ax,energy,adj_winner,"Color",'b','DisplayName',name); % Tauc line segment
                app.FitsAndBases(end+1,1) = line(ax,energy,adj_base,"Color",'b',"LineStyle","-.",'HandleVisibility','off'); % Baseline segment

                % Assign segments to property for exportation
                app.MARSregion = [app.MARSregion, adj_winner, adj_base];

                ax.XLim = [xmin, xmax];
                ax.YLim = [0, 1];
            end

            % Calculate bandgap from intersection
            % bandgap = winner(5);
            [x_int,~] = line_intersection([winner(3),winner(4)],[base(3),base(4)]);
            bandgap = x_int;

            if bg_type == "direct"
                app.DAbgs(end+1) = bandgap;
            elseif bg_type == "indirect"
                app.IAbgs(end+1) = bandgap;
            end
        end
    
        function mean_band_gaps = segmentation_method(app,energy,absorption_coefficient,bg_type,show_graph)
            % Use bg_type to determine axes
            if bg_type == "direct"
                ax = app.UIAxes_2;
                name = "SEG_{DA}";
            elseif bg_type == "indirect"
                ax = app.UIAxes_3;
                name = "SEG_{IA}";
            end
            
            % Normalize absorption coeff
            Norm = max(absorption_coefficient);
            Norm_abs_corrected = absorption_coefficient./Norm;

            [Energy,Abs] = flipIndices(app,energy,Norm_abs_corrected);

            % Assign old variable to cut data
            direct_abs_corrected = Abs;

            % Assign min and max for limits
            xmin = min(Energy);
            xmax = max(Energy);

            if show_graph == true
                if isa(app.DAData,'matlab.graphics.GraphicsPlaceholder') && bg_type == "direct"
                    app.DAData = line(ax,energy,Norm_abs_corrected,'HandleVisibility','off'); % Tauc data DA
                end

                if isa(app.IAData,'matlab.graphics.GraphicsPlaceholder') && bg_type == "indirect"
                    app.IAData = line(ax,energy,Norm_abs_corrected,'HandleVisibility','off'); % Tauc data IA
                end
            end

            % Assign calculated data to property for exportation
            app.SEGdata(:,1) = energy;
            app.SEGdata = [app.SEGdata, Norm_abs_corrected];

            % Calculate derivatives
            first_deriv = diff(direct_abs_corrected)./diff(Energy);
            first_deriv(end+1) = first_deriv(end); %Add copy of end for size

            % Filter Y data
            first_deriv_savgol_filt = sgolayfilt(first_deriv,4,25);

            % These 4 equations are used for Tauc segment
            energy_negats_elim = Energy(first_deriv_savgol_filt > mean(first_deriv_savgol_filt));
            first_deriv_negats_elim = direct_abs_corrected(first_deriv_savgol_filt > mean(first_deriv_savgol_filt));

            E = energy_negats_elim(energy_negats_elim < mean(energy_negats_elim)+2*std(energy_negats_elim));
            EA = first_deriv_negats_elim(energy_negats_elim < mean(energy_negats_elim)+2*std(energy_negats_elim));

            % These 4 equations are used for the baseline segment
            energy_pos_elim = Energy(first_deriv_savgol_filt < mean(first_deriv_savgol_filt));
            first_deriv_pos_elim = direct_abs_corrected(first_deriv_savgol_filt < mean(first_deriv_savgol_filt));

            baseE = energy_pos_elim(energy_pos_elim < mean(energy_pos_elim)+2*std(energy_pos_elim));
            baseEA = first_deriv_pos_elim(energy_pos_elim < mean(energy_pos_elim)+2*std(energy_pos_elim));

            % Assign specific variables for easier definitions
            new_energy = E;
            new_absorption_direct = EA;

            base_energy = baseE;
            base_absorption_direct = baseEA;

            spring_quotient = 1.0; %USE THIS TO ALTER THE NUMBER OF LINES SELECTED!

            % Calculate baseline first
            try
                base_Segs = [];
                t = 1:round(length(base_energy)/7):length(base_energy);

                for k = 1:length(t)-1
                    [slope, intercept, R_squared, x_intercept] = linregress(app,base_energy(t(k):t(k+1)),base_absorption_direct(t(k):t(k+1)),[]);
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared < 0.75
                        continue
                    else
                        base_Segs(end+1,:) = [t(k),t(k+1),slope,intercept,R_squared,x_intercept]; %#ok<AGROW> 
                    end
                end

                % Sort segments and choose lowest slope for baseline
                base_Segs = sortrows(base_Segs,3);
%                 base_Segs(base_Segs(:,3) > (base_Segs(end,3))/(spring_quotient),:) = [];

            catch ValueError
                disp(ValueError);
                base_Segs = [];
            end

            if app.BaselineIndexEditField.Visible == 0
                I = 1;
            else
                I = app.BaselineIndexEditField.Value;
            end

            try
                position_linregress_vals_10_role = [];
                s = 1:round(length(new_energy)/10):length(new_energy);

                for k = 1:length(s)-1
                    [slope, intercept, R_squared, x_intercept] = linregress(app,new_energy(s(k):s(k+1)),new_absorption_direct(s(k):s(k+1)),base_Segs(I,:));
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared < 0.75
                        continue
                    else
                        position_linregress_vals_10_role(end+1,:) = [s(k),s(k+1),slope,intercept,R_squared,x_intercept]; %#ok<AGROW> 
                    end
                end

                % Sort and then delete any slope less than the max
                % slope/spring_quotients
                position_linregress_vals_10_role = sortrows(position_linregress_vals_10_role,3);
                position_linregress_vals_10_role(position_linregress_vals_10_role(:,3) < (position_linregress_vals_10_role(end,3))/(spring_quotient),:) = [];

            catch ValueError
                disp(ValueError);
                position_linregress_vals_10_role = [];
            end

            try
                position_linregress_vals_9_role = [];
                r = 1:round(length(new_energy)/9):length(new_energy);

                for k = 1:length(r)-1
                    [slope, intercept, R_squared, x_intercept] = linregress(app,new_energy(r(k):r(k+1)),new_absorption_direct(r(k):r(k+1)),base_Segs(I,:));
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared < 0.75
                        continue
                    else
                        position_linregress_vals_9_role(end+1,:) = [r(k),r(k+1),slope,intercept,R_squared,x_intercept]; %#ok<AGROW> 
                    end
                end

                % Sort and then delete any slope less than the max slope/spring_quotient
                position_linregress_vals_9_role = sortrows(position_linregress_vals_9_role,3);
                position_linregress_vals_9_role(position_linregress_vals_9_role(:,3) < (position_linregress_vals_9_role(end,3))/(spring_quotient),:) = [];

            catch ValueError
                disp(ValueError);
                position_linregress_vals_9_role = [];
            end

            try
                r = 1:round(length(new_energy)/8):length(new_energy);
                sz = size(r);
                position_linregress_vals_8_role = [];
                % position_linregress_vals_8_role = nan(sz(2),6);

                for k = 1:sz(2)-1  % sz(2) = length(r);
                    [slope, intercept, R_squared, x_intercept] = linregress(app,new_energy(r(k):r(k+1)),new_absorption_direct(r(k):r(k+1)),base_Segs(I,:));
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared < 0.75
                        continue
                    else
                        position_linregress_vals_8_role(end+1,:) = [r(k),r(k+1),slope,intercept,R_squared,x_intercept];  
                    end
                end

                % position_linregress_vals_8_role = rmmissing(position_linregress_vals_8_role);

                % Sort and then delete any slope less than the max slope/spring_quotient
                position_linregress_vals_8_role = sortrows(position_linregress_vals_8_role,3);
                position_linregress_vals_8_role(position_linregress_vals_8_role(:,3) < (position_linregress_vals_8_role(end,3))/(spring_quotient),:) = [];

            catch ValueError
                disp(ValueError);
                position_linregress_vals_8_role = [];
            end

            try
                position_linregress_vals_7_role = [];
                t = 1:round(length(new_energy)/7):length(new_energy);

                for k = 1:length(t)-1
                    [slope, intercept, R_squared, x_intercept] = linregress(app,new_energy(t(k):t(k+1)),new_absorption_direct(t(k):t(k+1)),base_Segs(I,:));
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared < 0.75
                        continue
                    else
                        position_linregress_vals_7_role(end+1,:) = [t(k),t(k+1),slope,intercept,R_squared,x_intercept]; %#ok<AGROW> 
                    end
                end

                % Sort and then delete any slope less than the max slope/spring_quotient
                position_linregress_vals_7_role = sortrows(position_linregress_vals_7_role,3);
                position_linregress_vals_7_role(position_linregress_vals_7_role(:,3) < (position_linregress_vals_7_role(end,3))/(spring_quotient),:) = [];

            catch ValueError
                disp(ValueError);
                position_linregress_vals_7_role = [];
            end

            try
                position_linregress_vals_6_role = [];
                t = 1:round(length(new_energy)/6):length(new_energy);
                for k = 1:length(t)-1
                    [slope, intercept, R_squared, x_intercept] = linregress(app,new_energy(t(k):t(k+1)),new_absorption_direct(t(k):t(k+1)),base_Segs(I,:));
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared < 0.75
                        continue
                    else
                        position_linregress_vals_6_role(end+1,:) = [t(k),t(k+1),slope,intercept,R_squared,x_intercept]; %#ok<AGROW> 
                    end
                end

                % Sort and then delete any slope less than the max slope/spring_quotient
                position_linregress_vals_6_role = sortrows(position_linregress_vals_6_role,3);
                position_linregress_vals_6_role(position_linregress_vals_6_role(:,3) < (position_linregress_vals_6_role(end,3))/(spring_quotient),:) = [];

            catch ValueError
                disp(ValueError);
                position_linregress_vals_6_role = [];
            end

            try
                position_linregress_vals_5_role = [];
                t = 1:round(length(new_energy)/5):length(new_energy);
                for k = 1:length(t)-1
                    [slope, intercept, R_squared, x_intercept] = linregress(app,new_energy(t(k):t(k+1)),new_absorption_direct(t(k):t(k+1)),base_Segs(I,:));
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared <  0.75
                        continue
                    else
                        position_linregress_vals_5_role(end+1,:) = [t(k),t(k+1),slope,intercept,R_squared,x_intercept]; %#ok<AGROW> 
                    end
                end

                % Sort and then delete any slope less than the max slope/spring_quotient
                position_linregress_vals_5_role = sortrows(position_linregress_vals_5_role,3);
                position_linregress_vals_5_role(position_linregress_vals_5_role(:,3) < (position_linregress_vals_5_role(end,3))/(spring_quotient),:) = [];


            catch ValueError
                disp(ValueError);
                position_linregress_vals_5_role = [];
            end

            try

                % Populate band gap values in list w/ mean and stddev below
                % Use line_intersection to calculate in linregress(app,...)
                band_gaps_list = [
                    position_linregress_vals_5_role(end,6);
                    position_linregress_vals_6_role(end,6);
                    position_linregress_vals_7_role(end,6);
                    position_linregress_vals_8_role(end,6);
                    position_linregress_vals_9_role(end,6);
                    position_linregress_vals_10_role(end,6)];

                mean_band_gaps = mean(band_gaps_list);
                std_band_gaps = std(band_gaps_list);

                % Remove greater or less than 2 stddevs from list
                band_gaps_list(band_gaps_list(:,1) > (mean_band_gaps + 2*std_band_gaps)) = [];
                band_gaps_list(band_gaps_list(:,1) < (mean_band_gaps - 2*std_band_gaps)) = [];

                % Recalculate mean and stddev
                mean_band_gaps = mean(band_gaps_list);
                std_band_gaps = std(band_gaps_list); %#ok<NASGU> 

                x = linspace(min(new_energy),max(new_energy),size(Energy,1));
                x = x';

                basex = linspace(min(base_energy),xmax,size(Energy,1));
                basex = basex';

                % Turn all 6 Tauc segments into one mean and then find the intersection to baseline
                meanTauc = (position_linregress_vals_10_role + position_linregress_vals_9_role ...
                + position_linregress_vals_8_role + position_linregress_vals_7_role + ...
                position_linregress_vals_6_role + position_linregress_vals_5_role)./6;

                [mean_band_gaps,~] = line_intersection([meanTauc(1,3),meanTauc(1,4)],[base_Segs(I,3),base_Segs(I,4)]);

                if show_graph == true
                    h = base_Segs(I,:);
                    baseline = basex*h(3)+h(4);
                    app.FitsAndBases(end+1,1) = line(ax,basex,baseline,'Color','k',"LineStyle","-.",'HandleVisibility','off'); % Baseline segment

                    h = meanTauc;
                    taucY = x*h(3)+h(4);
                    line(ax,x,taucY,'Color','k',"DisplayName",name); % Tauc line segment

%                     h = position_linregress_vals_5_role(end,:);
%                     taucY5 = x*h(3)+h(4);
%                     line(ax,x,taucY5,'Color','g');
% 
%                     h = position_linregress_vals_6_role(end,:);
%                     taucY6 = x*h(3)+h(4);
%                     line(ax,x,taucY6,'Color','c');
% 
%                     h = position_linregress_vals_7_role(end,:);
%                     taucY7 = x*h(3)+h(4);
%                     line(ax,x,taucY7,'Color','m');
% 
%                     h = position_linregress_vals_8_role(end,:);
%                     taucY8 = x*h(3)+h(4);
%                     line(ax,x,taucY8,'Color','y');
% 
%                     h = position_linregress_vals_9_role(end,:);
%                     taucY9 = x*h(3)+h(4);
%                     line(ax,x,taucY9,'Color',[0.4940 0.1840 0.5560]);
% 
%                     h = position_linregress_vals_10_role(end,:);
%                     taucY10 = x*h(3)+h(4);
%                     line(ax,x,taucY10,'Color',[0.8500 0.3250 0.0980]);

                    ax.XLim = [xmin, xmax];
                    ax.YLim = [0, 1];

                    % Assign segments to property for exportation
                    app.SEGregion = [app.SEGregion, x, taucY, basex, baseline];
                end

            catch ValueError
                disp(ValueError);
                disp("Empty Sequence!");
                return
            end

            if bg_type == "direct"
                app.DAbgs(end+1) = mean_band_gaps;
            elseif bg_type == "indirect"
                app.IAbgs(end+1) = mean_band_gaps;
            end
        end


        function calculate_band_gap(app,energy,absorption_coefficient,method) % GENERAL FUNC FOR ALL METHODS
            show_graph = true;
            absorption_coeff_savgol_filt = sgolayfilt(absorption_coefficient,4,25);

            for bg_type = ["direct", "indirect"]
                if bg_type == "direct"
                    absorption_y(:,1) = (abs(absorption_coeff_savgol_filt).*energy).^2;
                elseif bg_type == "indirect"
                    absorption_y(:,1) = (abs(absorption_coeff_savgol_filt).*energy).^(1/2);
                elseif bg_type == "log10"
                    absorption_y(:,1) = log10(abs(absorption_coeff_savgol_filt))-3;
                elseif bg_type == "raw_alpha"
                    absorption_y(:,1) = abs(absorption_coeff_savgol_filt);
                else
                    uialert(app.TaucPlotFittingUIFigure,"Not a valid band gap type.","Type Error","Icon","error");
                end
            
                if method == "SNG"
                    SNG_method(app,energy,absorption_coeff_savgol_filt);
                    break % The SNG method calculates both DA and IA in one call
                elseif method == "Segmentation"
                    segmentation_method(app,energy,absorption_y,bg_type,show_graph);
                elseif method == "MARS"
                    mars_method(app,energy,absorption_y,bg_type,show_graph);
                else
                    uialert(app.TaucPlotFittingUIFigure,"Not a valid band gap methodology","Method Error","Icon","error");
                end
            end
        end
        
        function [slope, intercept, R_squared, x_intercept] = linregress(~,x,y,base)
        % This function computes a basic linear regression when given x and y data.
        % It returns the slope, intercept, R-squared value, and x_intercept.
        % For further information on an alternative, see sklearn.linear_model.LinearRegression.

            if length(x) ~= length(y)
                disp("Error: Lists must be the same size!")
                return
            end
            
            x_squared = x.^2;
            x_times_y = x.*y;

            intercept = (sum(y)*sum(x_squared)-sum(x)*sum(x_times_y))/(length(x)*sum(x_squared)-sum(x)^2);
            slope = (length(x)*sum(x_times_y)-sum(x)*sum(y))/(length(x)*sum(x_squared)-sum(x)^2);
            %a+bx=y Where a is intercept and b is slope
            %Calculate the R**2 value through the formula 1-(SSres/SStot)
            y_mean = mean(y);

            modeled_pt = slope*x + intercept;
            y_res = (modeled_pt-y_mean).^2;
            y_tot = (y-y_mean).^2;

            R_squared = sum(y_res)/sum(y_tot);
            % y_diff_squared=[]
            % for i in y:
            %    y_diff_squared.append((i-y_mean)**2)
            % SStot=sum(y_diff_squared)
            % y_diff_func_squared=[]
            % for i in y:
            %    y_diff_func_squared.append((i-(slope*i+intercept))**2)
            % SSres=sum(y_diff_func_squared)    
            % R_squared=1-(SSres/SStot)

            if isempty(base) == 1
                x_intercept = -intercept/slope;
            else
                [x_intercept,~] = line_intersection([slope,intercept],[base(3),base(4)]);
            end
        end
        
        function [xdata,ydata] = flipIndices(app,xdata,ydata)
            if app.left_line_index < app.right_line_index
                xdata = xdata(app.left_line_index:app.right_line_index);
                ydata = ydata(app.left_line_index:app.right_line_index);
            else
                xdata = xdata(app.right_line_index:app.left_line_index);
                ydata = ydata(app.right_line_index:app.left_line_index);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("plist.mat","plist");

            app.CenterPanel.Visible = false;
            app.RightPanel.Visible = false;

            app.SegmentationCheckBox.Value = 1;
            app.MARSCheckBox.Value = 1;
            app.SNGCheckBox.Value = 1;

            % Definitions of possible bg_code0 values
            code0 = 'Successful assignment of bandgap linear segment using simple rules';
            code1 = 'Linear segment with a slope less than min_slope was found';
            code2 = 'Succesful assignment of bandgap linear segment using a slightly higher slope at following segment criterion but bgdiff > min in current segment; exists only the older versions and is obsolete from 3/6/16';
            code3 = 'No linear segment was observed for band gap';
            code4 = 'Band gap linear segment(s) deleted due to inability to identify background linear segment with sufficient difference in slope';
            code5 = 'All Band gap lin segs deleted due to inability to identify background linear segment with sufficient difference in slope';
            code6 = 'Final segment has slope higher than previous and explains bgdiff>min this check occurs only when no band gap has been found with other criteria above';
            % There is a chance that you are underestimating band gaps
            code7 = 'Peaks were found';
            code8 = 'NaNs were found in the absorption spectrum';
            code9 = 'Linear fitting failed';

            app.UITable.Data = ["Code 0",code0;"Code 1",code1;"Code 2",code2;"Code 3",code3;"Code 4",code4;"Code 5",code5;"Code 6",code6;"Code 7",code7;"Code 8",code8;"Code 9",code9;];

            try
                for i = 2:size(plist,2)
                    % Check if MATLAB addons are installed
                    if plist(1,i).Certain == 1
                        matlab.addons.isAddonEnabled(plist(1,i).Name);
                    end
                end
            catch ME            
                app.LoadCreateError.Text = "Please install " + plist(1,i).Name + " to use all of MARS";
                app.LoadCreateError.FontColor = [1.00,0.00,0.00];
                app.LoadAbsDataButton.Enable = 'on';
                return;
            end

            app.FitsAndBases = gobjects(6,1);
            legend(app.UIAxes_2,'Location','northwest');
            legend(app.UIAxes_3,'Location','northwest');
            legend(app.UIAxes_2,'hide');
            legend(app.UIAxes_3,'hide');
        end

        % Button pushed function: LoadAbsDataButton
        function LoadAbsDataButtonPushed(app, event)
            app.LoadAbsDataButton.Enable = 'off';
            % Find the csv file and record path and clear plots
            app.LoadCreateError.Text = "Loading data";
            [File, app.FilePath] = uigetfile('*.csv;*.xlsx;*.txt');
            app.LoadCreateError.Text = "Loaded Abs: " + File;
            app.LoadCreateError.FontColor = [0.15,0.15,0.15];

            app.BaselineIndexEditField.Visible = false;
            app.BaselineIndexEditFieldLabel.Visible = false;

            try
                % Read the data and copy to public property to use
                %                 opts = detectImportOptions(fullfile(FilePath, File));
                %                 opts.VariableNamesLine = 1;
                temp = readmatrix(fullfile(app.FilePath, File));

            catch ERROR
                disp(ERROR);
                app.LoadCreateError.Text = 'Function is not on selected path';
                app.LoadCreateError.FontColor = [1.00,0.00,0.00];
                app.LoadAbsDataButton.Enable = 'on';
                return;
            end
            app.LoadAbsDataButton.Enable = 'on';
            app.UIAxes_1.YLabel.String = "Intensity";
            app.CenterPanel.Visible = true;
            app.RightPanel.Visible = true;

            % Clear all previous properties and reset to default values
            cla(app.UIAxes_1);
            clearProperties(app);
            app.Wavelength = [];
            app.Absorption = [];
            app.absp = gobjects(0);

            % Set app properties from table data as array variables
            firstColumn = temp(:,1);
            secondColumn = temp(:,2);

            % If wavelength is increasing, energy will be flipped and will flip knots/slopes
            if all(diff(firstColumn) >=0 )
                app.Wavelength = flipud(firstColumn);
                app.Absorption = flipud(secondColumn);
            else
                app.Wavelength = firstColumn;
                app.Absorption = secondColumn;
            end

            % Smoothing & filtering of ydata
%             SmoothYDataSwitchValueChanged(app);

            % Normalize ydata
            normY = max(app.Absorption);
            app.Absorption = app.Absorption/normY;

            % Draw the absorption spectra plot next
            app.absp = line(app.UIAxes_1,app.Wavelength,app.Absorption); % Absorption data

            % Find center of x data to use as refernece for initial ROI
            % line positions
            CenterLambda = median(app.Wavelength);

            % Set max and min data for both x and y properties
            app.XMin = min(app.Wavelength);
            app.XMax = max(app.Wavelength);
            app.YMin = min(app.Absorption);
            app.YMax = max(app.Absorption);

            Width_1 = (CenterLambda-app.XMin);
            Width_2 = (app.XMax - CenterLambda);

            app.ShadeArea = [app.XMin + Width_1/2; CenterLambda + Width_2/2];

            app.left_line_index = closest(app,app.ShadeArea(1,1));
            app.right_line_index = closest(app,app.ShadeArea(2,1));

            % Set GUI elements to the where ROI lines start
            app.LowerFitLimitEditField.Value = app.Wavelength(app.left_line_index);
            app.UpperFitLimitEditField.Value = app.Wavelength(app.right_line_index);

            % Draw shaded rectangles with ROI lines first
            shader(app);

            % Make sure line is ontop of shade
            uistack(app.absp,"top");
        end

        % Button pushed function: LoadReflectanceDataButton
        function LoadReflectanceDataButtonPushed(app, event)
            app.LoadReflectanceDataButton.Enable = 'off';
            % Find the csv file and record path and clear plots
            app.LoadCreateError.Text = "Loading data";
            [File, app.FilePath] = uigetfile('*.csv;*.xlsx;*.txt');
            app.LoadCreateError.Text = "Loaded Ref: " + File;
            app.LoadCreateError.FontColor = [0.15,0.15,0.15];

            app.BaselineIndexEditField.Visible = false;
            app.BaselineIndexEditFieldLabel.Visible = false;

            try
                % Read the data and copy to public property to use
                %                 opts = detectImportOptions(fullfile(FilePath, File));
                %                 opts.VariableNamesLine = 1;
                temp = readmatrix(fullfile(app.FilePath, File));

            catch ERROR
                disp(ERROR);
                app.LoadCreateError.Text = 'Function is not on selected path';
                app.LoadCreateError.FontColor = [1.00,0.00,0.00];
                app.LoadReflectanceDataButton.Enable = 'on';
                return;
            end
            app.LoadReflectanceDataButton.Enable = 'on';
            app.UIAxes_1.YLabel.String = "f(R)";
            app.CenterPanel.Visible = true;
            app.RightPanel.Visible = true;

            % Clear all previous properties and reset to default values
            cla(app.UIAxes_1);
            clearProperties(app);
            app.Wavelength = [];
            app.Absorption = [];
            app.absp = gobjects(0);

            % Set app properties from table data as array variables
            firstColumn = temp(:,1);
            secondColumn = temp(:,2);

            % If wavelength is increasing, energy will be flipped and will flip knots/slopes
            if all(diff(firstColumn) >=0 )
                app.Wavelength = flipud(firstColumn);
                DR = flipud(secondColumn)/100;
            else
                app.Wavelength = firstColumn;
                DR = secondColumn/100;
            end

            app.Absorption = (1-DR.^2)./(2*DR);

            % Smoothing & filtering of ydata
%             SmoothYDataSwitchValueChanged(app);

            % Normalize ydata
            normY = max(app.Absorption);
            app.Absorption = app.Absorption/normY;

             % Draw the absorption spectra plot next
            app.absp = line(app.UIAxes_1,app.Wavelength,app.Absorption); % Absorption data

            % Find center of x data to use as refernece for initial ROI
            % line positions
            CenterLambda = median(app.Wavelength);

            % Set max and min data for both x and y properties
            app.XMin = min(app.Wavelength);
            app.XMax = max(app.Wavelength);
            app.YMin = min(app.Absorption);
            app.YMax = max(app.Absorption);

            Width_1 = (CenterLambda-app.XMin);
            Width_2 = (app.XMax - CenterLambda);

            app.ShadeArea = [app.XMin + Width_1/2; CenterLambda + Width_2/2];

            app.left_line_index = closest(app,app.ShadeArea(1,1));
            app.right_line_index = closest(app,app.ShadeArea(2,1));

            % Set GUI elements to the where ROI lines start
            app.LowerFitLimitEditField.Value = app.Wavelength(app.left_line_index);
            app.UpperFitLimitEditField.Value = app.Wavelength(app.right_line_index);

            % Draw shaded rectangles with ROI lines first
            shader(app);

            % Make sure line is ontop of shade
            uistack(app.absp,"top");
        end

        % Button pushed function: StartFitButton
        function StartFitButtonPushed(app, event)
            clearProperties(app);

            % Cut wavelength and abs data to the specified fit limits
%             if app.left_line_index < app.right_line_index
%                 XData = app.Wavelength(app.left_line_index:app.right_line_index)*1e-9;
%                 YData = app.Absorption(app.left_line_index:app.right_line_index);
                XData = app.Wavelength*1e-9;
                YData = app.Absorption;
%             else % This is if wavelength starts from highest value
%                 XData = app.Wavelength(app.right_line_index:app.left_line_index)*1e-9;
%                 YData = app.Absorption(app.right_line_index:app.left_line_index);
%             end

            % Define constants and calculate energy in joules
            h = 6.62607015e-34;
            c = 299792458;
            joules = (h*c)./XData;

            % Convert energy into eV and calculate (a*hv)
            Energy = joules*6.242e18;
            
            % Select method by checkbox and calculate Tauc YData/bandgap 
            for method = {app.SNGCheckBox,app.MARSCheckBox,app.SegmentationCheckBox}
                if method{1,1}.Value == 1
                    calculate_band_gap(app,Energy,YData,method{1,1}.Text)
                end
            end

            if isempty(app.Bases)
                app.Bases = [1, 3];
            end

            if app.BaselineIndexEditField.Visible == false
                app.BaselineIndexEditField.Visible = true;
                app.BaselineIndexEditFieldLabel.Visible = true;
            end

            app.DABandGapLabel.Text = "DA Bandgap Energy (eV): " + newline + "[" + num2str(app.DAbgs) + "]";
            app.IABandGapLabel.Text = "IA Bandgap Energy (eV): " + newline + "[" + num2str(app.IAbgs) + "]";

            legend(app.UIAxes_2,'show');
            legend(app.UIAxes_3,'show');

            if app.ExportDataButton.Enable == 0
                app.ExportDataButton.Enable = "on";
                app.ExportDataButton.Tooltip = "";
            end
        end

        % Value changed function: UpperFitLimitEditField
        function UpperFitLimitEditFieldValueChanged(app, event)
            value = app.UpperFitLimitEditField.Value;

            % Keep the changed input within the boundary conditons
            if (value > app.XMax)
                value = app.XMax;
                app.UpperFitLimitEditField.Value = value;
            elseif (value < app.ROILine(1,1).Position(1))
                uialert(app.TaucPlotFittingUIFigure,"Cannot input a limit past other ROI line","Input Error","Icon","Error");
                app.UpperFitLimitEditField.Value = event.PreviousValue;
                return
            end

            app.right_line_index = closest(app,value);
            close = app.Wavelength(app.right_line_index);

            app.ROILine(2,1).Position = [close app.UIAxes_1.YLim(1)-1; close app.UIAxes_1.YLim(2)*1.01];

            app.ShadeArea = [app.ShadeArea(1,1); close];
            updateAreas(app);
        end

        % Value changed function: LowerFitLimitEditField
        function LowerFitLimitEditFieldValueChanged(app, event)
            value = app.LowerFitLimitEditField.Value;

            % Keep the changed input within the boundary conditons
            if (value < app.XMin)
                value = app.XMin;
                app.LowerFitLimitEditField.Value = value;
            elseif (value > app.ROILine(2,1).Position(1))
                uialert(app.TaucPlotFittingUIFigure,"Cannot input a limit past other ROI line","Input Error","Icon","Error");
                app.LowerFitLimitEditField.Value = event.PreviousValue;
                return
            end

            % Code to snap to nearest data point
            app.left_line_index = closest(app,value);
            close = app.Wavelength(app.left_line_index);

            app.ROILine(1,1).Position = [close app.UIAxes_1.YLim(1)-1; close app.UIAxes_1.YLim(2)*1.01];

            app.ShadeArea = [close; app.ShadeArea(2,1)];
            updateAreas(app);
        end

        % Callback function
        function SmoothYDataSwitchValueChanged(app, event)
%             value = app.SmoothYDataSwitch.Value;
%             
%             if value == "On"
%                 app.Absorption = smooth(app.OriginalAbs,'sgolay',4);
% 
%                 % Normalize ydata
%                 normY = max(app.Absorption);
%                 app.Absorption = app.Absorption/normY;
% 
%                 app.smoothed = line(app.UIAxes_1,app.Wavelength,app.Absorption,'Color','r');
%             else
%                 app.Absorption = app.OriginalAbs;
% 
%                 % Normalize ydata
%                 normY = max(app.Absorption);
%                 app.Absorption = app.Absorption/normY;
% 
%                 delete(app.smoothed)
%             end
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
%             selectedButton = app.ButtonGroup.SelectedObject;
%             
%             if selectedButton.Text == "SNG"
%                 app.GoodnessofFitFOMDCodesSNGOnlyLabel.Visible = true;
%                 app.UITable.Visible = true;
%                 app.DACode.Visible = true;
%                 app.IACode.Visible = true;
%             else
%                 app.GoodnessofFitFOMDCodesSNGOnlyLabel.Visible = false;
%                 app.UITable.Visible = false;
%                 app.DACode.Visible = false;
%                 app.IACode.Visible = false;
%             end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            path = uigetdir;
%             [file, path] = uiputfile('*.*','File Selection',strjoin([method{1,1}.Text,"data",".csv"],""));
            if path == 0
                return
            end

            for method = {app.SNGCheckBox,app.MARSCheckBox,app.SegmentationCheckBox}
                if method{1,1}.Value == 1
                    switch method{1,1}.Text
                        case "SNG"
                            data = nan(length(app.SNGdata),7);
                            data(:,1:3) = app.SNGdata;
                            l = length(app.SNGregion);
                            data(1:l,4:7) = app.SNGregion;
%                             data = [app.SNGdata,app.SNGregion];
                            writtenData = array2table(data,"VariableNames",["Energy","DAabsCoef","IAabsCoef","DATaucSeg","DABaselineSeg","IATaucSeg","IABaselineSeg"]);
                            fileName = "SNGdata.csv";
                        case "MARS"
                            data = nan(length(app.MARSdata),7);
                            data(:,1:3) = app.MARSdata;
                            l = length(app.MARSregion);
                            data(1:l,4:7) = app.MARSregion;
%                             data = [app.MARSdata,app.MARSregion];
                            writtenData = array2table(data,"VariableNames",["Energy","DAabsCoef","IAabsCoef","DATaucSeg","DABaselineSeg","IATaucSeg","IABaselineSeg"]);
                            fileName = "MARSdata.csv";
                        case "Segmentation"
                            data = nan(length(app.SEGdata),11);
                            data(:,1:3) = app.SEGdata;
                            l = length(app.SEGregion);
                            data(1:l,4:11) = app.SEGregion;
%                             data = [app.SEGdata,app.SEGregion];
                            writtenData = array2table(data,"VariableNames",["Energy","DAabsCoef","IAabsCoef","DAEnergyTauc(X)","MeanDATaucSeg(Y)","DAEnergyBase(X)","MeanDABaseSeg(Y)","IAEnergyTauc(X)","MeanIATaucSeg(Y)","IAEnergyBase(X)","MeanIABaseSeg(Y)"]);
                            fileName = "SEGdata.csv";
                    end
                    try
                        writetable(writtenData,fullfile(path,fileName))
                    catch e
                        disp(e);
                    end
                end
            end
        end

        % Value changed function: BaselineIndexEditField
        function BaselineIndexEditFieldValueChanged(app, event)
            value = app.BaselineIndexEditField.Value;
            
            if value < app.Bases(1) || value > app.Bases(2)
                uialert(app.TaucPlotFittingUIFigure,"Please keep baseline index value in baseline range: " + app.Bases(1) + " - " + app.Bases(2),"Baseline Index Error","Icon","error");
            end

            app.BaselineIndexEditField.Tooltip = "Please re-fit the data to apply change";
        end

        % Value changed function: SNGCheckBox
        function SNGCheckBoxValueChanged(app, event)
            value = app.SNGCheckBox.Value;
            app.ExportDataButton.Enable = "off";
            app.ExportDataButton.Tooltip = "Please re-fit the data to export";
        end

        % Value changed function: MARSCheckBox
        function MARSCheckBoxValueChanged(app, event)
            value = app.MARSCheckBox.Value;
            app.ExportDataButton.Enable = "off";
            app.ExportDataButton.Tooltip = "Please re-fit the data to export";
        end

        % Value changed function: SegmentationCheckBox
        function SegmentationCheckBoxValueChanged(app, event)
            value = app.SegmentationCheckBox.Value;
            app.ExportDataButton.Enable = "off";
            app.ExportDataButton.Tooltip = "Please re-fit the data to export";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TaucPlotFittingUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {640, 640, 640};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {640, 640};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 308};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TaucPlotFittingUIFigure = uifigure('Visible', 'off');
            app.TaucPlotFittingUIFigure.Name = 'Tauc Plot Fitting';
            app.TaucPlotFittingUIFigure.Icon = 'logo.png';
            app.TaucPlotFittingUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TaucPlotFittingUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoadAbsDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadAbsDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAbsDataButtonPushed, true);
            app.LoadAbsDataButton.Text = 'Load Abs Data';
            app.LoadCreateError = uilabel(app.GridLayout2);
            app.LoadCreateError.Text = '';
            app.StartFitButton = uibutton(app.GridLayout2, 'push');
            app.StartFitButton.ButtonPushedFcn = createCallbackFcn(app, @StartFitButtonPushed, true);
            app.StartFitButton.Text = 'Start Fit';
            app.LoadReflectanceDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadReflectanceDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReflectanceDataButtonPushed, true);
            app.LoadReflectanceDataButton.Text = 'Load Reflectance Data';
            app.DABandGapLabel = uilabel(app.GridLayout2);
            app.DABandGapLabel.Text = 'DA Bandgap Energy (eV):';
            app.IABandGapLabel = uilabel(app.GridLayout2);
            app.IABandGapLabel.Text = 'IA Bandgap Energy (eV):';
            app.IACode = uilabel(app.GridLayout2);
            app.IACode.Text = 'IA Code(s):';
            app.BandGapValuesLabel = uilabel(app.GridLayout2);
            app.BandGapValuesLabel.Text = {'Band Gap Values: '; '[SNG MARS Segmentation]'};
            app.DACode = uilabel(app.GridLayout2);
            app.DACode.Text = 'DA Code(s):';
            app.FOMDCodesLabel = uilabel(app.GridLayout2);
            app.FOMDCodesLabel.Text = 'FOMD Code(s) - SNG Only:';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.UIAxes_3 = uiaxes(app.GridLayout3);
            title(app.UIAxes_3, 'Tauc Plot for Indirect Transitions')
            xlabel(app.UIAxes_3, 'Energy (eV)')
            ylabel(app.UIAxes_3, '(\alphahv)^{1/2}')
            app.UIAxes_2 = uiaxes(app.GridLayout3);
            title(app.UIAxes_2, 'Tauc Plot for Direct Transitions')
            xlabel(app.UIAxes_2, 'Energy (eV)')
            ylabel(app.UIAxes_2, '(\alphahv)^{2}')
            app.UIAxes_1 = uiaxes(app.GridLayout3);
            title(app.UIAxes_1, 'Absorption Spectra')
            xlabel(app.UIAxes_1, 'Wavelength (nm)')
            ylabel(app.UIAxes_1, 'Intensity')
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.RightPanel);
            app.FittingMethodLabel = uilabel(app.GridLayout6);
            app.FittingMethodLabel.Text = 'Fitting Method';
            app.AbsorptionCutoffLimitsLabel = uilabel(app.GridLayout6);
            app.AbsorptionCutoffLimitsLabel.Text = {'Absorption Cutoff Limits'; ''};
            app.GridLayout5 = uigridlayout(app.GridLayout6);
            app.LowerFitLimitEditFieldLabel = uilabel(app.GridLayout5);
            app.LowerFitLimitEditFieldLabel.Text = 'Lower Fit Limit';
            app.LowerFitLimitEditField = uieditfield(app.GridLayout5, 'numeric');
            app.LowerFitLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerFitLimitEditFieldValueChanged, true);
            app.UpperFitLimitEditFieldLabel = uilabel(app.GridLayout5);
            app.UpperFitLimitEditFieldLabel.Text = 'Upper Fit Limit';
            app.UpperFitLimitEditField = uieditfield(app.GridLayout5, 'numeric');
            app.UpperFitLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperFitLimitEditFieldValueChanged, true);
            app.GoodnessofFitFOMDCodesSNGOnlyLabel = uilabel(app.GridLayout6);
            app.GoodnessofFitFOMDCodesSNGOnlyLabel.Text = 'Goodness of Fit (FOMD) Codes - SNG Only';
            app.UITable = uitable(app.GridLayout6);
            app.UITable.ColumnName = {'Code'; 'Description'};
            app.UITable.RowName = {};
            app.ExportDataButton = uibutton(app.GridLayout6, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Enable = 'off';
            app.ExportDataButton.Text = 'Export Data';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.SNGCheckBox = uicheckbox(app.GridLayout7);
            app.SNGCheckBox.ValueChangedFcn = createCallbackFcn(app, @SNGCheckBoxValueChanged, true);
            app.SNGCheckBox.Text = 'SNG';
            app.SNGCheckBox.Value = true;
            app.MARSCheckBox = uicheckbox(app.GridLayout7);
            app.MARSCheckBox.ValueChangedFcn = createCallbackFcn(app, @MARSCheckBoxValueChanged, true);
            app.MARSCheckBox.Text = 'MARS';
            app.MARSCheckBox.Value = true;
            app.SegmentationCheckBox = uicheckbox(app.GridLayout7);
            app.SegmentationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationCheckBoxValueChanged, true);
            app.SegmentationCheckBox.Text = 'Segmentation';
            app.SegmentationCheckBox.Value = true;
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.BaselineIndexEditFieldLabel = uilabel(app.GridLayout8);
            app.BaselineIndexEditFieldLabel.Text = 'Baseline Index';
            app.BaselineIndexEditField = uieditfield(app.GridLayout8, 'numeric');
            app.BaselineIndexEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineIndexEditFieldValueChanged, true);
            app.BaselineIndexEditField.Value = 2;
            app.BaselineSegmentSNGandSEGOnlyLabel = uilabel(app.GridLayout6);
            app.BaselineSegmentSNGandSEGOnlyLabel.Text = 'Baseline Segment - SNG and SEG Only';
            % Show the figure after all components are created
            app.TaucPlotFittingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UniversalTaucPlot

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TaucPlotFittingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TaucPlotFittingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TaucPlotFittingUIFigure)
        end
    end
end